classdef CorAnaAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FSDAdynamicinteractiveCAplotUIFigure  matlab.ui.Figure
        ChkBoxRevSignCA2        matlab.ui.control.CheckBox
        ChkBoxRevSignCA1        matlab.ui.control.CheckBox
        ResetButton             matlab.ui.control.Button
        SuppColsListBox         matlab.ui.control.ListBox
        SuppColsListBoxLabel    matlab.ui.control.Label
        SuppRowsListBox         matlab.ui.control.ListBox
        SuppRowsListBoxLabel    matlab.ui.control.Label
        ChkBoxshowColPointsSup  matlab.ui.control.CheckBox
        ChkBoxshowRowPointsSup  matlab.ui.control.CheckBox
        MapTypeDropDown         matlab.ui.control.DropDown
        MapTypeDropDownLabel    matlab.ui.control.Label
        ChkBoxshowColPoints     matlab.ui.control.CheckBox
        ChkBoxshowMoonPlot      matlab.ui.control.CheckBox
        ChkBoxshowRowPoints     matlab.ui.control.CheckBox
        SliderAlpha             matlab.ui.control.Slider
        SliderAlphaLabel        matlab.ui.control.Label
        SliderBDP               matlab.ui.control.Slider
        bdpLabel                matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % Property % Description
        Xtable='';
        % omega parameter in the slider
        omega='';
        % alpha parameter in the slider
        alpha='';
        % boolean which specifies to standardize data in Xtable
        standardize;
        % typeCA
        typeCA='rowprincipal'
    end

    properties (Access = private)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Xtable, varargin)
            [I,J]=size(Xtable);
            if istable(Xtable)
                colnames=Xtable.Properties.VariableNames;
                rownames=Xtable.Properties.RowNames;
                N=table2array(Xtable);
            else
                colnames=cellstr(num2str((1:J)','Y%d'));
                rownames=cellstr(num2str((1:I)','%d'));
                Xtable=array2table(X,"RowNames",rownames,"VariableNames",colnames);
                N=Xtable;
            end

            app.omega = 0.7;
            app.alpha = 0.3;

            % Fill the list box just the very first time the APP is
            % launched
            if isempty(app.SuppRowsListBox.Items)
                app.SuppRowsListBox.Items=rownames;
                app.SuppRowsListBox.ItemsData=1:I;
                app.SuppRowsListBox.Multiselect='on';
                app.SuppColsListBox.Items=colnames;
                app.SuppColsListBox.ItemsData=1:J;
                app.SuppColsListBox.Multiselect='on';
            end

            app.typeCA='bothprincipal';
            app.Xtable=Xtable;


            % Optional arguments TODO
            %             if nargin>2
            %
            %                 options=struct('Sup',app.omega);
            %
            %                 UserOptions=varargin(1:2:length(varargin));
            %                 if ~isempty(UserOptions)
            %                     % Check if number of supplied options is valid
            %                     if length(varargin) ~= 2*length(UserOptions)
            %                         error('FSDA:CorAnaAPP:WrongInputOpt','Number of supplied options is invalid. Probably values for some parameters are missing.');
            %                     end
            %                     % Check if user options are valid options
            %                     chkoptions(options,UserOptions)
            %                 end
            %
            %                 % Write in structure 'options' the options chosen by the user
            %                 for i=1:2:length(varargin)
            %                     options.(varargin{i})=varargin{i+1};
            %                 end
            %                 Sup=options.Sup;
            %             end

            plots=struct;
            if strcmp(app.MapTypeDropDown.Value,'numeric value in [0 1]')
                app.SliderAlpha.Visible='on';
                app.SliderAlphaLabel.Visible='on';
                plots.alpha=app.SliderAlpha.Value;
            else
                app.SliderAlpha.Visible='off';
                app.SliderAlphaLabel.Visible='off';
                plots.alpha=app.MapTypeDropDown.Value;
            end

            if app.ChkBoxshowRowPoints.Value==true
                plots.ColorRows='b';
            else
                plots.ColorRows='w';
            end

            if app.ChkBoxshowRowPointsSup.Value==true
                plots.ColorRowsSup='b';
            else
                plots.ColorRowsSup='w';
            end


            if app.ChkBoxshowColPoints.Value==true
                plots.ColorCols='r';
            else
                plots.ColorCols='w';
            end

            if app.ChkBoxshowColPointsSup.Value==true
                plots.ColorColsSup='r';
            else
                plots.ColorColsSup='w';
            end

            if app.SliderBDP.Value~=0
                outrob=mcdCorAna(N,'bdp',app.SliderBDP.Value);
                if ~isempty(outrob.outliers)
                    app.SuppRowsListBox.Value=[];
                    app.SuppRowsListBox.Value=outrob.outliers;
                end
            end
            disp('sulcols')
            disp(app.SuppColsListBox.Value)

            if isempty(app.SuppRowsListBox.Value) &&  isempty(app.SuppColsListBox.Value)

                out=CorAna(Xtable,'plots',0);
            else
                Sup=struct;
                if ~isempty(app.SuppRowsListBox.Value)
                    Sup.r=app.SuppRowsListBox.Value;
                end
                if ~isempty(app.SuppColsListBox.Value)
                    Sup.c=app.SuppColsListBox.Value;
                end
                out=CorAna(Xtable,'plots',0,'Sup',Sup);
            end

            delete(app.UIAxes.Children)

            changedimsign=[false false];
            if app.ChkBoxRevSignCA1.Value == true
                changedimsign(1)=true;
            end
            if app.ChkBoxRevSignCA2.Value == true
                changedimsign(2)=true;
            end


            if app.ChkBoxshowMoonPlot.Value == true
                moonplot(out,'h',app.UIAxes,'plots',plots,'changedimsign',changedimsign)
            else
                CorAnaplot(out,'h',app.UIAxes,'plots',plots,'changedimsign',changedimsign)
            end

        end

        % Value changed function: SliderAlpha
        function SliderAlphaValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: SliderBDP
        function SliderBDPValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowRowPoints
        function ChkBoxshowRowPointsValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowMoonPlot
        function ChkBoxshowMoonPlotValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowColPoints
        function ChkBoxshowColPointsValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: MapTypeDropDown
        function MapTypeDropDownValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: SuppRowsListBox
        function SuppRowsListBoxValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.SuppRowsListBox.Value=[];
            app.SuppColsListBox.Value=[];
            app.SliderBDP.Value=0;
            % reset check box related to sign of latent components
            app.ChkBoxRevSignCA1.Value=false;
            app.ChkBoxRevSignCA2.Value=false;
            % reset check boxes relate to visualization of row (supp) and
            % column (supp) points
            app.ChkBoxshowRowPoints.Value=true;
            app.ChkBoxshowRowPointsSup.Value=true;
            app.ChkBoxshowColPoints.Value=true;
            app.ChkBoxshowColPointsSup.Value=true;
            out=CorAna(app.Xtable,'plots',0);
            delete(app.UIAxes.Children)
            if app.ChkBoxshowMoonPlot.Value==false
                CorAnaplot(out,'h',app.UIAxes);
            else
                moonplot(out,'h',app.UIAxes);
            end
            %  startupFcn(app, app.Xtable)
        end

        % Value changed function: SuppColsListBox
        function SuppColsListBoxValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowRowPointsSup
        function ChkBoxshowRowPointsSupValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowColPointsSup
        function ChkBoxshowColPointsSupValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxRevSignCA1
        function ChkBoxRevSignCA1ValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxRevSignCA2
        function ChkBoxRevSignCA2ValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FSDAdynamicinteractiveCAplotUIFigure = uifigure('Visible', 'off');
            app.FSDAdynamicinteractiveCAplotUIFigure.Name = 'FSDA dynamic interactive biplot';
            app.UIAxes = uiaxes(app.FSDAdynamicinteractiveCAplotUIFigure);
            title(app.UIAxes, ''' ''')
            xlabel(app.UIAxes, 'CA1')
            ylabel(app.UIAxes, 'CA2')
            app.bdpLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.bdpLabel.Text = 'bdp';
            app.SliderBDP = uislider(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SliderBDP.Limits = [0 0.5];
            app.SliderBDP.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5];
            app.SliderBDP.ValueChangedFcn = createCallbackFcn(app, @SliderBDPValueChanged, true);
            app.SliderBDP.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5];
            app.SliderBDP.Tooltip = {'Select the value of breakdown point you wish to use in otder to compute the MCD. The units flagged as outliers at the 1 per cent simultaneous confidence level will be shown in the plot as supplementary row points '};
            app.SliderAlphaLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SliderAlphaLabel.Text = 'α';
            app.SliderAlpha = uislider(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SliderAlpha.Limits = [0 1];
            app.SliderAlpha.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.SliderAlpha.ValueChangedFcn = createCallbackFcn(app, @SliderAlphaValueChanged, true);
            app.SliderAlpha.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SliderAlpha.Tooltip = {'\alpha=0 corresponds to columnprincipal,'; '\alpha=1 corresponds to rowprincipal:'; '\alpha=0.5 corresponds to symbiplot'};
            app.SliderAlpha.Value = 0.3;
            app.ChkBoxshowRowPoints = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxshowRowPoints.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowRowPointsValueChanged, true);
            app.ChkBoxshowRowPoints.Tooltip = {'If this checkbox is unchecked row points and associated labels are shown with white color'};
            app.ChkBoxshowRowPoints.Text = 'showRowPoints';
            app.ChkBoxshowRowPoints.Value = true;
            app.ChkBoxshowMoonPlot = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxshowMoonPlot.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowMoonPlotValueChanged, true);
            app.ChkBoxshowMoonPlot.Tooltip = {'Show the moonplot instead of the traditional correspondence analysis plot'};
            app.ChkBoxshowMoonPlot.Text = 'Moonplot';
            app.ChkBoxshowColPoints = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxshowColPoints.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowColPointsValueChanged, true);
            app.ChkBoxshowColPoints.Tooltip = {'If this checkbox is unchecked column points and associated labels are shown with white color'};
            app.ChkBoxshowColPoints.Text = 'showColPoints';
            app.ChkBoxshowColPoints.Value = true;
            app.MapTypeDropDownLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.MapTypeDropDownLabel.Text = 'MapType';
            app.MapTypeDropDown = uidropdown(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.MapTypeDropDown.Items = {'rowprincipal', 'colprincipal', 'symbiplot', 'bothprincipal', 'bothstandard', 'rowgab', 'rowgreen', 'colgreen', 'numeric value in [0 1]'};
            app.MapTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MapTypeDropDownValueChanged, true);
            app.MapTypeDropDown.Tooltip = {'Specify the type of coordinates to use in the plot'; 'For additional details please see'; 'http://rosa.unipr.it/FSDA/coranaplot.html'};
            app.MapTypeDropDown.Value = 'bothprincipal';
            app.ChkBoxshowRowPointsSup = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxshowRowPointsSup.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowRowPointsSupValueChanged, true);
            app.ChkBoxshowRowPointsSup.Tooltip = {'If this checkbox is unchecked supplementary row points and associated labels are shown with white color'};
            app.ChkBoxshowRowPointsSup.Text = 'show Supp RowPoints';
            app.ChkBoxshowRowPointsSup.Value = true;
            app.ChkBoxshowColPointsSup = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxshowColPointsSup.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowColPointsSupValueChanged, true);
            app.ChkBoxshowColPointsSup.Tooltip = {'If this checkbox is unchecked supplementary column points and associated labels are shown with white color'};
            app.ChkBoxshowColPointsSup.Text = 'show Supp ColPoints';
            app.ChkBoxshowColPointsSup.Value = true;
            app.SuppRowsListBoxLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SuppRowsListBoxLabel.Text = 'SuppRows';
            app.SuppRowsListBox = uilistbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SuppRowsListBox.Items = {};
            app.SuppRowsListBox.ValueChangedFcn = createCallbackFcn(app, @SuppRowsListBoxValueChanged, true);
            app.SuppRowsListBox.Tooltip = {'Select the rows points which must be treated as supplementary units'};
            app.SuppRowsListBox.Value = {};
            app.SuppColsListBoxLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SuppColsListBoxLabel.Text = 'SuppCols';
            app.SuppColsListBox = uilistbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SuppColsListBox.Items = {};
            app.SuppColsListBox.Multiselect = 'on';
            app.SuppColsListBox.ValueChangedFcn = createCallbackFcn(app, @SuppColsListBoxValueChanged, true);
            app.SuppColsListBox.Tooltip = {'Select the column points which must be treated as supplementary units'};
            app.SuppColsListBox.Value = {};
            app.ResetButton = uibutton(app.FSDAdynamicinteractiveCAplotUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Clicking on Reset clears the selections in the listboxes of Supplementary Row points and Supplementary  Column points, sets the bdp to 0 (standard non robust analysis) and unselects the two check boxes of sign..'};
            app.ResetButton.Text = 'Reset';
            app.ChkBoxRevSignCA1 = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxRevSignCA1.ValueChangedFcn = createCallbackFcn(app, @ChkBoxRevSignCA1ValueChanged, true);
            app.ChkBoxRevSignCA1.Tooltip = {'Change sign of the first latent dimension'};
            app.ChkBoxRevSignCA1.Text = 'Rev sign CA1';
            app.ChkBoxRevSignCA2 = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxRevSignCA2.ValueChangedFcn = createCallbackFcn(app, @ChkBoxRevSignCA2ValueChanged, true);
            app.ChkBoxRevSignCA2.Tooltip = {'Change sign of the second latent dimension'};
            app.ChkBoxRevSignCA2.Text = 'Rev sign CA2';
            % Show the figure after all components are created
            app.FSDAdynamicinteractiveCAplotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CorAnaAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FSDAdynamicinteractiveCAplotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FSDAdynamicinteractiveCAplotUIFigure)
        end
    end
end