classdef biplotFS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FSDAdynamicinteractivebiplotUIFigure  matlab.ui.Figure
        SliderLabel          matlab.ui.control.Label
        SliderOmega          matlab.ui.control.Slider
        Label                matlab.ui.control.Label
        SliderAlpha          matlab.ui.control.Slider
        ChkBoxshowRowPoints  matlab.ui.control.CheckBox
        ChkBoxshowRowNames   matlab.ui.control.CheckBox
        ChkBoxshowArrows     matlab.ui.control.CheckBox
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        Xtable='';
        Property1 % Description
        omega='';
        Property2 % Description
        alpha='';
        
        Property3 % Description
        standardize;
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Xtable, varargin)
            [n,p]=size(Xtable);
            if istable(Xtable)
                X=table2array(Xtable);
                varlabs=Xtable.Properties.VariableNames;
                rownames=Xtable.Properties.RowNames;
            else
                varlabs=cellstr(num2str((1:p)','Y%d'));
                rownames=cellstr(num2str((1:n)','%d'));
                X=Xtable;
            end
            
            app.omega = 0.7;
            app.alpha = 0.3;
            showRowPoints=true;
            showRowNames=false;
            showArrows=true;
            app.standardize=true;
            
            if nargin>2
                
                options=struct('omega',app.omega,...
                    'alpha',app.alpha,'showRowPoints',showRowPoints,'showRowNames',showRowNames,...
                    'showArrows',showArrows,'standardize',app.standardize);
                
                UserOptions=varargin(1:2:length(varargin));
                if ~isempty(UserOptions)
                    % Check if number of supplied options is valid
                    if length(varargin) ~= 2*length(UserOptions)
                        error('FSDA:biplotFS:WrongInputOpt','Number of supplied options is invalid. Probably values for some parameters are missing.');
                    end
                    % Check if user options are valid options
                    chkoptions(options,UserOptions)
                end
                
                % Write in structure 'options' the options chosen by the user
                for i=1:2:length(varargin)
                    options.(varargin{i})=varargin{i+1};
                end
                app.alpha=options.alpha;
                % Set the supplied value of alpha in the corresponding
                % slider bar
                app.SliderAlpha.Value=app.alpha;
                app.omega=options.omega;
                % Set the supplied value of omega in the corresponding
                % slider bar
                app.SliderOmega.Value=app.omega;
                
                showRowPoints= options.showRowPoints;
                app.ChkBoxshowRowPoints.Value=showRowPoints;
                
                showRowNames = options.showRowNames;
                app.ChkBoxshowRowNames.Value=showRowNames;
                
                showArrows = options.showArrows;
                app.ChkBoxshowArrows.Value=showArrows;
                app.standardize=options.standardize;
            end


            if app.standardize==true
                % Create matrix of standardized data
                Z=zscore(X);
            else
                % Create matrix of deviations from the means
                Z=X-mean(X);
            end
            
            [U,Gammastar,V]=svd(Z,'econ');
            % tot var =
            totvar=sum(Gammastar.^2,'all');
            percexpl1=num2str(100*Gammastar(1,1)^2/totvar,4);
            percexpl2=num2str(100*Gammastar(2,2)^2/totvar,4);
            
            sqn1=sqrt(n-1);
            Gamma=Gammastar/sqn1;


            RowPoints=sqn1^app.omega*U(:,1:2)*Gamma(1:2,1:2)^app.alpha;
            % resetplotview(app.UIAxes,'InitializeCurrentView');
            if showRowPoints == true
                plot(app.UIAxes,RowPoints(:,1),RowPoints(:,2),'o')
            else
                % Remove the points
                set(findobj(app.UIAxes,'Marker','o'),'XData',[])
                set(findobj(app.UIAxes,'Marker','o'),'YData',[])
            end
            xlabel(app.UIAxes, ['PC1 % var explained='  percexpl1])
            ylabel(app.UIAxes, ['PC2 % var explained='  percexpl2])
            
            ColPoints=V(:,1:2)*(Gamma(1:2,1:2)^(1-app.alpha))*sqn1^(1-app.omega);
            zeroes = zeros(p,1);
            hold(app.UIAxes,'on')
            dx=0.001;
            dy=0.001;
            texttag='texttag';
            
            % First delete arrows before redrawing them
            delete(findobj(app.UIAxes,'Type','quiver'))
            delete(findobj(app.UIAxes,'Tag',texttag))
            
            if showArrows==true
                ddperc=0.95;
                quiver(app.UIAxes,zeroes,zeroes,ColPoints(:,1),ColPoints(:,2));
                text(app.UIAxes,ColPoints(:,1)*ddperc,ColPoints(:,2)*ddperc,varlabs,...
                    'Color','b','HorizontalAlignment',"center",...
                    "VerticalAlignment","baseline",'Tag',texttag);
            end
            
            
            if showRowNames ==true
                text(app.UIAxes,RowPoints(:,1)+dx,RowPoints(:,2)+dy,rownames,...
                    'Color','k','Tag',texttag);
            end
            
            % Store Xtable
            app.Xtable=Xtable;
            
            % set x and y limits
            kk=1;
            PuntiX=[RowPoints(:,1);ColPoints(:,1)];
            xlim(app.UIAxes,kk*[min(PuntiX),max(PuntiX)])
            PuntiY=[RowPoints(:,2);ColPoints(:,2)];
            ylim(app.UIAxes,kk*[min(PuntiY),max(PuntiY)])
            FontSize=16;
            height=0.95;
            d1=text(app.UIAxes, .01, height, '$Row \; points: (\sqrt{n-1})^{\omega}U \Gamma^{  \alpha  }$',...
                'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);
            d2=text(app.UIAxes, .450, height, '$Arrow \; coordinates: (\sqrt{n-1})^{1- \omega}V \Gamma^{1-\alpha}$',...
                'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);
            
            Xaxislim=app.UIAxes.XLim;
            % Add x axis
            line(app.UIAxes,kk*[Xaxislim(1);Xaxislim(2)], [0;0], 'Color','black');
            Yaxislim=app.UIAxes.YLim;
            % Add y axis
            line(app.UIAxes,[0;0],kk*[Yaxislim(1);Yaxislim(2)], 'Color','black');
            
            
            if app.omega==1 % && app.standardize==true
                if app.alpha==0
                    disp('When \omega=1 and \alpha=0')
                    disp('Row points represent standardized principal components sqrt(n-1)*U=ZV*\Gamma^-1')
                    disp('Arrows represent correlations between variables and principal components \Gamma*V')
                    disp('The length of the arrows is the communality of the variable')
                    disp('In this case the unit circle is also shown')
                    th = 0:pi/50:2*pi;
                    xunit = cos(th);
                    yunit = sin(th) ;
                    hold('on')
                    plot(app.UIAxes, xunit, yunit);
                    daspect(app.UIAxes,[1,1,1])
                    set(d1,"Visible","off")
                    set(d2,"Visible","off")
                    text(app.UIAxes, .95, height, '$Row \; points: (\sqrt{n-1})U$',...
                        'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);
                    text(app.UIAxes, 0.95, height-0.3, '$Arrow \; coordinates: V \Gamma$',...
                        'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);
                    
                elseif  app.alpha==1
                    disp('When \omega=1 and \alpha=1')
                    disp('Row points represent principal components sqrt(n-1)*U*\Gamma=ZV')
                    disp('Arrows represent the eigenvectors V')
                    disp('In this case the unit circle is also shown')
                    th = 0:pi/50:2*pi;
                    xunit = cos(th);
                    yunit = sin(th) ;
                    hold('on')
                    plot(app.UIAxes, xunit, yunit);
                    daspect(app.UIAxes,[1,1,1])
                    set(d1,"Visible","off")
                    set(d2,"Visible","off")
                    text(app.UIAxes, .95, height, '$Row \; points: (\sqrt{n-1})U\Gamma$',...
                        'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);
                    text(app.UIAxes, 0.95, height-0.3, '$Arrow \; coordinates: V$',...
                        'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);

                else
                    % Remove the circle if it is present
                    app.UIAxes.Position =[36,33,569,387];
                    app.UIAxes.DataAspectRatio = [0.6595092024539878,1,1];
                    app.UIAxes.PlotBoxAspectRatio= [1.5162790697674418,1,1];
                    daspect(app.UIAxes,'auto')
                end
            else
                app.UIAxes.Position =[36,33,569,387];
                app.UIAxes.DataAspectRatio = [0.6595092024539878,1,1];
                app.UIAxes.PlotBoxAspectRatio= [1.5162790697674418,1,1];
                daspect(app.UIAxes,'auto')
            end
            hold(app.UIAxes,'off')
        end

        % Value changed function: SliderAlpha
        function SliderAlphaValueChanged(app, event)
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize)
        end

        % Value changed function: SliderOmega
        function SliderOmegaValueChanged(app, event)
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize)
        end

        % Value changed function: ChkBoxshowRowPoints
        function ChkBoxshowRowPointsValueChanged(app, event)
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize)
        end

        % Value changed function: ChkBoxshowRowNames
        function ChkBoxshowRowNamesValueChanged(app, event)
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize)
        end

        % Value changed function: ChkBoxshowArrows
        function ChkBoxshowArrowsValueChanged(app, event)
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FSDAdynamicinteractivebiplotUIFigure = uifigure('Visible', 'off');
            app.FSDAdynamicinteractivebiplotUIFigure.Name = 'FSDA dynamic interactive biplot';
            app.SliderLabel = uilabel(app.FSDAdynamicinteractivebiplotUIFigure);
            app.SliderLabel.Text = 'ω';
            app.SliderOmega = uislider(app.FSDAdynamicinteractivebiplotUIFigure);
            app.SliderOmega.Limits = [0 1];
            app.SliderOmega.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.SliderOmega.ValueChangedFcn = createCallbackFcn(app, @SliderOmegaValueChanged, true);
            app.SliderOmega.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SliderOmega.Value = 0.7;
            app.Label = uilabel(app.FSDAdynamicinteractivebiplotUIFigure);
            app.Label.Text = 'α';
            app.SliderAlpha = uislider(app.FSDAdynamicinteractivebiplotUIFigure);
            app.SliderAlpha.Limits = [0 1];
            app.SliderAlpha.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.SliderAlpha.ValueChangedFcn = createCallbackFcn(app, @SliderAlphaValueChanged, true);
            app.SliderAlpha.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SliderAlpha.Value = 0.3;
            app.ChkBoxshowRowPoints = uicheckbox(app.FSDAdynamicinteractivebiplotUIFigure);
            app.ChkBoxshowRowPoints.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowRowPointsValueChanged, true);
            app.ChkBoxshowRowPoints.Text = 'showRowPoints';
            app.ChkBoxshowRowPoints.Value = true;
            app.ChkBoxshowRowNames = uicheckbox(app.FSDAdynamicinteractivebiplotUIFigure);
            app.ChkBoxshowRowNames.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowRowNamesValueChanged, true);
            app.ChkBoxshowRowNames.Text = 'showRowNames';
            app.ChkBoxshowArrows = uicheckbox(app.FSDAdynamicinteractivebiplotUIFigure);
            app.ChkBoxshowArrows.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowArrowsValueChanged, true);
            app.ChkBoxshowArrows.Text = 'showArrows';
            app.ChkBoxshowArrows.Value = true;
            app.UIAxes = uiaxes(app.FSDAdynamicinteractivebiplotUIFigure);
            title(app.UIAxes, ''' ''')
            xlabel(app.UIAxes, 'PC1')
            ylabel(app.UIAxes, 'PC2')
            % Show the figure after all components are created
            app.FSDAdynamicinteractivebiplotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = biplotFS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FSDAdynamicinteractivebiplotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FSDAdynamicinteractivebiplotUIFigure)
        end
    end
end