classdef CorAnaAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FSDAdynamicinteractiveCAplotUIFigure  matlab.ui.Figure
        indConfLevLabel         matlab.ui.control.Label
        FSMonitoringplotsPanel  matlab.ui.container.Panel
        CheckBoxMDforward       matlab.ui.control.CheckBox
        CheckBoxminMDLabels     matlab.ui.control.CheckBox
        CheckBoxInertia         matlab.ui.control.CheckBox
        CheckBoxminMD           matlab.ui.control.CheckBox
        RowProfilesPanel        matlab.ui.container.Panel
        CheckBoxShowSpmplot     matlab.ui.control.CheckBox
        CheckBoxShowMDwei       matlab.ui.control.CheckBox
        ReversesignPanel        matlab.ui.container.Panel
        CA1Switch               matlab.ui.control.Switch
        CA1SwitchLabel          matlab.ui.control.Label
        CA2Switch               matlab.ui.control.Switch
        CA2SwitchLabel          matlab.ui.control.Label
        ShoworHidePanel         matlab.ui.container.Panel
        ChkBoxshowColPointsSup  matlab.ui.control.CheckBox
        ChkBoxshowRowPointsSup  matlab.ui.control.CheckBox
        ChkBoxshowColPoints     matlab.ui.control.CheckBox
        ChkBoxshowRowPoints     matlab.ui.control.CheckBox
        MapTypeButtonGroup      matlab.ui.container.ButtonGroup
        ScatterDropDown         matlab.ui.control.DropDown
        MoonplotButton          matlab.ui.control.RadioButton
        ScatterButton           matlab.ui.control.RadioButton
        SliderAlpha             matlab.ui.control.Slider
        SliderAlphaLabel        matlab.ui.control.Label
        simulationsLabel        matlab.ui.control.Label
        EditFieldNsimul         matlab.ui.control.NumericEditField
        LampLabel               matlab.ui.control.Lamp
        BusyLabel               matlab.ui.control.Label
        EditFieldConfLev        matlab.ui.control.NumericEditField
        simultaneousconfidencelevelLabel  matlab.ui.control.Label
        CheckBoxSimulUnderH0    matlab.ui.control.CheckBox
        EditFieldConfLevEll     matlab.ui.control.NumericEditField
        LabelConfEllPerc        matlab.ui.control.Label
        ListBoxColsEll          matlab.ui.control.ListBox
        ListBoxColsEllLabel     matlab.ui.control.Label
        ListBoxRowsEll          matlab.ui.control.ListBox
        ChkBoxshowConfEll       matlab.ui.control.CheckBox
        ListBoxRowsEllLabel     matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        MCD                     matlab.ui.container.Tab
        SliderBDP               matlab.ui.control.Slider
        SliderBDPLabel          matlab.ui.control.Label
        ForwardSearch           matlab.ui.container.Tab
        ChkBoxAutomaticFS       matlab.ui.control.CheckBox
        SliderFS                matlab.ui.control.Slider
        SliderFSLabel           matlab.ui.control.Label
        ResetButton             matlab.ui.control.Button
        SuppColsListBox         matlab.ui.control.ListBox
        SuppColsListBoxLabel    matlab.ui.control.Label
        SuppRowsListBox         matlab.ui.control.ListBox
        SuppRowsListBoxLabel    matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % Property % Description
        Xtable='';
        % alpha parameter in the slider
        alpha='';
        % typeCA
        typeCA='rowprincipal'
        % slider bdp
        BDPslider=false;
        % slider FS
        FSslider=false;
    end

    properties (Access = private)
        RAW % Output from mcdCorAna
        outFS % Output from FSCorAna
    end


    methods (Access = private)

        function setlight(app, ~, busy)
            if busy == true
                app.LampLabel.Color='r';
                drawnow;
            else
                app.LampLabel.Color='g';
                drawnow;
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Xtable, varargin)
            [I,J]=size(Xtable);
            if istable(Xtable) || istimetable(Xtable)
                colnames=Xtable.Properties.VariableNames;
                if istable(Xtable)
                    rownames=Xtable.Properties.RowNames;
                else
                    rownames=string(Xtable.Properties.RowTimes);
                end
                N=table2array(Xtable);
            else
                colnames=cellstr(num2str((1:J)','Y%d'));
                rownames=cellstr(num2str((1:I)','%d'));
                Xtable=array2table(X,"RowNames",rownames,"VariableNames",colnames);
                N=Xtable;
            end

            n=sum(N,'all');

            app.alpha = 0.3;

            if ~isempty(app.SuppColsListBox.Value)
                selCols=setdiff(1:J,app.SuppColsListBox.Value);
                nred=sum(N(:,selCols),'all');
                ini=round(nred/4);
            else
                selCols=1:J;
                nred=n;
                ini=round(n/4);
            end
            NselCols=N(:,selCols);
            NselColstable=Xtable(:,selCols);
            nidot=sum(NselCols,2);
            ProfileRows=NselCols./nidot;
            r=nidot/nred;

            % Set sliders for bdp and subset size
            selsteps=round(nred*[0.25 0.33 0.4 0.5:0.1:1]);
            app.SliderFS.Limits=[ini nred];
            app.SliderFS.MajorTicks=selsteps;
            app.SliderFS.MinorTicks=round(nred*[0.3 0.45:0.10:0.95]);
            app.SliderFS.MajorTickLabels={'1/4' '1/3' '0.4' '1/2' '0.6' '0.7' '0.8' '0.9' '1'};
            % app.SliderFSLabel.Text={'Subset size/n' ['n=' num2str(nred)]};
            app.SliderFSLabel.Text={'Subset size/n'};
            app.SliderBDPLabel.Text=['(n=' num2str(nred) ') bdp'];

            % Fill the list box just the very first time the APP is
            % launched
            if isempty(app.SuppRowsListBox.Items)
                app.SuppRowsListBox.Items=rownames;
                app.SuppRowsListBox.ItemsData=1:I;
                app.SuppRowsListBox.Multiselect='on';
                app.SuppColsListBox.Items=colnames;
                app.SuppColsListBox.ItemsData=1:J;
                app.SuppColsListBox.Multiselect='on';
            end

            app.typeCA='bothprincipal';
            app.Xtable=Xtable;


            %             % Optional arguments TODO
            %             if nargin>2
            %
            %                 options=struct('BDPslider',BDPslider,'FSslider',FSslider);
            %
            %                 UserOptions=varargin(1:2:length(varargin));
            %                 if ~isempty(UserOptions)
            %                     % Check if number of supplied options is valid
            %                     if length(varargin) ~= 2*length(UserOptions)
            %                         error('FSDA:CorAnaAPP:WrongInputOpt','Number of supplied options is invalid. Probably values for some parameters are missing.');
            %                     end
            %                     % Check if user options are valid options
            %                     chkoptions(options,UserOptions)
            %                 end
            %
            %                 % Write in structure 'options' the options chosen by the user
            %                 for i=1:2:length(varargin)
            %                     options.(varargin{i})=varargin{i+1};
            %                 end
            %                 BDPslider=options.BDPslider;
            %                 FSslider=options.FSslider;
            %             end

            plots=struct;
            if strcmp(app.ScatterDropDown.Value,'numeric value α in [0 1]')
                app.SliderAlpha.Visible='on';
                app.SliderAlphaLabel.Visible='on';
                plots.alpha=app.SliderAlpha.Value;
            else
                app.SliderAlpha.Visible='off';
                app.SliderAlphaLabel.Visible='off';
                plots.alpha=app.ScatterDropDown.Value;
            end

            if app.ChkBoxshowRowPoints.Value==true
                plots.ColorRows='b';
            else
                plots.ColorRows='w';
            end

            if app.ChkBoxshowRowPointsSup.Value==true
                plots.ColorRowsSup='b';
            else
                plots.ColorRowsSup='w';
            end


            if app.ChkBoxshowColPoints.Value==true
                plots.ColorCols='r';
            else
                plots.ColorCols='w';
            end

            if app.ChkBoxshowColPointsSup.Value==true
                plots.ColorColsSup='r';
            else
                plots.ColorColsSup='w';
            end

            % Example of findEmpiricalEnvelope a struct
            findEmp=struct;
            % Generate nsimul contingency tables
            findEmp.nsimul=app.EditFieldNsimul.Value;
            % Under the null hypothesis of independence
            findEmp.underH0=app.CheckBoxSimulUnderH0.Value;
            % Store the nsimul robust distance sorted (for each row)
            findEmp.StoreSim=true;
            conflevMCD=1-(1-app.EditFieldConfLev.Value/100)/I;
            conflevFS=app.EditFieldConfLev.Value/100;

            app.indConfLevLabel.Text=['Ind. conf. lev=' num2str(100*conflevMCD) '%'];

            if app.CheckBoxInertia.Value == true || app.CheckBoxminMD.Value == true || app.CheckBoxMDforward.Value == true
                app.BDPslider=false;
                app.FSslider = true;
            end

            if app.BDPslider==true  % slider MCD case

                % begin scanning
                setlight(app, 1, true);
                app.BusyLabel.Visible = true;
                drawnow

                % call mcdCorAna independently of the value of bdp
                if ~isempty(app.RAW) && isequal(app.RAW.N,NselCols) && abs(app.RAW.bdp-(0.5-app.SliderBDP.Value))<1e-06 ...
                        && app.RAW.simulateUnderH0==app.CheckBoxSimulUnderH0.Value ...
                        && app.RAW.conflev==conflevMCD && size(app.RAW.NsimStore,2)==findEmp.nsimul
                    % In this case mcd has already been computed using the
                    % same variables (columns) and using the simulation
                    % mechanism (underH0 or underH1) and the same
                    % confidence level and that the number of
                    % simulations has not changed
                else
                    app.RAW=mcdCorAna(NselCols,'bdp',0.5-app.SliderBDP.Value, ...
                        'findEmpiricalEnvelope',findEmp,'conflev',conflevMCD);
                end

                % if (0.5-app.SliderBDP.Value)~=0

                bsel=ones(I,1);
                bsel(app.RAW.outliers)=0;


                if app.CheckBoxShowMDwei.Value == true
                    MD=mahalCorAna(ProfileRows,app.RAW.loc).*r;
                    EmpEnv=app.RAW.EmpEnv.*r;
                    nout=length(app.RAW.outliers);
                    titl=['Squared MD multiplied by weights when bdp=' num2str(0.5-app.SliderBDP.Value)];
                    malindexplot(MD,EmpEnv,'ylimy',[0 max([MD;EmpEnv])*1.05],'title',titl,'label',rownames,'numlab',{nout});
                    legend off
                else
                    close(findobj('type','figure','Tag','pl_malindex'))
                end


                if app.CheckBoxShowSpmplot.Value == true
                    group=repelem("Good units",I,1);
                    group(bsel==0)="Outliers";
                    clr='rbk';
                    sym='o+*';
                    plo=struct;
                    unigroup=unique(group);
                    lunigroup=length(unigroup);
                    plo.clr=clr(1:lunigroup);
                    plo.sym=sym(1:lunigroup);


                    plo.nameY=NselColstable.Properties.VariableNames;
                    % Check if spmplot already exists
                    close(findobj('type','figure','Tag','pl_spm'))

                    if ~isempty(findobj('type','figure','Tag','pl_malindex'))
                        figure
                    end
                    spmplot(ProfileRows,group,plo,'box');
                else
                    % Close spmplot if already exists
                    close(findobj('type','figure','Tag','pl_spm'))
                end

                % Reset to null app.SuppRowsListBox.Value
                app.SuppRowsListBox.Value=[];
                if ~isempty(app.RAW.outliers)
                    app.SuppRowsListBox.Value=app.RAW.outliers;
                end
                % end of mcd case bdp>0

                % else % no unit is declared as outlier given that bdp=0
                %
                %     if app.CheckBoxShowMDwei.Value == true
                %         ym=sum(NselCols,1)/nred;
                %         MD=mahalCorAna(ProfileRows,ym).*r;
                %         titl='Squared MD multiplied by weights when bdp=0 (with theoretical envelopes)';
                %         EmpEnv=repmat(chi2inv(conflevMCD,(J-1)*(I-1))/(nred),I,1);
                %
                %         malindexplot(MD,EmpEnv,'ylimy',[0 max([MD;EmpEnv])*1.05],'title',titl,'label',rownames);
                %         legend off
                %     else
                %         close(findobj('type','figure','Tag','pl_malindex'))
                %     end
                %
                %     if app.CheckBoxShowSpmplot.Value == true
                %         group=repelem("Units inside",I,1);
                %         plo=struct;
                %         plo.nameY=NselColstable.Properties.VariableNames;
                %         % Check if spmplot already exists
                %         close(findobj('type','figure','Tag','pl_spm'))
                %
                %         if ~isempty(findobj('type','figure','Tag','pl_malindex'))
                %             figure
                %         end
                %         spmplot(ProfileRows,group,plo,'box');
                %     end
                %
                %     app.SuppRowsListBox.Value=[];
                % end
                % finished scanning
                app.BusyLabel.Visible = false;
                setlight(app, 1, false);


                Sup=struct;
                if ~isempty(app.SuppRowsListBox.Value)
                    Sup.r=app.SuppRowsListBox.Value;
                end
                if ~isempty(app.SuppColsListBox.Value)
                    Sup.c=app.SuppColsListBox.Value;
                end
                out=CorAna(Xtable,'plots',0,'Sup',Sup);
                % end of mcd case bdp=0

                % beginning of FS case m<n
            elseif   app.FSslider==true %% && round(app.SliderFS.Value)~=nred
                % begin scanning
                setlight(app, 1, true);
                app.BusyLabel.Visible = true;
                drawnow

                if ~isempty(app.RAW) && isequal(app.RAW.N,NselCols) && app.RAW.bdp>=0.25 ...
                        && app.RAW.simulateUnderH0==app.CheckBoxSimulUnderH0.Value && size(app.RAW.NsimStore,2)==findEmp.nsimul
                    % In this case mcd has already been computed using the
                    % same variables (columns) and using the simulation
                    % mechanism (underH0 or underH1) and that the number of
                    % simulations has not changed. Note that for the FS
                    % it does not matter the confidence level used by MCD

                else
                    % selCols allows the case where not all cols are considered
                    app.RAW=mcdCorAna(NselCols,'bdp',0.5,'plots',0, ...
                        'findEmpiricalEnvelope',findEmp);
                end

                if app.ChkBoxAutomaticFS.Value == true

                    ini=round(sum(NselCols,"all")/4);
                    % Automatic detection of outliers in FS
                    if ~isempty(app.outFS) && isequal(app.outFS.N,NselCols) && app.outFS.conflev==conflevFS ...
                            && app.outFS.simulateUnderH0==app.CheckBoxSimulUnderH0.Value  && app.outFS.nsimul==findEmp.nsimul
                    else
                        app.outFS=FSCorAna(app.RAW,'init',ini,'plots',0,'conflev',conflevFS);
                    end

                    % define unitsinside
                    Nsel=NselCols;
                    Nsel(app.outFS.outliers,:)=[];
                    unitsinside=setdiff(1:I,app.outFS.outliers);
                    bsel=ones(I,1);
                    bsel(app.outFS.outliers)=0;
                else
                    % FS with EDA purposes
                    ini=round(sum(NselCols,"all")/5);
                    outFSeda=FSCorAnaeda(app.RAW,'init',ini,'plots',0);
                    if round(app.SliderFS.Value)<n
                        pos=find(outFSeda.Un(:,1)==round(app.SliderFS.Value));
                        bsel=outFSeda.BB(:,pos+1);
                        units0Weight=isnan(bsel);
                        bsel(units0Weight)=0;
                        Nsel=NselCols.*bsel;
                    else
                        bsel=ones(I,1);
                        Nsel=NselCols;
                    end
                    unitsinside=sum(Nsel,2)>0;

                end
                % finished scanning
                app.BusyLabel.Visible = false;
                setlight(app, 1, false);

                if app.CheckBoxminMD.Value == true
                    close(findobj('type','figure','Tag','pl_mmd'))
                    figure

                    if app.ChkBoxAutomaticFS.Value == true
                        plot(app.outFS.mmd(:,1),app.outFS.mmd(:,2))

                        hold on
                        % include specified tag in the current plot
                        gmmd=app.outFS.mmd(:,[1 3:end]);

                        lwdenv=2;
                        % Superimpose 50% envelope
                        line(gmmd(:,1),gmmd(:,3),'LineWidth',lwdenv,'LineStyle','--','Color','g','tag','env');
                        % Superimpose alpha1% and alpha2% envelope
                        line(gmmd(:,1),gmmd(:,2),'LineWidth',lwdenv,'LineStyle','--','Color',[0.2 0.8 0.4],'tag','env');
                        line(gmmd(:,1),gmmd(:,4),'LineWidth',lwdenv,'LineStyle','--','Color',[0.2 0.8 0.4],'tag','env');
                        yline(max(gmmd(:,end)))
                        hold off

                        if app.CheckBoxminMDLabels.Value == true
                            sel=~ismissing(app.outFS.Un(:,end));
                            text(app.outFS.mmd(sel,1),app.outFS.mmd(sel,2)*1.05,rownames(app.outFS.Un(sel,end)));
                        end

                        % add the horizontal line
                    else
                        % FS with EDA purposes
                        ini=round(sum(NselCols,"all")/5);
                        if ~isempty(app.RAW)
                            outFSeda=FSCorAnaeda(app.RAW,'init',ini,'plots',0);
                        else
                            outFSeda=FSCorAnaeda(NselCols,'init',ini,'plots',0);
                        end

                        plot(outFSeda.mmd(2:end,1),outFSeda.mmd(2:end,2))
                        if app.CheckBoxminMDLabels.Value == true
                            sel=~ismissing(outFSeda.Un(:,end));
                            text(outFSeda.mmd(sel,1),outFSeda.mmd(sel,2)*1.05,rownames(outFSeda.Un(sel,end)));
                        end

                    end
                    set(gcf,'tag','pl_mmd');
                    set(gcf,'Name', 'Monitoring of min MD (weighted)', 'NumberTitle', 'off');
                    xlabel('Subset size m');
                    ylabel('Monitoring of min MD');
                    % title('minMD')
                else
                    app.CheckBoxminMDLabels.Value = false;
                    close(findobj('type','figure','Tag','pl_mmd'))
                end

                % Niter=Nsel(unitsinside,:);
                if app.CheckBoxShowMDwei.Value == true
                    mm=sum(Nsel,'all');
                    ym=sum(Nsel,1)/mm;
                    if app.ChkBoxAutomaticFS.Value == true
                        distsq=mahalCorAna(ProfileRows,ym);
                        MD=distsq.*r;
                        v=sqrt(distsq).*app.outFS.thresh/n;
                        titl=['Squared MD multiplied by weights when m=' num2str(sum(Nsel,'all'))];
                        nout=length(app.outFS.outliers);
                    else
                        MD=mahalCorAna(ProfileRows,ym).*r;
                        titl=['Squared MD multiplied by weights when m=' num2str(mm)];
                        nout=5;
                        v=1;
                    end
                    malindexplot(MD,v,'ylimy',[0 max(MD)*1.05],'title',titl,'label',rownames,'numlab',{nout});
                    legend off
                else
                    close(findobj('type','figure','Tag','pl_malindex'))
                end

                if app.CheckBoxShowSpmplot.Value == true
                    group=repelem("Units inside",I,1);
                    group(bsel==0)="Units outside";
                    group(bsel>0 & bsel<1)="Unit partially represented";
                    clr='rbk';
                    sym='o+*';
                    plo=struct;
                    unigroup=unique(group);
                    lunigroup=length(unigroup);
                    plo.clr=clr(1:lunigroup);
                    plo.sym=sym(1:lunigroup);


                    plo.nameY=NselColstable.Properties.VariableNames;
                    % Check if spmplot already exists
                    close(findobj('type','figure','Tag','pl_spm'))

                    if ~isempty(findobj('type','figure','Tag','pl_malindex'))
                        figure
                    end
                    spmplot(ProfileRows,group,plo,'box');
                else
                    % Close spmplot if already exists
                    close(findobj('type','figure','Tag','pl_spm'))
                end

                % Monitoring plot of inertia explained
                if app.CheckBoxInertia.Value == true
                    close(findobj('type','figure','Tag','pl_in'))
                    figure

                    if app.ChkBoxAutomaticFS.Value == true
                        plot(app.outFS.ine(:,1),app.outFS.ine(:,2))

                        hold on
                        % include specified tag in the current plot
                        gine=app.outFS.ine(:,[1 3:end]);

                        lwdenv=2;
                        % Superimpose 50% envelope
                        line(gine(:,1),gine(:,3),'LineWidth',lwdenv,'LineStyle','--','Color','g','tag','env');
                        % Superimpose alpha1% and alpha2% envelope
                        line(gine(:,1),gine(:,2),'LineWidth',lwdenv,'LineStyle','--','Color',[0.2 0.8 0.4],'tag','env');
                        line(gine(:,1),gine(:,4),'LineWidth',lwdenv,'LineStyle','--','Color',[0.2 0.8 0.4],'tag','env');
                        hold off
                    else
                        plot(outFSeda.ine(2:end,1),outFSeda.ine(2:end,2))
                    end
                    set(gcf,'tag','pl_in');
                    set(gcf,'Name', 'Monitoring of inertia', 'NumberTitle', 'off');
                    xlabel('Subset size m');
                    ylabel('Monitoring of total inertia');
                    % title('Total inertia')
                else
                    close(findobj('type','figure','Tag','pl_in'))
                end

                % Monitoring plot of forward Mahalanobis distances
                if app.CheckBoxMDforward.Value == true
                    close(findobj('type','figure','Tag','pl_malfwd'))

                    if app.ChkBoxAutomaticFS.Value == true

                        if ~exist(outFSeda,1)
                            % FS with EDA purposes
                            ini=round(sum(NselCols,"all")/5);
                            if ~isempty(app.RAW)
                                outFSeda=FSCorAnaeda(app.RAW,'init',ini,'plots',0);
                            else
                                outFSeda=FSCorAnaeda(NselCols,'init',ini,'plots',0);
                            end
                        end
                    end
                    outFSeda.Ntable.Properties.RowNames=rownames;

                    malfwdplot(outFSeda)
                    set(gcf,'tag','pl_malfwd');
                    set(gcf,'Name', 'Monitoring MD', 'NumberTitle', 'off');
                    xlabel('Subset size m');
                    ylabel('Monitoring forward MD (weighted)');
                    % title('Total inertia')
                else
                    close(findobj('type','figure','Tag','pl_malfwd'))
                end


                NiterWithSupCols=N(unitsinside,:);
                Nitertable=array2table(NiterWithSupCols,"RowNames",rownames(unitsinside),"VariableNames",colnames);
                NselSup=NselCols.*(1-bsel);
                unitsoutside=sum(NselSup,2)>0;

                Sup=struct;
                app.SuppRowsListBox.Value=[];

                if round(app.SliderFS.Value)<n
                    % Note that we are in the situation in which  app.SliderFS.Value~=n
                    % therefore there are always (partial) supplementary rows
                    NiterSup=NselSup(unitsoutside,:);
                    NiterSuptable=array2table(NiterSup,"RowNames",rownames(unitsoutside),"VariableNames",colnames(selCols));
                    seqI=1:I;
                    app.SuppRowsListBox.Value=seqI(unitsoutside);
                    Sup.r=NiterSuptable;
                end

                % Keep into account the supplementary columns
                if ~isempty(app.SuppColsListBox.Value)
                    Sup.c=app.SuppColsListBox.Value;
                end
                out=CorAna(Nitertable,'plots',0,'Sup',Sup);
                % end FS case m<n

                %     % Beginning of FS case all units considered
                %   elseif round(app.SliderFS.Value)==nred && app.FSslider==true
                %
                %     if app.CheckBoxInertia.Value == true
                %         close(findobj('type','figure','Tag','pl_in'))
                %         figure
                %
                %         if app.ChkBoxAutomaticFS.Value == true
                %             plot(app.outFS.ine(:,1),app.outFS.ine(:,2))
                %
                %             hold on
                %             % include specified tag in the current plot
                %             gine=app.outFS.ine(:,[1 3:end]);
                %
                %             lwdenv=2;
                %             % Superimpose 50% envelope
                %             line(gine(:,1),gine(:,3),'LineWidth',lwdenv,'LineStyle','--','Color','g','tag','env');
                %             % Superimpose alpha1% and alpha2% envelope
                %             line(gine(:,1),gine(:,2),'LineWidth',lwdenv,'LineStyle','--','Color',[0.2 0.8 0.4],'tag','env');
                %             line(gine(:,1),gine(:,4),'LineWidth',lwdenv,'LineStyle','--','Color',[0.2 0.8 0.4],'tag','env');
                %             hold off
                %         else
                %             % FS with EDA purposes
                %             ini=round(sum(NselCols,"all")/5);
                %             if ~isempty(app.RAW)
                %                 outFSeda=FSCorAnaeda(app.RAW,'init',ini,'plots',0);
                %             else
                %                 outFSeda=FSCorAnaeda(NselCols,'init',ini,'plots',0);
                %             end
                %
                %             plot(outFSeda.ine(2:end,1),outFSeda.ine(2:end,2))
                %         end
                %         set(gcf,'tag','pl_in');
                %         set(gcf,'Name', 'Monitoring of inertia', 'NumberTitle', 'off');
                %         xlabel('Subset size m');
                %         ylabel('Monitoring of inertia');
                %         title('Total inertia')
                %     else
                %         close(findobj('type','figure','Tag','pl_in'))
                %     end
                %
                %     if app.CheckBoxminMD.Value == true
                %         close(findobj('type','figure','Tag','pl_mmd'))
                %         figure
                %
                %         if app.ChkBoxAutomaticFS.Value == true
                %             plot(app.outFS.mmd(:,1),app.outFS.mmd(:,2))
                %
                %             hold on
                %             % include specified tag in the current plot
                %             gmmd=app.outFS.mmd(:,[1 3:end]);
                %
                %             lwdenv=2;
                %             % Superimpose 50% envelope
                %             line(gmmd(:,1),gmmd(:,3),'LineWidth',lwdenv,'LineStyle','--','Color','g','tag','env');
                %             % Superimpose alpha1% and alpha2% envelope
                %             line(gmmd(:,1),gmmd(:,2),'LineWidth',lwdenv,'LineStyle','--','Color',[0.2 0.8 0.4],'tag','env');
                %             line(gmmd(:,1),gmmd(:,4),'LineWidth',lwdenv,'LineStyle','--','Color',[0.2 0.8 0.4],'tag','env');
                %             hold off
                %         else
                %             % FS with EDA purposes
                %             ini=round(sum(NselCols,"all")/5);
                %             if ~isempty(app.RAW)
                %                 outFSeda=FSCorAnaeda(app.RAW,'init',ini,'plots',0);
                %             else
                %                 outFSeda=FSCorAnaeda(NselCols,'init',ini,'plots',0);
                %             end
                %
                %             plot(outFSeda.mmd(2:end,1),outFSeda.mmd(2:end,2))
                %         end
                %         set(gcf,'tag','pl_mmd');
                %         set(gcf,'Name', 'Monitoring of min MD (weighted)', 'NumberTitle', 'off');
                %         xlabel('Subset size m');
                %         ylabel('Monitoring of min MD');
                %         % title('minMD')
                %     else
                %         close(findobj('type','figure','Tag','pl_mmd'))
                %     end
                %
                %     if app.CheckBoxShowMDwei.Value == true
                %
                %         ym=sum(NselCols,1)/nred;
                %         MD=mahalCorAna(ProfileRows,ym).*r;
                %         titl=['Square MD multiplied by weights when m=n=' num2str(nred)];
                %         malindexplot(MD,1,'ylimy',[0 max(MD)*1.05],'title',titl,'label',rownames);
                %         legend off
                %     else
                %         close(findobj('type','figure','Tag','pl_malindex'))
                %     end
                %
                %     if app.CheckBoxShowSpmplot.Value == true
                %         group=repelem("Units inside",I,1);
                %         plo=struct;
                %         plo.nameY=NselColstable.Properties.VariableNames;
                %         % Check if spmplot already exists
                %         close(findobj('type','figure','Tag','pl_spm'))
                %
                %         if ~isempty(findobj('type','figure','Tag','pl_malindex'))
                %             figure
                %         end
                %         spmplot(ProfileRows,group,plo,'box');
                %     end
                %
                %     app.SuppRowsListBox.Value=[];
                %     Sup=struct;
                %     if ~isempty(app.SuppColsListBox.Value)
                %         Sup.c=app.SuppColsListBox.Value;
                %     end
                %     out=CorAna(Xtable,'plots',0,'Sup',Sup);
                %     % end of FS all units are considered

                % Beginning of traditional analysis no FS no MCD
            else
                % There is no FS or no MCD
                % There are possibly just supplementary rows or supp cols
                Sup=struct;
                if isempty(app.SuppRowsListBox.Value) &&  isempty(app.SuppColsListBox.Value)
                    out=CorAna(Xtable,'plots',0);
                else
                    if  ~isempty(app.SuppColsListBox.Value)
                        Sup.c=app.SuppColsListBox.Value;
                    end
                    if  ~isempty(app.SuppRowsListBox.Value)
                        Sup.r=app.SuppRowsListBox.Value;
                    end
                    out=CorAna(Xtable,'plots',0,'Sup',Sup);
                end
                % Compute the centroid without the supplementary rows and
                % take into account the possible presence of supplementary
                % columns
                NselCols=N;
                if isfield(Sup,'c')
                    NselCols(:,Sup.c)=[];
                end
                if isfield(Sup,'r')
                    NselCols(Sup.r,:)=[];
                end

                nred=sum(NselCols,'all');

                if app.CheckBoxShowMDwei.Value == true

                    ym=sum(NselCols,1)/nred;
                    MD=mahalCorAna(ProfileRows,ym).*r;
                    titl=['Squared MD (weighted) when n=' num2str(nred) ' (with theo. envelopes under H0)'];
                    EmpEnv=repmat(chi2inv(conflevMCD,(J-1)*(I-1))/(nred),I,1);
                    malindexplot(MD,EmpEnv,'ylimy',[0 max([MD;EmpEnv])*1.05],'title',titl,'label',rownames);

                    legend off
                else
                    close(findobj('type','figure','Tag','pl_malindex'))
                end

                if app.CheckBoxShowSpmplot.Value == true
                    NselColstable=Xtable;
                    if isfield(Sup,'c')
                        NselColstable(:,Sup.c)=[];
                    end

                    group=repelem("Active units",I,1);
                    if isfield(Sup,'r')
                        group(Sup.r)="Supplementary units";
                    end
                    plo=struct;
                    plo.nameY=NselColstable.Properties.VariableNames;
                    clr='rb';
                    sym='o+';
                    unigroup=unique(group);
                    lunigroup=length(unigroup);
                    plo.clr=clr(1:lunigroup);
                    plo.sym=sym(1:lunigroup);


                    % Check spmplot if already exists
                    close(findobj('type','figure','Tag','pl_spm'))

                    if ~isempty(findobj('type','figure','Tag','pl_malindex'))
                        figure
                    end
                    spmplot(ProfileRows,group,plo,'box');
                else
                    % Close spmplot if already exists
                    close(findobj('type','figure','Tag','pl_spm'))
                end

            end
            % disp('sulcols')
            % disp(app.SuppColsListBox.Value)


            delete(app.UIAxes.Children)

            changedimsign=[false false];
            if app.CA1Switch.Value == true
                changedimsign(1)=true;
            end
            if app.CA2Switch.Value == true
                changedimsign(2)=true;
            end

            if app.ChkBoxshowConfEll.Value == true
                app.ListBoxRowsEll.Visible ="on";
                app.ListBoxRowsEllLabel.Visible="on";
                app.ListBoxColsEllLabel.Visible = "on";
                app.ListBoxColsEll.Visible ="on";
                app.EditFieldConfLevEll.Visible ="on";
                app.LabelConfEllPerc.Visible = "on";
            else
                app.ListBoxRowsEll.Visible ="off";
                app.ListBoxRowsEllLabel.Visible = "off";
                app.ListBoxColsEll.Visible ="off";
                app.ListBoxColsEllLabel.Visible = "off";
                app.EditFieldConfLevEll.Visible ="off";
                app.LabelConfEllPerc.Visible = "off";
            end

            if app.MoonplotButton.Value == true
                moonplot(out,'h',app.UIAxes,'plots',plots,'changedimsign',changedimsign)
            else

                if app.ChkBoxshowConfEll.Value==true
                    rownamsel=out.Ntable.Properties.RowNames;
                    rownamsel(end+1)={'none'};
                    app.ListBoxRowsEll.Items=rownamsel;
                    app.ListBoxRowsEll.ItemsData=1:length(rownamsel)+1;

                    colnamsel=out.Ntable.Properties.VariableNames;
                    colnamsel(end+1)={'none'};
                    app.ListBoxColsEll.Items=colnamsel;
                    app.ListBoxColsEll.ItemsData=1:length(colnamsel)+1;

                    confellipse=struct;
                    % Get the selected entries
                    rowssel=app.ListBoxRowsEll.Value;
                    % If the empty choice has also been selected remove it
                    if ~iscell(rowssel) && rowssel(end)==I+1
                        rowssel(rowssel>length(rownamsel)-1)=[];
                    end
                    confellipse.selRows=rowssel;

                    % Get the selected entries
                    colssel=app.ListBoxColsEll.Value;
                    % If the empty choice has also been selected remove it
                    if  ~iscell(colssel) && colssel(end)==J+1
                        colssel(colssel>length(colnamsel)-1)=[];
                    end
                    confellipse.selCols=colssel;
                    confellipse.conflev=app.EditFieldConfLevEll.Value/100;
                else
                    confellipse=0;
                end

                CorAnaplot(out,'h',app.UIAxes,'plots',plots,'changedimsign',changedimsign,'confellipse',confellipse)
            end

        end

        % Value changed function: SliderAlpha
        function SliderAlphaValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: SliderBDP
        function SliderBDPValueChanged(app, event)
            app.BDPslider=true;
            app.FSslider=false;
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowRowPoints
        function ChkBoxshowRowPointsValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowColPoints
        function ChkBoxshowColPointsValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ScatterDropDown
        function ScatterDropDownValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: SuppRowsListBox
        function SuppRowsListBoxValueChanged(app, event)
            % If the user selects manually supplementary rows therefore
            % there is no MCD and no FS
            app.BDPslider=false;
            app.FSslider=false;
            startupFcn(app, app.Xtable)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.SuppRowsListBox.Value=[];
            app.SuppColsListBox.Value=[];
            n=sum(app.Xtable{:,:},'all');

            % Reset app.RAW
            app.RAW=[];

            % Set sliders for bdp and subset size
            selsteps=round(n*[0.25 0.33 0.4 0.5:0.1:1]);
            app.SliderFS.Limits=[round(n/4) n];
            app.SliderFS.MajorTicks=selsteps;
            app.SliderFS.MinorTicks=round(n*[0.3 0.45:0.10:0.95]);
            app.SliderFS.MajorTickLabels={'1/4' '1/3' '0.4' '1/2' '0.6' '0.7' '0.8' '0.9' '1'};
            app.SliderFS.Value=n;
            app.SliderFSLabel.Text={'Subset size/n'};
            app.SliderBDP.Value=0.5; % note that app.SliderBDP.Value=0.5 corresponds to bdp=0
            app.SliderBDPLabel.Text=['(n=' num2str(n) ') bdp'];

            % reset check box related to sign of latent components
            app.CA1Switch.Value=false;
            app.CA2Switch.Value=false;
            % reset check boxes relate to visualization of row (supp) and
            % column (supp) points
            app.ChkBoxshowRowPoints.Value=true;
            app.ChkBoxshowRowPointsSup.Value=true;
            app.ChkBoxshowColPoints.Value=true;
            app.ChkBoxshowColPointsSup.Value=true;
            app.ChkBoxshowConfEll.Value=false;

            app.ListBoxRowsEll.Visible ="off";
            app.ListBoxRowsEllLabel.Visible="off";
            app.ListBoxRowsEll.Value={};
            app.ListBoxColsEll.Visible ="off";
            app.ListBoxColsEllLabel.Visible="off";
            app.ListBoxColsEll.Value={};

            app.EditFieldConfLevEll.Visible = "off";
            app.CheckBoxInertia.Value=false;
            app.ChkBoxAutomaticFS.Value=false;
            app.CheckBoxminMD.Value=false;
            out=CorAna(app.Xtable,'plots',0);
            delete(app.UIAxes.Children)
            if app.MoonplotButton.Value == false
                CorAnaplot(out,'h',app.UIAxes);
            else
                moonplot(out,'h',app.UIAxes);
            end
            %  startupFcn(app, app.Xtable)
        end

        % Value changed function: SuppColsListBox
        function SuppColsListBoxValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowRowPointsSup
        function ChkBoxshowRowPointsSupValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowColPointsSup
        function ChkBoxshowColPointsSupValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: SliderFS
        function SliderFSValueChanged(app, event)
            app.BDPslider=false;
            app.FSslider=true;
            app.ChkBoxAutomaticFS.Value =false;
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowConfEll
        function ChkBoxshowConfEllValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ListBoxRowsEll
        function ListBoxRowsEllValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ListBoxColsEll
        function ListBoxColsEllValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: EditFieldConfLevEll
        function EditFieldConfLevEllValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxAutomaticFS
        function ChkBoxAutomaticFSValueChanged(app, event)
            app.SliderFS.Value=app.SliderFS.Limits(1)+1;
            app.BDPslider=false;
            app.FSslider=true;
            startupFcn(app, app.Xtable)
        end

        % Value changed function: CheckBoxSimulUnderH0
        function CheckBoxSimulUnderH0ValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: EditFieldNsimul
        function EditFieldNsimulValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: CheckBoxShowMDwei
        function CheckBoxShowMDweiValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: CheckBoxShowSpmplot
        function CheckBoxShowSpmplotValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: EditFieldConfLev
        function EditFieldConfLevValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: CheckBoxInertia
        function CheckBoxInertiaValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: CheckBoxminMD
        function CheckBoxminMDValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Selection changed function: MapTypeButtonGroup
        function MapTypeButtonGroupSelectionChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: CA1Switch
        function CA1SwitchValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: CA2Switch
        function CA2SwitchValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: CheckBoxminMDLabels
        function CheckBoxminMDLabelsValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: CheckBoxMDforward
        function CheckBoxMDforwardValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FSDAdynamicinteractiveCAplotUIFigure = uifigure('Visible', 'off');
            app.FSDAdynamicinteractiveCAplotUIFigure.Name = 'FSDA dynamic interactive biplot';
            app.UIAxes = uiaxes(app.FSDAdynamicinteractiveCAplotUIFigure);
            title(app.UIAxes, ''' ''')
            xlabel(app.UIAxes, 'CA1')
            ylabel(app.UIAxes, 'CA2')
            app.SuppRowsListBoxLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SuppRowsListBoxLabel.Text = 'SuppRows';
            app.SuppRowsListBox = uilistbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SuppRowsListBox.Items = {};
            app.SuppRowsListBox.ValueChangedFcn = createCallbackFcn(app, @SuppRowsListBoxValueChanged, true);
            app.SuppRowsListBox.Tooltip = {'Select the rows points which must be treated as supplementary units'};
            app.SuppRowsListBox.Value = {};
            app.SuppColsListBoxLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SuppColsListBoxLabel.Text = 'SuppCols';
            app.SuppColsListBox = uilistbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SuppColsListBox.Items = {};
            app.SuppColsListBox.Multiselect = 'on';
            app.SuppColsListBox.ValueChangedFcn = createCallbackFcn(app, @SuppColsListBoxValueChanged, true);
            app.SuppColsListBox.Tooltip = {'Select the column points which must be treated as supplementary units'};
            app.SuppColsListBox.Value = {};
            app.ResetButton = uibutton(app.FSDAdynamicinteractiveCAplotUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Clicking on Reset clears the selections in the listboxes of Supplementary Row points and Supplementary  Column points, sets the bdp to 0 (standard non robust analysis), the subset size to n and unselects the two check boxes of sign..'};
            app.ResetButton.Text = 'Reset';
            app.TabGroup = uitabgroup(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.MCD = uitab(app.TabGroup);
            app.MCD.Title = 'MCD';
            app.SliderBDPLabel = uilabel(app.MCD);
            app.SliderBDPLabel.Text = 'bdp';
            app.SliderBDP = uislider(app.MCD);
            app.SliderBDP.Limits = [0 0.5];
            app.SliderBDP.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5];
            app.SliderBDP.MajorTickLabels = {'0.5', '0.4', '0.3', '0.2', '0.1', '0'};
            app.SliderBDP.ValueChangedFcn = createCallbackFcn(app, @SliderBDPValueChanged, true);
            app.SliderBDP.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5];
            app.SliderBDP.Tooltip = {'Select the value of breakdown point you wish to use in otder to compute the MCD. The units flagged as outliers at the 1 per cent simultaneous confidence level will be shown in the plot as supplementary row points '};
            app.SliderBDP.Value = 0.5;
            app.ForwardSearch = uitab(app.TabGroup);
            app.ForwardSearch.Title = 'Forward Search';
            app.SliderFSLabel = uilabel(app.ForwardSearch);
            app.SliderFSLabel.Text = {'Subset size/n'; ''};
            app.SliderFS = uislider(app.ForwardSearch);
            app.SliderFS.Limits = [-1 10000000];
            app.SliderFS.MajorTicks = [];
            app.SliderFS.ValueChangedFcn = createCallbackFcn(app, @SliderFSValueChanged, true);
            app.SliderFS.MinorTicks = [];
            app.SliderFS.Tooltip = {'Select the value of subset size you wish to use in order to compute CA . The units outside the subset  are shown in the plot as supplementary row points. The row which is both active and supplementary in the plot, is shown both with filled and unfilled circle. The size of the filled circle is directly proportional to the number of units in that partciular row, which have been left outside the subset. Use this bar if you are interested in an exploratory analysis context; otherwise select checkbox ''Automatic'' for automatic outlier detection based on confidence bands.'};
            app.SliderFS.Value = 10000000;
            app.ChkBoxAutomaticFS = uicheckbox(app.ForwardSearch);
            app.ChkBoxAutomaticFS.ValueChangedFcn = createCallbackFcn(app, @ChkBoxAutomaticFSValueChanged, true);
            app.ChkBoxAutomaticFS.Tooltip = {'Automatic outlier detection based on the Forward Search. This is an alternative to the ''Subset Size/n'' slider. If this check box is selected the envelopes are shown in the plots of min MD (minimum Mahalanobis distance) and In  (inertia explained)'};
            app.ChkBoxAutomaticFS.Text = 'Automatic';
            app.ListBoxRowsEllLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ListBoxRowsEllLabel.Visible = 'off';
            app.ListBoxRowsEllLabel.Text = 'Rows  with ell';
            app.ChkBoxshowConfEll = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxshowConfEll.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowConfEllValueChanged, true);
            app.ChkBoxshowConfEll.Tooltip = {'If this checkbox is unchecked row points and associated labels are shown with white color'};
            app.ChkBoxshowConfEll.Text = 'Conf ellipses';
            app.ListBoxRowsEll = uilistbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ListBoxRowsEll.Items = {};
            app.ListBoxRowsEll.Multiselect = 'on';
            app.ListBoxRowsEll.ValueChangedFcn = createCallbackFcn(app, @ListBoxRowsEllValueChanged, true);
            app.ListBoxRowsEll.Visible = 'off';
            app.ListBoxRowsEll.Tooltip = {'Select the active rows which must have a confidence ellipse'};
            app.ListBoxRowsEll.Value = {};
            app.ListBoxColsEllLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ListBoxColsEllLabel.Visible = 'off';
            app.ListBoxColsEllLabel.Text = 'Cols with ell';
            app.ListBoxColsEll = uilistbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ListBoxColsEll.Items = {};
            app.ListBoxColsEll.Multiselect = 'on';
            app.ListBoxColsEll.ValueChangedFcn = createCallbackFcn(app, @ListBoxColsEllValueChanged, true);
            app.ListBoxColsEll.Visible = 'off';
            app.ListBoxColsEll.Tooltip = {'Select the active columns which must have a confidence ellipse'};
            app.ListBoxColsEll.Value = {};
            app.LabelConfEllPerc = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.LabelConfEllPerc.Visible = 'off';
            app.LabelConfEllPerc.Text = '%';
            app.EditFieldConfLevEll = uieditfield(app.FSDAdynamicinteractiveCAplotUIFigure, 'numeric');
            app.EditFieldConfLevEll.Limits = [25 99.999999];
            app.EditFieldConfLevEll.ValueDisplayFormat = '%11.8g';
            app.EditFieldConfLevEll.ValueChangedFcn = createCallbackFcn(app, @EditFieldConfLevEllValueChanged, true);
            app.EditFieldConfLevEll.Visible = 'off';
            app.EditFieldConfLevEll.Value = 90;
            app.CheckBoxSimulUnderH0 = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.CheckBoxSimulUnderH0.ValueChangedFcn = createCallbackFcn(app, @CheckBoxSimulUnderH0ValueChanged, true);
            app.CheckBoxSimulUnderH0.Tooltip = {'Contingency tables which have to simulated to provide the confidence bands must  have or  not association '};
            app.CheckBoxSimulUnderH0.Text = 'Simulate under H0';
            app.CheckBoxSimulUnderH0.Value = true;
            app.simultaneousconfidencelevelLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.simultaneousconfidencelevelLabel.Text = '% simultaneous  confidence  level';
            app.EditFieldConfLev = uieditfield(app.FSDAdynamicinteractiveCAplotUIFigure, 'numeric');
            app.EditFieldConfLev.Limits = [25 99.999999];
            app.EditFieldConfLev.ValueDisplayFormat = '%11.8g';
            app.EditFieldConfLev.ValueChangedFcn = createCallbackFcn(app, @EditFieldConfLevValueChanged, true);
            app.EditFieldConfLev.Value = 99;
            app.BusyLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.BusyLabel.Visible = 'off';
            app.BusyLabel.Text = 'Busy, please wait';
            app.LampLabel = uilamp(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.LampLabel.Tooltip = {'When the colour of the lamp gets red, please wait until it becomes green again (computations are running)'};
            app.EditFieldNsimul = uieditfield(app.FSDAdynamicinteractiveCAplotUIFigure, 'numeric');
            app.EditFieldNsimul.Limits = [2 100000];
            app.EditFieldNsimul.ValueDisplayFormat = '%11.8g';
            app.EditFieldNsimul.ValueChangedFcn = createCallbackFcn(app, @EditFieldNsimulValueChanged, true);
            app.EditFieldNsimul.Value = 1000;
            app.simulationsLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.simulationsLabel.Text = 'simulations';
            app.SliderAlphaLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SliderAlphaLabel.Text = 'α';
            app.SliderAlpha = uislider(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SliderAlpha.Limits = [0 1];
            app.SliderAlpha.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.SliderAlpha.ValueChangedFcn = createCallbackFcn(app, @SliderAlphaValueChanged, true);
            app.SliderAlpha.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SliderAlpha.Tooltip = {'α=0 corresponds to columnprincipal,'; 'α=1 corresponds to rowprincipal:'; 'α=0.5 corresponds to symbiplot'};
            app.SliderAlpha.Value = 0.3;
            app.MapTypeButtonGroup = uibuttongroup(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.MapTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MapTypeButtonGroupSelectionChanged, true);
            app.MapTypeButtonGroup.Tooltip = {'Choose on the two types of visualization:'; 'scatter: plots row points (in the same space as the column points'; 'moonplot: plot column points are equidistant from the center of the visualization'};
            app.MapTypeButtonGroup.Title = 'Map Type';
            app.ScatterButton = uiradiobutton(app.MapTypeButtonGroup);
            app.ScatterButton.Text = 'Scatter';
            app.ScatterButton.Value = true;
            app.MoonplotButton = uiradiobutton(app.MapTypeButtonGroup);
            app.MoonplotButton.Tooltip = {'In the moonplotall column points are equidistant from the center of the visualization. The font sizes, on the map below, contain the same information conveyed in the earlier brand map by the distance of the attributes to the origins.'};
            app.MoonplotButton.Text = 'Moonplot';
            app.ScatterDropDown = uidropdown(app.MapTypeButtonGroup);
            app.ScatterDropDown.Items = {'rowprincipal', 'colprincipal', 'symbiplot', 'bothprincipal', 'bothstandard', 'rowgab', 'rowgreen', 'colgreen', 'numeric value α in [0 1]'};
            app.ScatterDropDown.ValueChangedFcn = createCallbackFcn(app, @ScatterDropDownValueChanged, true);
            app.ScatterDropDown.Tooltip = {'Specify the type of coordinates to use in the scatter'; 'For additional details please see'; 'http://rosa.unipr.it/FSDA/coranaplot.html'};
            app.ScatterDropDown.Value = 'bothprincipal';
            app.ShoworHidePanel = uipanel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ShoworHidePanel.Title = 'Show or Hide';
            app.ChkBoxshowRowPoints = uicheckbox(app.ShoworHidePanel);
            app.ChkBoxshowRowPoints.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowRowPointsValueChanged, true);
            app.ChkBoxshowRowPoints.Tooltip = {'If this checkbox is unchecked row points and associated labels are shown with white color'};
            app.ChkBoxshowRowPoints.Text = 'RowPoints';
            app.ChkBoxshowRowPoints.Value = true;
            app.ChkBoxshowColPoints = uicheckbox(app.ShoworHidePanel);
            app.ChkBoxshowColPoints.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowColPointsValueChanged, true);
            app.ChkBoxshowColPoints.Tooltip = {'If this checkbox is unchecked column points and associated labels are shown with white color'};
            app.ChkBoxshowColPoints.Text = 'ColPoints';
            app.ChkBoxshowColPoints.Value = true;
            app.ChkBoxshowRowPointsSup = uicheckbox(app.ShoworHidePanel);
            app.ChkBoxshowRowPointsSup.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowRowPointsSupValueChanged, true);
            app.ChkBoxshowRowPointsSup.Tooltip = {'If this checkbox is unchecked supplementary row points and associated labels are shown with white color'};
            app.ChkBoxshowRowPointsSup.Text = 'SuppRowPoints';
            app.ChkBoxshowRowPointsSup.Value = true;
            app.ChkBoxshowColPointsSup = uicheckbox(app.ShoworHidePanel);
            app.ChkBoxshowColPointsSup.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowColPointsSupValueChanged, true);
            app.ChkBoxshowColPointsSup.Tooltip = {'If this checkbox is unchecked supplementary column points and associated labels are shown with white color'};
            app.ChkBoxshowColPointsSup.Text = 'SuppColPoints';
            app.ChkBoxshowColPointsSup.Value = true;
            app.ReversesignPanel = uipanel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ReversesignPanel.Title = 'Reverse sign';
            app.CA2SwitchLabel = uilabel(app.ReversesignPanel);
            app.CA2SwitchLabel.Text = 'CA2';
            app.CA2Switch = uiswitch(app.ReversesignPanel, 'slider');
            app.CA2Switch.ItemsData = [false true];
            app.CA2Switch.ValueChangedFcn = createCallbackFcn(app, @CA2SwitchValueChanged, true);
            app.CA2Switch.Value = false;
            app.CA1SwitchLabel = uilabel(app.ReversesignPanel);
            app.CA1SwitchLabel.Text = 'CA1';
            app.CA1Switch = uiswitch(app.ReversesignPanel, 'slider');
            app.CA1Switch.ItemsData = [false true];
            app.CA1Switch.ValueChangedFcn = createCallbackFcn(app, @CA1SwitchValueChanged, true);
            app.CA1Switch.Value = false;
            app.RowProfilesPanel = uipanel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.RowProfilesPanel.Title = 'Row Profiles';
            app.CheckBoxShowMDwei = uicheckbox(app.RowProfilesPanel);
            app.CheckBoxShowMDwei.ValueChangedFcn = createCallbackFcn(app, @CheckBoxShowMDweiValueChanged, true);
            app.CheckBoxShowMDwei.Tooltip = {'Show the index plot of squared Mahalanobis distances multiplied by the row masses'};
            app.CheckBoxShowMDwei.Text = 'MD index plot';
            app.CheckBoxShowSpmplot = uicheckbox(app.RowProfilesPanel);
            app.CheckBoxShowSpmplot.ValueChangedFcn = createCallbackFcn(app, @CheckBoxShowSpmplotValueChanged, true);
            app.CheckBoxShowSpmplot.Tooltip = {'Show scatterplot of row profile with the units declared as outliers highlighted'};
            app.CheckBoxShowSpmplot.Text = 'spmplot';
            app.FSMonitoringplotsPanel = uipanel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.FSMonitoringplotsPanel.Title = 'FS Monitoring plots';
            app.CheckBoxminMD = uicheckbox(app.FSMonitoringplotsPanel);
            app.CheckBoxminMD.ValueChangedFcn = createCallbackFcn(app, @CheckBoxminMDValueChanged, true);
            app.CheckBoxminMD.Tooltip = {'Show the plot of minimum Mahalanabis distance outside subset multipled by mass'};
            app.CheckBoxminMD.Text = 'minimum MD outside subset';
            app.CheckBoxInertia = uicheckbox(app.FSMonitoringplotsPanel);
            app.CheckBoxInertia.ValueChangedFcn = createCallbackFcn(app, @CheckBoxInertiaValueChanged, true);
            app.CheckBoxInertia.Tooltip = {'Show the monitoring plot of inertia'};
            app.CheckBoxInertia.Text = 'Inertia';
            app.CheckBoxminMDLabels = uicheckbox(app.FSMonitoringplotsPanel);
            app.CheckBoxminMDLabels.ValueChangedFcn = createCallbackFcn(app, @CheckBoxminMDLabelsValueChanged, true);
            app.CheckBoxminMDLabels.Tooltip = {'Show the entry order of the rows in the monitoring plot of min MD'};
            app.CheckBoxminMDLabels.Text = 'Show labels';
            app.CheckBoxMDforward = uicheckbox(app.FSMonitoringplotsPanel);
            app.CheckBoxMDforward.ValueChangedFcn = createCallbackFcn(app, @CheckBoxMDforwardValueChanged, true);
            app.CheckBoxMDforward.Tooltip = {'Show the monitoring plot of inertia'};
            app.CheckBoxMDforward.Text = ' MD forward';
            app.indConfLevLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.indConfLevLabel.Text = 'indConfLev';
            % Show the figure after all components are created
            app.FSDAdynamicinteractiveCAplotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CorAnaAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FSDAdynamicinteractiveCAplotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FSDAdynamicinteractiveCAplotUIFigure)
        end
    end
end