classdef LatLongAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InteractivegeomapbasedonrobustPC1orPC2Label  matlab.ui.control.Label
        geoPanel                       matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        GeographiccoordinatesCheckBox  matlab.ui.control.CheckBox
        ShowlabelsCheckBox             matlab.ui.control.CheckBox
        MapTypeDropDown                matlab.ui.control.DropDown
        MapTypeDropDownLabel           matlab.ui.control.Label
        SwitchcolortoPC2Label          matlab.ui.control.Label
        RevertcolorSwitch              matlab.ui.control.Switch
        RevertcolorSwitchLabel         matlab.ui.control.Label
        SwitchcolortoPC2               matlab.ui.control.Switch
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
    end

    properties (Access = public)
        Xtable='';
        score='';
        Xgeotable='';
        proj='';
        
    end

    methods (Access = public)

    end
    
    methods (Access = private)
        
        function  main(app)
            rownames=app.Xtable.Properties.RowNames;
            n=size(app.Xtable,1);

            % Specify the variable for which the colors have to be set
            if app.SwitchcolortoPC2.ValueIndex == 2
                colorIntensity = app.score(:,2);
                strForColorBar='Color based on second PC';

            else
                colorIntensity = app.score(:,1);
                strForColorBar='Color based on first PC';
            end

            if app.RevertcolorSwitch.ValueIndex==2
                colorIntensity=-colorIntensity;
            end

            % Define a colorbar from green to red with 50 colors
            numColors=50;
            customGradient = interp1([0;1],[0 1 0; 1 0 0],linspace(0,1,numColors));


            % Show or not in geographical coordinates
            if app.GeographiccoordinatesCheckBox.Value == true 
                geocoord = true;
            else
                geocoord = false;
            end

            if geocoord == true
                app.geoPanel.Visible = "on";
                app.MapTypeDropDown.Visible= "on";
                app.MapTypeDropDownLabel.Visible ="on";

                
                delete(app.geoPanel.Children)
                ga=geoaxes(app.geoPanel);
                % Add to the geotable the Variable which contains the colors
                app.Xgeotable.ColorVar=colorIntensity;
                geoplot(ga,app.Xgeotable,'ColorVariable','ColorVar');
                colormap(ga,customGradient);
                h=colorbar(ga);
                h.Label.String=strForColorBar;
                value = app.MapTypeDropDown.Value;
                basemaps = ["satellite" "streets" "streets-light" "streets-dark" ...
                                "landcover" "darkwater" "grayland" "bluegreen" ...
                                "grayterrain" "colorterrain"];
                geobasemap(ga,basemaps(value))

                if app.ShowlabelsCheckBox.Value == true
                    % Add labels in the centroids of the shapes
                    % estract XY pairs coords of enclosing poligons for each row of input table
                    Xtab= geotable2table(app.Xgeotable, ["X" "Y"]);
                    % Obtain the averate latitute and longitude for each row
                    warning('off','map:shapefile:unsupportedType')
                  
                    arrlonglat=zeros(n,2);
                    for i = 1:n
                        [lat, lon] = projinv(app.proj, Xtab.X{i},Xtab.Y{i});  % Convert to lat/lon
                        arrlonglat(i,1:2)=[mean(lat,'omitmissing') mean(lon,'omitmissing')];
                    end

                    % Show the labels using geographical coordinates
                    text(ga,arrlonglat(:,1), arrlonglat(:,2),rownames)
                end

            else % Plot without using geographical coordinates
                app.geoPanel.Visible = "off";
                app.MapTypeDropDown.Visible="off";
                app.MapTypeDropDownLabel.Visible ="off";

                cla(app.UIAxes, 'reset')

                fc=customGradient(round(rescale(colorIntensity,1,numColors)),:);
                polygon=mapshow(app.UIAxes,app.Xgeotable, "FaceAlpha",0.9);
                for i=1:n
                    set(polygon.Children(i), 'FaceColor', fc(i,:),"FaceAlpha",0.9);
                end
                colormap(app.UIAxes,customGradient);
                rang=[min(colorIntensity) max(colorIntensity)];
                h=colorbar(app.UIAxes);
                h.Label.String=strForColorBar;
                soglie=linspace(min(rang),max(rang),6);
                valori=(soglie-min(soglie))/(max(soglie)-min(soglie));
                h.Ticks=valori;
                h.TickLabels=string(soglie);

                if app.ShowlabelsCheckBox.Value == true

                    % Add labels in the centroids of the shapes
                    arrlonglat=zeros(n,2);
                    % estract XY pairs coords (that is latitude and
                    % longitude) of enclosing poligons for each row of
                    % input table
                    Xtab= geotable2table(app.Xgeotable, ["X" "Y"]);
                    warning('off','MATLAB:polyshape:repairedBySimplify')

                    for i=1:n
                        % prima creiamo degli oggetti poligonali
                        polObj= polyshape(Xtab.X{i,1}, ...
                            Xtab.Y{i,1});
                        [centX,centY]=centroid(polObj);
                        % popoliamo i vettori
                        arrlonglat(i,1:2)=[centX centY];
                    end
                    text(app.UIAxes,arrlonglat(:,1), arrlonglat(:,2),rownames)
                end
                hold(app.UIAxes,'off')
            end
        end
    end
%                  GeographicAxes     FaceColor
%    geoplot            yes             no (yes if mapping toolbox is
%                                           installed)
%    geoscatter         yes             no
%    wmpolygon           no             yes
%     mapshow            no             yes
%     geoshow            no             yes
% geodensityplot         yes             no 
%


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Xtable, score, ShapeFile)

            if isgeotable(ShapeFile)
                app.Xgeotable=ShapeFile;
            elseif ischar(ShapeFile) || isstring(ShapeFile)     
                app.Xgeotable = readgeotable(ShapeFile);
            else
                error('FSDA:LatLongApp:WrongInput','ShapeFile must be a char or a string or a geotable')
            end
            % Not necessary to call shapeinfo
            %{
            info = shapeinfo(ShapeFile);
            app.proj = info.CoordinateReferenceSystem;
            %}
            
            app.proj = app.Xgeotable.Shape.ProjectedCRS;


            % Store Xtable
            app.Xtable=Xtable;
            app.score=score;
            main(app)

        end

        % Value changed function: ShowlabelsCheckBox
        function ShowlabelsCheckBoxValueChanged(app, event)
            % value = app.ShowlabelsCheckBox.Value;
            main(app)
        end

        % Value changed function: SwitchcolortoPC2
        function SwitchcolortoPC2ValueChanged(app, event)
            main(app)
        end

        % Value changed function: RevertcolorSwitch
        function RevertcolorSwitchValueChanged(app, event)
            main(app)
        end

        % Value changed function: MapTypeDropDown
        function MapTypeDropDownValueChanged(app, event)
           main(app)
        end

        % Value changed function: GeographiccoordinatesCheckBox
        function GeographiccoordinatesCheckBoxValueChanged(app, event)
             main(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.RevertcolorSwitchLabel = uilabel(app.Panel);
            app.RevertcolorSwitchLabel.Text = 'Revert color';
            app.RevertcolorSwitch = uiswitch(app.Panel, 'slider');
            app.RevertcolorSwitch.ValueChangedFcn = createCallbackFcn(app, @RevertcolorSwitchValueChanged, true);
            app.SwitchcolortoPC2 = uiswitch(app.Panel, 'slider');
            app.SwitchcolortoPC2.ValueChangedFcn = createCallbackFcn(app, @SwitchcolortoPC2ValueChanged, true);
            app.SwitchcolortoPC2Label = uilabel(app.Panel);
            app.SwitchcolortoPC2Label.Text = 'Switch color to PC2';
            app.MapTypeDropDownLabel = uilabel(app.Panel);
            app.MapTypeDropDownLabel.Text = 'Map Type';
            app.MapTypeDropDown = uidropdown(app.Panel);
            app.MapTypeDropDown.Items = {'"satellite"', '"streets"', '"streets-light"', '"streets-dark"', '"landcover"', '"darkwater"', '"grayland"', '"bluegreen" ', '"grayterrain"', '"colorterrain'};
            app.MapTypeDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.MapTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MapTypeDropDownValueChanged, true);
            app.MapTypeDropDown.Tooltip = {'Type of map to use. Note that this drop down is visbile just if Geografic coordinates is selected.'};
            app.MapTypeDropDown.Value = 3;
            app.ShowlabelsCheckBox = uicheckbox(app.Panel);
            app.ShowlabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowlabelsCheckBoxValueChanged, true);
            app.ShowlabelsCheckBox.Text = 'Show labels';
            app.GeographiccoordinatesCheckBox = uicheckbox(app.Panel);
            app.GeographiccoordinatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @GeographiccoordinatesCheckBoxValueChanged, true);
            app.GeographiccoordinatesCheckBox.Tooltip = {'Use geogrphic axes based on latitute and longitude or simpl use the coordinates in the original ShapeFile'};
            app.GeographiccoordinatesCheckBox.Text = 'Geographic coordinates';
            app.GeographiccoordinatesCheckBox.Value = true;
            app.geoPanel = uipanel(app.UIFigure);
            app.InteractivegeomapbasedonrobustPC1orPC2Label = uilabel(app.UIFigure);
            app.InteractivegeomapbasedonrobustPC1orPC2Label.Text = 'Interactive geomap based on (robust) PC1 or PC2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LatLongAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end