classdef biplotAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FSDAdynamicinteractivebiplotUIFigure  matlab.ui.Figure
        ConflevEditField                matlab.ui.control.NumericEditField
        ConflevEditFieldLabel           matlab.ui.control.Label
        SliderAlpha                     matlab.ui.control.Slider
        SliderLabelAlpha                matlab.ui.control.Label
        SliderOmega                     matlab.ui.control.Slider
        SliderLabelOmega                matlab.ui.control.Label
        ChangesignPanel                 matlab.ui.container.Panel
        CA1Switch                       matlab.ui.control.Switch
        CA1SwitchLabel                  matlab.ui.control.Label
        CA2Switch                       matlab.ui.control.Switch
        CA2SwitchLabel                  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        TextdensitypercentageKnob       matlab.ui.control.Knob
        TextdensitypercentageKnobLabel  matlab.ui.control.Label
        ConfEllLabel                    matlab.ui.control.Label
        ConfEllEditField                matlab.ui.control.NumericEditField
        ChkBoxConfEll                   matlab.ui.control.CheckBox
        ChkBoxBrush                     matlab.ui.control.CheckBox
        HideSVDslidersCheckBox          matlab.ui.control.CheckBox
        ChkBoxRobAnalysis               matlab.ui.control.CheckBox
        ChkBoxChangeSign                matlab.ui.control.CheckBox
        ChkBoxRowPointsProp2Dist        matlab.ui.control.CheckBox
        ChkBoxshowArrows                matlab.ui.control.CheckBox
        ChkBoxshowRowNames              matlab.ui.control.CheckBox
        ChkBoxshowRowPoints             matlab.ui.control.CheckBox
        ArrowsCoordinatesLabel          matlab.ui.control.Label
        RowPointsLabel                  matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        TabRobust                       matlab.ui.container.TabGroup
        ForwardSearch                   matlab.ui.container.Tab
        ChkBoxAutomaticFS               matlab.ui.control.CheckBox
        FSMonitoringplotsPanel          matlab.ui.container.Panel
        CheckBoxMDforward               matlab.ui.control.CheckBox
        CheckBoxminMD                   matlab.ui.control.CheckBox
        SliderFS                        matlab.ui.control.Slider
        SliderFSLabel                   matlab.ui.control.Label
        MCD                             matlab.ui.container.Tab
        MCDplotsPanel                   matlab.ui.container.Panel
        CheckBoxShowMDandrobMD          matlab.ui.control.CheckBox
        CheckBoxShowMDind               matlab.ui.control.CheckBox
        SliderBDP                       matlab.ui.control.Slider
        SliderBDPLabel                  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % Property % Description
        Xtable='';
        % omega parameter in the slider
        omega='';     % alpha parameter in the slider
        alpha='';
        % boolean which specifies to standardize data in Xtable
        standardize;
        % bsb subset on which to compute the statistics
        bsb=[];
        % slider bdp
        BDPslider=false;
        % slider FS
        FSslider=false;
        ShowRobustGroup=false;
        PCA1or2switch=false;
        automaticFS=false;

        % Latitude and Longitude of Row Poins
        Latitude=[];
        Longitude=[];
        ShapeFile=[];
        densKnob=100;
        
        colorBlindSafe= false; 
    end
    
   properties (Access = private)
        % Invisible/background axes used only to host the colorbar.
        ColorbarAxes = []

        % Handle of the orthogonal-distance colorbar.
        OrthDistColorbar = []
    end


    methods (Access = private)

        function main(app, Xtable, varargin)

            app.omega = 0.7;
            app.alpha = 0.3;
            app.standardize=true;

            showRowPoints=true;
            RowPointsProp2OrthDist=false;
            showRowNames=false;
            showArrows=true;

            bdp=[];


            [n,p]=size(Xtable);
            if istable(Xtable)
                X=table2array(Xtable);
                varlabs=Xtable.Properties.VariableNames;
                rownames=Xtable.Properties.RowNames;
            else
                varlabs=cellstr(num2str((1:p)','Y%d'));
                rownames=cellstr(num2str((1:n)','%d'));

                X=Xtable;
                Xtable=array2table(X,"RowNames",rownames,"VariableNames",varlabs);
            end

            if nargin>2

                options=struct('omega',app.omega,...
                    'alpha',app.alpha,'showRowPoints',showRowPoints,...
                    'RowPointsProp2OrthDist', RowPointsProp2OrthDist, ...
                    'showRowNames',showRowNames,...
                    'showArrows',showArrows,'standardize',app.standardize, ...
                    'bsb',[], 'bdp','',...
                    'BDPslider',[],'FSslider',[],'colorBlindSafe',app.colorBlindSafe);

                UserOptions=varargin(1:2:length(varargin));
                if ~isempty(UserOptions)
                    % Check if number of supplied options is valid
                    if length(varargin) ~= 2*length(UserOptions)
                        error('FSDA:biplotFS:WrongInputOpt','Number of supplied options is invalid. Probably values for some parameters are missing.');
                    end
                    % Check if user options are valid options
                    aux.chkoptions(options,UserOptions)
                end

                % Write in structure 'options' the options chosen by the user
                for i=1:2:length(varargin)
                    options.(varargin{i})=varargin{i+1};
                end
                app.alpha=options.alpha;
                % Set the supplied value of alpha in the corresponding
                % slider bar
                app.SliderAlpha.Value=app.alpha;
                app.omega=options.omega;
                % Set the supplied value of omega in the corresponding
                % slider bar
                app.SliderOmega.Value=app.omega;

                showRowPoints= options.showRowPoints;
                app.ChkBoxshowRowPoints.Value=showRowPoints;


                RowPointsProp2OrthDist=options.RowPointsProp2OrthDist;
                app.ChkBoxRowPointsProp2Dist.Value=RowPointsProp2OrthDist;

                showRowNames = options.showRowNames;
                app.ChkBoxshowRowNames.Value=showRowNames;

                showArrows = options.showArrows;
                app.ChkBoxshowArrows.Value=showArrows;
                app.standardize=options.standardize;

                app.bsb=options.bsb;
                bdp=options.bdp;
                app.colorBlindSafe=options.colorBlindSafe;
                
            end

            if ~isempty(bdp) && ~isempty(app.bsb)
                error('FSDA:biplotAPP:WrongInputOpt','just one between bsb and bdp has to be supplied');
            end

            texttag='texttag';

            if isempty(app.bsb) && isempty(bdp) &&  app.ShowRobustGroup==true
                app.Button.Visible="off";
                app.ShowRobustGroup=false;
                app.TabRobust.Visible="off";
                app.ConflevEditField.Visible="off";
                app.ConflevEditFieldLabel.Visible="off";
                showTABS=false;
            elseif app.ShowRobustGroup==false || isempty(app.bsb)
                app.Button.Visible="off";
                app.TabRobust.Visible="off";
                app.ConflevEditField.Visible="off";
                app.ConflevEditFieldLabel.Visible="off";
                showTABS=false;
            else
                showTABS=true;
                app.Button.Visible="on";
                app.TabRobust.Visible="on";
                app.ConflevEditField.Visible="on";
                app.ConflevEditFieldLabel.Visible="on";
                app.ChkBoxRobAnalysis.Value=true;
            end

            if app.BDPslider==true
                % find bsb using MCD
                app.bsb=true(n,1);
                bdp=0.5-options.BDPslider;
                outMCD=mcd(X,'bdp',bdp,'conflev',1-(1-app.ConflevEditField.Value)/n,'msg',0);
                if bdp>0
                    app.bsb(outMCD.outliers)=false;
                else

                end


                if  app.CheckBoxShowMDind.Value == true
                    if isempty(outMCD.outliers)
                        showout={5};
                    else
                        showout=outMCD.outliers;
                    end
                    % Show the index plot of robust MD distances (in
                    % squared units)
                    malindexplot(outMCD.md,p,'conflev',1-(1-app.ConflevEditField.Value)/n,'laby','robust distances','numlab',showout);
                    title('Index plot of robust MD (squared units)')
                else
                    h_malindex = findobj(0, 'type', 'figure','tag','pl_malindex');
                    delete(h_malindex); % pl_malindex is deleted
                end

                if  app.CheckBoxShowMDandrobMD.Value == true

                    % Show the scatter plot of robust vs non robust MD distances (in
                    % squared units)
                    mu=mean(X);
                    covar=cov(X);
                    MDtrad=mahalFS(X,mu,covar);

                    xc=sqrt(MDtrad);
                    yc=sqrt(outMCD.md);
                    h_robnonrob = findobj(0, 'type', 'figure','tag','pl_robnonrob');
                    delete(h_robnonrob); % pl_robnonrob is deleted
                    figure('Tag','pl_robnonrob');
                    scatter(xc,yc);
                    lin=sqrt(chi2inv(0.975,p));
                    xline(lin,'Color','r')
                    yline(lin,'Color','r')
                    xlabel('Mahalanobis distances')
                    ylabel('Robust MCD Mahalanobis distances')
                    sel=yc>sqrt(chi2inv(1-(1-app.ConflevEditField.Value)/n,p));
                    lab=string(1:n);
                    text(xc(sel),yc(sel),lab(sel))
                    set(gcf,'Tag','pl_robnonrob')
                else
                    h_robnonrob = findobj(0, 'type', 'figure','tag','pl_robnonrob');
                    delete(h_robnonrob); % pl_robnonrob is deleted
                end


            elseif app.FSslider == true % FS with eda purposes
                outMCD=mcd(X,'bdp',0.5,'msg',0);
                [~,unitsbsb]=FSMbsb(X,outMCD.bs,'bsbsteps',round(options.FSslider),'init',p+1);
                % find units inside subset at step m of the FS
                app.bsb=true(n,1);
                app.bsb(isnan(unitsbsb))=false;

                if  app.CheckBoxminMD.Value == true || app.CheckBoxMDforward.Value == true
                    outEDA=FSMeda(X,outMCD.bs,'scaled',2);
                    if  app.CheckBoxminMD.Value == true
                        % Create Minimum MD plot
                        mmdplot(outEDA);
                    else
                        h_mmd= findobj(0, 'type', 'figure','tag','pl_mmd');
                        delete(h_mmd); % pl_mmd is deleted
                    end

                    if  app.CheckBoxMDforward.Value == true
                        % Create monitoring MD plot
                        malfwdplot(outEDA,'label',rownames);
                    else
                        h_mal = findobj(0, 'type', 'figure','tag','pl_malfwd');
                        delete(h_mal); % pl_malfwd is deleted
                    end
                else
                    % Close both plots
                    h_mal = findobj(0, 'type', 'figure','tag','pl_malfwd');
                    h_mmd= findobj(0, 'type', 'figure','tag','pl_mmd');
                    delete(h_mal); % pl_malfwd is deleted
                    delete(h_mmd); % pl_mmd is deleted
                end


            elseif app.automaticFS == true
                outFS=FSM(X,'msg',0,'plots',0,'init',round(n*0.7),'bonflev',app.ConflevEditField.Value);

                if  app.CheckBoxminMD.Value == true || app.CheckBoxMDforward.Value == true
                    outMCD=mcd(X,'bdp',0.5,'msg',0);
                    outEDA=FSMeda(X,outMCD.bs,'scaled',2);

                    if  app.CheckBoxMDforward.Value == true
                        % Create monitoring MD plot
                        malfwdplot(outEDA,'label',rownames);
                    else
                        h_mal = findobj(0, 'type', 'figure','tag','pl_malfwd');
                        delete(h_mal); % pl_malfwd is deleted
                    end

                    if  app.CheckBoxminMD.Value == true
                        % Create Minimum MD plot
                        mmdplot(outEDA);
                    else
                        h_mmd= findobj(0, 'type', 'figure','tag','pl_mmd');
                        delete(h_mmd); % pl_mmd is deleted
                    end

                end

                app.bsb=true(n,1);
                app.bsb(outFS.outliers)=false;
            else
                if isempty(app.bsb)
                    app.bsb=true(n,1);
                else
                    if ~islogical(app.bsb)
                        bsbini=false(n,1);
                        bsbini(app.bsb)=true;
                        app.bsb=bsbini;
                    end
                end
            end


            Xbsb=X(app.bsb,:);
            center=mean(Xbsb);
            nbsb=size(Xbsb,1);

            dispersion=std(Xbsb);
            if app.standardize==true
                % Create matrix of standardized data
                Z=(X-center)./dispersion;
                dispersion=1;
            else
                % Create matrix of deviations from the means
                Z=X-center;
            end

            Zbsb=Z(app.bsb,:);

            [~,Gammastar,V]=svd(Zbsb,'econ');

            if all(V(:,1)<0)
                V(:,1) = -V(:,1); % Flip the sign of the first principal component if necessary
            end

            % tot var =
            totvar=sum(Gammastar.^2,'all');
            percexpl1=num2str(100*Gammastar(1,1)^2/totvar,4);
            percexpl2=num2str(100*Gammastar(2,2)^2/totvar,4);

            sqn1=sqrt(nbsb-1);
            Gamma=Gammastar/sqn1;

            changedimsign=[false false];
            if app.CA1Switch.Value == true
                changedimsign(1)=true;
            end
            if app.CA2Switch.Value == true
                changedimsign(2)=true;
            end
            if changedimsign(1) ==true
                V(:,1)=-V(:,1);
            end
            if changedimsign(2) ==true
                V(:,2)=-V(:,2);
            end


            % Find orthogonal distances
            score=Z*V(:,1:2);
            Res=Z-score*V(:,1:2)';
            orthDist=sqrt(sum(Res.^2,2));

            % Just enter the following if, in the case in which the user
            % modified changed the sign of the first or second eigenvector
            % that is if the current routine is invoked by CA1SwitchValueChanged
            % or by CA1SwitchValueChanged

            % if  app.PCA1or2switch == true
            %     % Check whether the figure contains the loadings is open
            %     hFig = findobj('Type', 'figure', 'Name', 'Loadings');
            %     delete(hFig)
            %
            %     loadings=V*Gamma./dispersion;
            %
            %     xlabels=categorical(varlabs,varlabs);
            %     figure('Name','Loadings')
            %     NumComponents=2;
            %     for i=1:NumComponents
            %         subplot(NumComponents,1,i)
            %
            %         %DDD The next line gives a flat green. the color should
            %         %carry the sign of the loading, and flat green
            %         %currently throws that information away.
            %         %b=bar(xlabels, loadings(:,i),'g');
            %
            %         % This color the bars individually using the extreme stops of diverging_hcl_matlab
            %         cmapDiv = diverging_hcl_matlab(256);
            %         negColor = cmapDiv(1,:);
            %         posColor = cmapDiv(end,:);
            %
            %         vals = loadings(:,i);
            %         cdata = repmat(posColor, numel(vals), 1);
            %         cdata(vals < 0, :) = repmat(negColor, sum(vals<0), 1);
            %
            %         b = bar(xlabels, vals);
            %         b.FaceColor = 'flat';
            %         b.CData = cdata;
            %
            %         xtips=b(1).XData;
            %         ytips=b(1).YData;
            %         barlabels = string(round(vals,2));
            %         text(xtips,ytips,barlabels,'HorizontalAlignment','center','VerticalAlignment','bottom')
            %         title(['Correlations with PC' num2str(i)])
            %
            %
            %         % title(['Correlations with PC' num2str(i)])
            %         % xtips=b(1).XData;
            %         % ytips=b(1).YData;
            %         % % The alternative instructions below only work from MATLAB
            %         % % 2019b
            %         % %   xtips = b.XEndPoints;
            %         % %   ytips = b.YEndPoints;
            %         % barlabels = string(round(loadings(:,i),2));
            %         % text(xtips,ytips,barlabels,'HorizontalAlignment','center',...
            %         %     'VerticalAlignment','bottom')
            %         % title(['Correlations  with PC' num2str(i)])
            %     end
            %
            %     % Reset to false app.CA1or2switch.Value
            %     app.PCA1or2switch =false;
            % end
            if  app.PCA1or2switch == true
                hFig = findobj('Type', 'figure', 'Name', 'Loadings');
                delete(hFig)

                loadings=V*Gamma./dispersion;
                if app.colorBlindSafe == true
                    aux.plotLoadingsDiverging(loadings, varlabs, 2, 'Loadings', 'pl_loadings_biplotAPP');
                else
                    xlabels=categorical(varlabs,varlabs);
                    figure('Name','Loadings')
                    NumComponents=2;
                    for i=1:NumComponents
                        subplot(NumComponents,1,i)
                        b=bar(xlabels, loadings(:,i),'g');
                        title(['Correlations with PC' num2str(i)])
                        xtips=b(1).XData;
                        ytips=b(1).YData;
                        barlabels = string(round(loadings(:,i),2));
                        text(xtips,ytips,barlabels,'HorizontalAlignment','center',...
                            'VerticalAlignment','bottom')
                        title(['Correlations  with PC' num2str(i)])
                    end

                end

                app.PCA1or2switch =false;
            end

            % Score distance in PCA subspace of dimension k
            % larow=diag(Gamma(1:2,1:2)).^2';
            % scoreDist=sqrt(sum(score.^2./larow,2));

            % RowPoints=sqn1^app.omega*U(:,1:2)*Gamma(1:2,1:2)^app.alpha;
            RowPointsZV=Z*V(:,1:2);
            RowPoints=RowPointsZV*(sqn1^(app.omega-1))*Gamma(1:2,1:2)^(app.alpha-1);


            % resetplotview(app.UIAxes,'InitializeCurrentView');
            if showRowPoints == true
                lwd=1;
                app.ChkBoxRowPointsProp2Dist.Visible="on";
                if RowPointsProp2OrthDist ==true
                    plot(app.UIAxes,RowPoints(:,1),RowPoints(:,2),'o')
                    % If all units are considered then all are shown in
                    % filled style
                    if sum(app.bsb)==n
                        scatter(app.UIAxes,RowPoints(app.bsb,1),RowPoints(app.bsb,2),50,orthDist(app.bsb),'filled','LineWidth',lwd)
                    else
                        scatter(app.UIAxes,RowPoints(app.bsb,1),RowPoints(app.bsb,2),50,orthDist(app.bsb),'LineWidth',lwd)
                    end
                    hold(app.UIAxes,'on')
                    scatter(app.UIAxes,RowPoints(~app.bsb,1),RowPoints(~app.bsb,2),50,orthDist(~app.bsb),'filled','LineWidth',lwd)
                    
                    if app.colorBlindSafe == true
                        map = aux.sequential_hcl_matlab(256);
                    else
                        map='jet';
                    end

                    showOrthDistColorbarBehindLabels(app,map,orthDist)
                else
                    scatter(app.UIAxes,RowPoints(app.bsb,1),RowPoints(app.bsb,2),50,"blue",'LineWidth',lwd);
                    hold(app.UIAxes,'on')
                    scatter(app.UIAxes,RowPoints(~app.bsb,1),RowPoints(~app.bsb,2),50,"blue",'filled','LineWidth',lwd);
                    scatter(app.UIAxes,RowPoints(:,1),RowPoints(:,2),'LineWidth',0.00001,'ButtonDownFcn', @(src, event)personalizedDataTip(app, src, event));

                    hideOrthDistColorbar(app)
                end
            else
                % Remove the points
                aaMarker=findobj(app.UIAxes,'Marker','o');
                % set(findobj(app.UIAxes,'Marker','o'),'XData',[])
                % set(findobj(app.UIAxes,'Marker','o'),'YData',[])
                for jj=1:length(aaMarker)
                    set(aaMarker(jj),'XData',[]);
                    set(aaMarker(jj),'YData',[]);
                end

                app.ChkBoxRowPointsProp2Dist.Visible="off";
                hideOrthDistColorbar(app)

            end
            xlabel(app.UIAxes, ['PC1 % var explained='  percexpl1])
            ylabel(app.UIAxes, ['PC2 % var explained='  percexpl2])

            ColPoints=V(:,1:2)*(Gamma(1:2,1:2)^(1-app.alpha))*sqn1^(1-app.omega);
            zeroes = zeros(p,1);
            hold(app.UIAxes,'on')
            dx=0.001;
            dy=0.001;

            % First delete arrows before redrawing them
            delete(findobj(app.UIAxes,'Type','quiver'))
            delete(findobj(app.UIAxes,'Tag',texttag))

            if showArrows==true
                %ddperc=0.95;
                ddperc=1.05; %DDD
                quiver(app.UIAxes,zeroes,zeroes,ColPoints(:,1),ColPoints(:,2));
                text(app.UIAxes,ColPoints(:,1)*ddperc,ColPoints(:,2)*ddperc,varlabs,...
                    'Color','b','HorizontalAlignment',"center",...
                    "VerticalAlignment","baseline",'Tag',texttag);
            end

            if app.ChkBoxConfEll.Value == true
                % hold(app.UIAxes,'on')
                % Create ellipse
                c = sqrt(chi2inv(app.ConfEllEditField.Value/100,2));
                semiAxes=c*std(RowPoints);
                % images.roi.Ellipse(app.UIAxes,'Center',[0 0],'SemiAxes',semiAxes),

                t = linspace(0,2*pi,200);
                xell = semiAxes(1)*cos(t);
                yell = semiAxes(2)*sin(t);
                plot(app.UIAxes,xell,yell,'r-','LineWidth',1.5);
                in=inpolygon(RowPoints(:,1),RowPoints(:,2),xell,yell);
                outOfEllipse=~in;
            else
                outOfEllipse=true(n,1);
            end

            if showRowNames ==true
                if app.densKnob==100
                    text(app.UIAxes,RowPoints(outOfEllipse,1)+dx,RowPoints(outOfEllipse,2)+dy,rownames(outOfEllipse),...
                        'Color','k','Tag',texttag);
                else
                    v = ver;                                  % struct array of installed products
                    names = {v.Name};                         % cell array of names
                    tf = any(contains(names, 'Text Analytics Toolbox', 'IgnoreCase', true));
                    if tf == false
                        disp('########################################################################################')
                        disp('# You need to install Text Analytics Toolbox to view the text density label percentage #')
                        disp('# Click on the link in the error message below to install it                           #')
                        disp('########################################################################################')

                    end
                    textscatter(app.UIAxes,RowPoints(outOfEllipse,1)+dx,RowPoints(outOfEllipse,2)+dy,rownames(outOfEllipse),...
                        'ColorData',[0 0 0],'Tag',texttag,'TextDensityPercentage',app.densKnob,'MarkerColor','none');
                end
            else
                app.TextdensitypercentageKnob.Visible="off";
                app.TextdensitypercentageKnobLabel.Visible="off";
            end

            % Store Xtable
            app.Xtable=Xtable;

            % set x and y limits
            kk=1;
            PuntiX=[RowPoints(:,1);ColPoints(:,1)];
            xlim(app.UIAxes,kk*[min(PuntiX),max(PuntiX)])
            PuntiY=[RowPoints(:,2);ColPoints(:,2)];
            ylim(app.UIAxes,kk*[min(PuntiY),max(PuntiY)])
            Xaxislim=app.UIAxes.XLim;
            % Add x axis
            line(app.UIAxes,kk*[Xaxislim(1);Xaxislim(2)], [0;0], 'Color','black');
            Yaxislim=app.UIAxes.YLim;
            % Add y axis
            line(app.UIAxes,[0;0],kk*[Yaxislim(1);Yaxislim(2)], 'Color','black');


            if showTABS==false
                % FontSize=16;
                % height=1.25;
                % d1=text(app.UIAxes, .01, height, '$Row \; points: (\sqrt{n-1})^{\omega}U \Gamma^{  \alpha  }$',...
                %     'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);
                % d2=text(app.UIAxes, .550, height, '$Arrow \; coordinates: (\sqrt{n-1})^{1- \omega}V \Gamma^{1-\alpha}$',...
                %     'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);
                app.RowPointsLabel.Interpreter='latex';
                app.RowPointsLabel.Text='$Row \; points: (\sqrt{n-1})^{\omega}U \Gamma^{  \alpha  }$';
                app.ArrowsCoordinatesLabel.Interpreter='latex';
                app.ArrowsCoordinatesLabel.Text='$Arrow \; coordinates: (\sqrt{n-1})^{1- \omega}V \Gamma^{1-\alpha}$';
            else
                app.RowPointsLabel.Visible="off";
                app.ArrowsCoordinatesLabel.Visible="off";
            end

            if app.omega==1 % && app.standardize==true
                if app.alpha==0
                    disp('When \omega=1 and \alpha=0')
                    disp('Row points represent standardized principal components sqrt(n-1)*U=ZV*\Gamma^-1')
                    disp('Arrows represent correlations between variables and principal components \Gamma*V')
                    disp('The length of the arrows is the communality of the variable')
                    disp('In this case the unit circle is also shown')
                    th = 0:pi/50:2*pi;
                    xunit = cos(th);
                    yunit = sin(th) ;
                    plot(app.UIAxes, xunit, yunit);
                    daspect(app.UIAxes,[1,1,1])
                    % set(d1,"Visible","off")
                    % set(d2,"Visible","off")
                    % text(app.UIAxes, 0.95, height, '$Row \; points: (\sqrt{n-1})U$',...
                    %     'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);
                    % text(app.UIAxes, 0.95, height-0.3, '$Arrow \; coordinates: V \Gamma$',...
                    %     'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);

                elseif  app.alpha==1
                    disp('When \omega=1 and \alpha=1')
                    disp('Row points represent principal components sqrt(n-1)*U*\Gamma=ZV')
                    disp('Arrows represent the eigenvectors V')
                    disp('In this case the unit circle is also shown')
                    th = 0:pi/50:2*pi;
                    xunit = cos(th);
                    yunit = sin(th) ;
                    plot(app.UIAxes, xunit, yunit);
                    daspect(app.UIAxes,[1,1,1])
                    % set(d1,"Visible","off")
                    % set(d2,"Visible","off")
                    % text(app.UIAxes, 0.95, height, '$Row \; points: (\sqrt{n-1})U\Gamma$',...
                    %     'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);
                    % text(app.UIAxes, 0.95, height-0.3, '$Arrow \; coordinates: V$',...
                    %     'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);

                else
                    % Keep original aspect ratio
                    app.UIAxes.DataAspectRatio = [0.6595092024539878,1,1];
                end
            else
                app.UIAxes.DataAspectRatio = [0.6595092024539878,1,1];
            end


            if app.ChkBoxBrush.Value == true
                disp("Brushing is active: drag with the mouse to select a region")

                v = ver;                                  % struct array of installed products
                names = {v.Name};                         % cell array of names
                tf = any(contains(names, 'Image Processing Toolbox', 'IgnoreCase', true));
                if tf == false
                    disp('##############################################################')
                    disp('# You need to install Image Processing Toolbox to brush      #')
                    disp('# Click on the link in the error message below to install it #')
                    disp('##############################################################')
                    app.ChkBoxBrush.Value = false;
                end

                r = drawrectangle(app.UIAxes);
                xcoo=RowPoints(:,1)>=r.Position(1) & RowPoints(:,1) <=r.Position(1)+r.Position(3);
                ycoo=RowPoints(:,2)>=r.Position(2) & RowPoints(:,2) <=r.Position(2)+r.Position(4);
                boo=xcoo & ycoo;
                plot(app.UIAxes, RowPoints(boo,1),  RowPoints(boo,2),'o',"MarkerFaceColor","r");

                group=repelem("Unbrushed units",n,1);
                group(boo)="Brushed units";
                plo=struct;
                if group(1)=="Brushed units"
                    plo.clr='rb';
                    plo.sym={'o';'+'};
                    spmplot(Xtable,'group',group,'plo',plo);
                else
                    spmplot(Xtable,'group',group);
                end
                % Set markers of units shown with symbol 'o' (brushed units) to filled red
                set(findobj(gcf,'marker','o'),'MarkerFaceColor','r');
                app.ChkBoxBrush.Value = false;
                disp("Brushing terminated (check box reset to false)")

                % if ~isempty(app.Latitude)
                %     figure
                %     % Divide the second PC into 4 classes
                %     cate=discretize(RowPointsZV(:,2),linspace(min(RowPointsZV(:,2)),max(RowPointsZV(:,2)),4),'categorical');
                %
                %     % Add title and legend titles
                %     % gb=geobubble(app.Latitude(boo),app.Longitude(boo),RowPoints(boo,1),cate(boo),'Basemap','topographic','MapLayout','maximized');
                %     lat1=app.Latitude;
                %     long1=app.Longitude;
                %     lat1(~boo)=NaN;
                %     long1(~boo)=NaN;
                %     gb=geobubble(lat1,long1,RowPointsZV(:,1),cate,'Basemap','topographic'); % ,'MapLayout','maximized');
                %
                %     % DDD: PC2 has a sign and a meaningful zero, so the 3 bins
                %     % should map to a 3-stop diverging palette, not
                %     % geobubble's default categorical blue/orange/yellow.
                %     % The next line shoudld fix this.
                %     gb.ColorOrder = diverging_hcl_matlab(3);   % 3 bins -> 3 discrete diverging colors
                %
                %     gb.Title = "Geobubble of brushed units";
                %     gb.SizeLegendTitle = "First PC (size)";
                %     gb.ColorLegendTitle = "Second PC (color)";
                %     % textm(0.9,0.9,'Geobubble of selected units')
                % end
                if ~isempty(app.Latitude)
                    figure
                    lat1=app.Latitude;
                    long1=app.Longitude;
                    lat1(~boo)=NaN;
                    long1(~boo)=NaN;
                    aux.plotGeobubbleDiverging(lat1, long1, RowPointsZV(:,1), RowPointsZV(:,2), ...
                        "Geobubble of brushed units", "First PC (size)", "Second PC (color)");
                end

                if ~isempty(app.ShapeFile)
                    RowPointsZVt=array2table(RowPointsZV,"RowNames",rownames,"VariableNames","PC"+(1:2));
                    geoplotAPP(RowPointsZVt,1,app.ShapeFile,boo)
                end

            else % invoke datatooltip
                % app.UIAxes.UserData=Xtable;

            end
            hold(app.UIAxes,'off')
        end


        function  personalizedDataTip(app,src,event)

            % disp("coordinate")
            % disp(event.IntersectionPoint(1:2))


            if length(src.DataTipTemplate.DataTipRows)>2
                src.DataTipTemplate.DataTipRows(end).Label='';
            end

            [~,rowmin] =min(sum((event.IntersectionPoint(1:2)-[src.XData' src.YData']).^2,2));

            src.DataTipTemplate.DataTipRows(end+1).Label=string(app.Xtable.Properties.RowNames(rowmin));
            src.XData(1)=event.IntersectionPoint(1);
            src.YData(1)=event.IntersectionPoint(2);
            datatip(src);
        end

        function showOrthDistColorbarBehindLabels(app, map, orthDist)
            % Put the orthogonal-distance colorbar behind all objects drawn in
            % app.UIAxes. This reproduces the layering used in CorAnaplot:
            % a background axes hosts the colorbar, while the foreground axes
            % containing points, arrows and labels is transparent.

            % Remove any colorbar attached to the foreground axes.
            colorbar(app.UIAxes,'off')

            % Create, or reuse, the background axes.
            if isempty(app.ColorbarAxes) || ~isvalid(app.ColorbarAxes)
                app.ColorbarAxes = uiaxes(app.FSDAdynamicinteractivebiplotUIFigure);
                app.ColorbarAxes.Toolbar.Visible = 'off';
                app.ColorbarAxes.Visible = 'off';
                app.ColorbarAxes.HitTest = 'off';
                app.ColorbarAxes.PickableParts = 'none';
            else
                colorbar(app.ColorbarAxes,'off')
            end

            % Keep the background axes exactly aligned with the main axes.
            app.ColorbarAxes.Position = app.UIAxes.Position;
            app.ColorbarAxes.Color = 'none';
            app.ColorbarAxes.XTick = [];
            app.ColorbarAxes.YTick = [];

            % Use the same colormap and color limits on the two axes.
            colormap(app.UIAxes,map)
            colormap(app.ColorbarAxes,map)

            climv = [min(orthDist(:)) max(orthDist(:))];
            if climv(1) == climv(2)
                delta = max(eps,abs(climv(1))*sqrt(eps));
                climv = climv + [-delta delta];
            end
            caxis(app.UIAxes,climv)
            caxis(app.ColorbarAxes,climv)

            % Create the colorbar on the background axes, not on app.UIAxes.
            app.OrthDistColorbar = colorbar(app.ColorbarAxes,'Location','eastoutside');
            app.OrthDistColorbar.Label.String = 'Orthogonal distance to subspace';

            getPos = app.OrthDistColorbar.Position;
            getPos(1) = 1.03*getPos(1);
            app.OrthDistColorbar.Location = 'manual';
            app.OrthDistColorbar.Position = getPos;
            app.OrthDistColorbar.Visible = 'on';

            % Make the foreground axes transparent and stack it above the
            % background colorbar layer. Therefore labels stay above the
            % colorbar when the colorbar overlaps the plot region.
            app.UIAxes.Color = 'none';

            drawnow limitrate
            try
                uistack(app.ColorbarAxes,'bottom')
                uistack(app.OrthDistColorbar,'bottom')
                uistack(app.UIAxes,'top')
            catch
                % Older releases can be conservative about uistack on UIAxes.
                % The colorbar is still attached to a background axes.
            end
        end

        function hideOrthDistColorbar(app)
            % Hide/delete all colorbars used by the biplot.

            colorbar(app.UIAxes,'off')

            if ~isempty(app.OrthDistColorbar)
                try
                    if isvalid(app.OrthDistColorbar)
                        delete(app.OrthDistColorbar)
                    end
                catch
                end
                app.OrthDistColorbar = [];
            end

            if ~isempty(app.ColorbarAxes) && isvalid(app.ColorbarAxes)
                colorbar(app.ColorbarAxes,'off')
                app.ColorbarAxes.Visible = 'off';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Xtable, varargin)

            if ~isMATLABReleaseOlderThan("R2025a")
                theme(app.FSDAdynamicinteractivebiplotUIFigure, "light")
            end


            [n]=size(Xtable,1);

            % Set slider for FS (subset size)
            selsteps=round(n*(0.5:0.1:1));
            app.SliderFS.Limits=[selsteps(1) n];
            app.SliderFS.MajorTicks=selsteps;
            app.SliderFS.MinorTicks=round(n*(0.55:0.10:0.95));
            app.SliderFS.MajorTickLabels={'1/2' '0.6' '0.7' '0.8' '0.9' '1'};
            % app.SliderFSLabel.Text={'Subset size/n' ['n=' num2str(n) '     ']};

            [varargin{:}] = convertStringsToChars(varargin{:});
            UserOptions=varargin(1:2:length(varargin));
            if ~isempty(UserOptions)
                posLat=strcmp('Latitude',UserOptions);
                posLong=strcmp('Longitude',UserOptions);
                posLatLong=posLat | posLong;
                fposLat=2*find(posLat);
                fposLong=2*find(posLong);
                if sum(posLatLong)==2
                    app.Latitude=varargin{fposLat};
                    app.Longitude=varargin{fposLong};
                    varargin([ fposLat-1  fposLat fposLong-1 fposLong])=[];
                    UserOptions=varargin(1:2:length(varargin));
                elseif sum(posLatLong)==1
                    error('FSDA:biplotAPP:missingInputs','Just Latitude or Longitude is given')
                end

                posShape=strcmp('ShapeFile',UserOptions);
                fposShape=2*find(posShape);
                if any(posShape)
                    app.ShapeFile=varargin{fposShape};
                    varargin([ fposShape-1  fposShape])=[];
                end

                posbsb=strcmp('bsb',UserOptions);

                if any(posbsb)
                    % In this case the robust option has been called
                    if sum(varargin{2*find(posbsb)})<n
                        app.ShowRobustGroup=true;
                    end
                end
            end

            app.colorBlindSafe = false;

            main(app, Xtable, varargin{:})
            % Call main again to enable
            hold(app.UIAxes,'off')
            plot(app.UIAxes,0,0,'o',"LineWidth",0.0001)
            drawnow
            main(app, Xtable, varargin{:})


        end

        % Value changed function: SliderAlpha
        function SliderAlphaValueChanged(app, event)
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb, ...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: SliderOmega
        function SliderOmegaValueChanged(app, event)
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb, ...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: ChkBoxshowRowPoints
        function ChkBoxshowRowPointsValueChanged(app, event)
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb, ...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: ChkBoxshowRowNames
        function ChkBoxshowRowNamesValueChanged(app, event)
            if app.ChkBoxshowRowNames.Value == true
                app.TextdensitypercentageKnob.Visible ="on";
                app.TextdensitypercentageKnobLabel.Visible="on";
            else
                app.TextdensitypercentageKnob.Visible ="off";
                app.TextdensitypercentageKnobLabel.Visible="off";
            end

            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: ChkBoxshowArrows
        function ChkBoxshowArrowsValueChanged(app, event)
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: ChkBoxRowPointsProp2Dist
        function ChkBoxRowPointsProp2DistValueChanged(app, event)
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)

        end

        % Value changed function: SliderBDP
        function SliderBDPValueChanged(app, event)
            app.BDPslider=true;
            app.FSslider=false;
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: SliderFS
        function SliderFSValueChanged(app, event)
            app.BDPslider=false;
            app.FSslider=true;
            app.ChkBoxAutomaticFS.Value =false;
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)

        end

        % Close request function: FSDAdynamicinteractivebiplotUIFigure
        function FSDAdynamicinteractivebiplotUIFigureCloseRequest(app, event)
            finalbsb=app.bsb;
            % save biplotAPPout bsb
            assignin('base','bsbfinalFromAPP',finalbsb);
            delete(app)

        end

        % Value changed function: CA1Switch
        function CA1SwitchValueChanged(app, event)
            app.PCA1or2switch =true;
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)

        end

        % Value changed function: CA2Switch
        function CA2SwitchValueChanged(app, event)
            app.PCA1or2switch =true;
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)

        end

        % Value changed function: ChkBoxChangeSign
        function ChkBoxChangeSignValueChanged(app, event)
            if app.ChkBoxChangeSign.Value == true
                app.ChangesignPanel.Visible ="on";
            else
                app.ChangesignPanel.Visible ="off";
            end

        end

        % Value changed function: ChkBoxAutomaticFS
        function ChkBoxAutomaticFSValueChanged(app, event)
            app.automaticFS = app.ChkBoxAutomaticFS.Value;
            if app.automaticFS==true
                app.SliderFS.Value=app.SliderFS.Limits(1);
                app.BDPslider=false;
                app.FSslider=false;
            end
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: CheckBoxminMD
        function CheckBoxminMDValueChanged(app, event)
            % value = app.CheckBoxminMD.Value;
            if app.ChkBoxAutomaticFS.Value == false && app.FSslider == false
                app.FSslider = true;
            end

            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)

        end

        % Value changed function: CheckBoxMDforward
        function CheckBoxMDforwardValueChanged(app, event)

            if app.FSslider == false
                app.FSslider = true;
            end
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)

        end

        % Value changed function: CheckBoxShowMDind
        function CheckBoxShowMDindValueChanged(app, event)
            app.BDPslider=true;
            app.FSslider=false;
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: ConflevEditField
        function ConflevEditFieldValueChanged(app, event)
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: ChkBoxRobAnalysis
        function ChkBoxRobAnalysisValueChanged(app, event)

            value = app.ChkBoxRobAnalysis.Value;

            if value == true
                app.ShowRobustGroup=true;
                app.RowPointsLabel.Visible="off";
                app.ArrowsCoordinatesLabel.Visible="off";

            else
                app.RowPointsLabel.Visible="on";
                app.ArrowsCoordinatesLabel.Visible="on";
                app.SliderAlpha.Visible="on";
                app.SliderOmega.Visible="on";
                app.CheckBoxMDforward.Value = false;
                app.CheckBoxminMD.Value = false;
                app.CheckBoxShowMDandrobMD.Value = false;
                app.CheckBoxShowMDind.Value = false;
                app.ShowRobustGroup=false;
                app.bsb='';
                app.FSslider = false;
                app.BDPslider = false;
            end

            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
            HideSVDslidersCheckBoxValueChanged(app, event)
        end

        % Value changed function: CheckBoxShowMDandrobMD
        function CheckBoxShowMDandrobMDValueChanged(app, event)
            app.BDPslider=true;
            app.FSslider=false;
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            rownames=app.Xtable.Properties.RowNames;
            varnames=app.Xtable.Properties.VariableNames;
            aux.computePCA(app.Xtable{:,:},app.bsb,rownames,varnames,app.standardize,2,true,true,app.Latitude,app.Longitude,app.ShapeFile,false);
        end

        % Value changed function: HideSVDslidersCheckBox
        function HideSVDslidersCheckBoxValueChanged(app, event)
            value = app.HideSVDslidersCheckBox.Value;
            % disp('Posizioni')
            % disp(app.UIAxes.Position)
            if value==1
                app.SliderAlpha.Visible="off";
                app.SliderOmega.Visible="off";
                app.SliderLabelAlpha.Visible="off";
                app.SliderLabelOmega.Visible="off";
                app.RowPointsLabel.Visible="off";
                app.ArrowsCoordinatesLabel.Visible="off";
                % if app.ChkBoxRobAnalysis ==true
                %     app.UIAxes.Position=[7 135 516 437];
                % else
                %     app.UIAxes.Position=[7 135 573 503];
                % end
                %app.UIAxes.Position=[7 75 589 497];
                % app.UIAxes.Position(4)=app.UIAxes.Position(4)*1.2;
            elseif value==0
                app.SliderAlpha.Visible="on";
                app.SliderOmega.Visible="on";
                app.SliderLabelAlpha.Visible="on";
                app.SliderLabelOmega.Visible="on";
                app.RowPointsLabel.Visible="on";
                app.ArrowsCoordinatesLabel.Visible="on";
                % if app.ChkBoxRobAnalysis ==true
                %     app.UIAxes.Position=[7 135 516 351];
                % else
                %     app.UIAxes.Position=[7 135 516 437];
                % end
                %app.UIAxes.Position=[7 75 546 400];
                % app.UIAxes.Position(4)=app.UIAxes.Position(4)/1.2;
            end
        end

        % Value changed function: ChkBoxBrush
        function ChkBoxBrushValueChanged(app, event)
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)

        end

        % Value changed function: ChkBoxConfEll
        function ChkBoxConfEllValueChanged(app, event)
            if app.ChkBoxConfEll.Value == true
                app.ConfEllEditField.Visible ="on";
                app.ConfEllLabel.Visible="on";
            else
                app.ConfEllEditField.Visible ="off";
                app.ConfEllLabel.Visible="off";
            end
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: ConfEllEditField
        function ConfEllEditFieldValueChanged(app, event)
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)

        end

        % Callback function
        function TextDensPercEditFieldValueChanged(app, event)


            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: TextdensitypercentageKnob
        function TextdensitypercentageKnobValueChanged(app, event)
            app.densKnob=app.TextdensitypercentageKnob.Value;
            main(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FSDAdynamicinteractivebiplotUIFigure = uifigure('Visible', 'off');
            app.FSDAdynamicinteractivebiplotUIFigure.Name = 'FSDA dynamic interactive biplot';
            app.FSDAdynamicinteractivebiplotUIFigure.CloseRequestFcn = createCallbackFcn(app, @FSDAdynamicinteractivebiplotUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.FSDAdynamicinteractivebiplotUIFigure);
            xlabel(app.UIAxes, 'PC1')
            ylabel(app.UIAxes, 'PC2')
            app.UIAxes.XTickLabelRotationMode = 'manual';
            app.UIAxes.YTickLabelRotationMode = 'manual';
            app.UIAxes.ZTickLabelRotationMode = 'manual';
            app.TabRobust = uitabgroup(app.FSDAdynamicinteractivebiplotUIFigure);
            app.ForwardSearch = uitab(app.TabRobust);
            app.ForwardSearch.Title = 'Forward Search';
            app.SliderFSLabel = uilabel(app.ForwardSearch);
            app.SliderFSLabel.Text = {'Subset size/n'; ''};
            app.SliderFS = uislider(app.ForwardSearch);
            app.SliderFS.Limits = [-1 10000000];
            app.SliderFS.MajorTicks = [];
            app.SliderFS.ValueChangedFcn = createCallbackFcn(app, @SliderFSValueChanged, true);
            app.SliderFS.MinorTicks = [-1 199999 399999 599999 799999 999999 1199999 1399999 1599999 1799999 1999999 2199999 2399999 2599999 2799999 2999999 3199999 3399999 3599999 3799999 3999999 4199999 4399999 4599999 4799999 4999999 5199999 5399999 5599999 5799999 5999999 6199999 6399999 6599999 6799999 6999999 7199999 7399999 7599999 7799999 7999999 8199999 8399999 8599999 8799999 8999999 9199999 9399999 9599999 9799999 10000000];
            app.SliderFS.Tooltip = {'Select the value of subset size you wish to use in order to compute CA for that subset size. The units outside the subset  are shown in the plot with filled circles.'};
            app.SliderFS.Value = 10000000;
            app.FSMonitoringplotsPanel = uipanel(app.ForwardSearch);
            app.FSMonitoringplotsPanel.Title = 'FS Monitoring plots';
            app.CheckBoxminMD = uicheckbox(app.FSMonitoringplotsPanel);
            app.CheckBoxminMD.ValueChangedFcn = createCallbackFcn(app, @CheckBoxminMDValueChanged, true);
            app.CheckBoxminMD.Tooltip = {'Show the plot of minimum Mahalanabis distance outside subset'};
            app.CheckBoxminMD.Text = 'minimum MD outside subset';
            app.CheckBoxMDforward = uicheckbox(app.FSMonitoringplotsPanel);
            app.CheckBoxMDforward.ValueChangedFcn = createCallbackFcn(app, @CheckBoxMDforwardValueChanged, true);
            app.CheckBoxMDforward.Tooltip = {'Show the monitoring plot of Mahalanobis distances along the forward search'};
            app.CheckBoxMDforward.Text = ' Monitoring MD';
            app.ChkBoxAutomaticFS = uicheckbox(app.ForwardSearch);
            app.ChkBoxAutomaticFS.ValueChangedFcn = createCallbackFcn(app, @ChkBoxAutomaticFSValueChanged, true);
            app.ChkBoxAutomaticFS.Text = 'Automatic';
            app.MCD = uitab(app.TabRobust);
            app.MCD.Title = 'MCD';
            app.SliderBDPLabel = uilabel(app.MCD);
            app.SliderBDPLabel.Text = 'bdp';
            app.SliderBDP = uislider(app.MCD);
            app.SliderBDP.Limits = [0 0.5];
            app.SliderBDP.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5];
            app.SliderBDP.MajorTickLabels = {'0.5', '0.4', '0.3', '0.2', '0.1', '0'};
            app.SliderBDP.ValueChangedFcn = createCallbackFcn(app, @SliderBDPValueChanged, true);
            app.SliderBDP.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5];
            app.SliderBDP.Tooltip = {'Select the value of breakdown point you wish to use in otder to compute the MCD. The units flagged as outliers at the 1 per cent simultaneous confidence level are shown in the plot with filled circle'};
            app.SliderBDP.Value = 0.5;
            app.MCDplotsPanel = uipanel(app.MCD);
            app.MCDplotsPanel.Title = 'MCD plots';
            app.CheckBoxShowMDind = uicheckbox(app.MCDplotsPanel);
            app.CheckBoxShowMDind.ValueChangedFcn = createCallbackFcn(app, @CheckBoxShowMDindValueChanged, true);
            app.CheckBoxShowMDind.Tooltip = {'Show the index plot of robust squared Mahalanobis distances '};
            app.CheckBoxShowMDind.Text = 'MD index plot';
            app.CheckBoxShowMDandrobMD = uicheckbox(app.MCDplotsPanel);
            app.CheckBoxShowMDandrobMD.ValueChangedFcn = createCallbackFcn(app, @CheckBoxShowMDandrobMDValueChanged, true);
            app.CheckBoxShowMDandrobMD.Tooltip = {'Show the scatter of robust Mahalanobis distances vs non robust Mahalanobis distances'};
            app.CheckBoxShowMDandrobMD.Text = 'rob vs non rob MD';
            app.Button = uibutton(app.FSDAdynamicinteractivebiplotUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'private', 'updateIMAGE.png');
            app.Button.Tooltip = {'Recompute the svd (eigenvalues, eigenvectors and the outliers map) after excluding the units detected as outliers '};
            app.Button.Text = '';
            app.RowPointsLabel = uilabel(app.FSDAdynamicinteractivebiplotUIFigure);
            app.RowPointsLabel.Text = 'RowPoints';
            app.ArrowsCoordinatesLabel = uilabel(app.FSDAdynamicinteractivebiplotUIFigure);
            app.ArrowsCoordinatesLabel.Text = 'ArrowsCoordinates';
            app.Panel = uipanel(app.FSDAdynamicinteractivebiplotUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.ChkBoxshowRowPoints = uicheckbox(app.GridLayout);
            app.ChkBoxshowRowPoints.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowRowPointsValueChanged, true);
            app.ChkBoxshowRowPoints.Tooltip = {'Show the coordinates (scores) of each row with respect to the first two PCs. The kind of score (unstandardized, standardized or in between) depends on the ω and α sliders on top of the window.'};
            app.ChkBoxshowRowPoints.Text = 'showRowPoints';
            app.ChkBoxshowRowPoints.Value = true;
            app.ChkBoxshowRowNames = uicheckbox(app.GridLayout);
            app.ChkBoxshowRowNames.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowRowNamesValueChanged, true);
            app.ChkBoxshowRowNames.Tooltip = {'Show the labels (RowNames) of the scores.'};
            app.ChkBoxshowRowNames.Text = 'showRowNames';
            app.ChkBoxshowArrows = uicheckbox(app.GridLayout);
            app.ChkBoxshowArrows.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowArrowsValueChanged, true);
            app.ChkBoxshowArrows.Tooltip = {'Show or hide the arrows associated to the correlations among the original variables and among the variables and the two PCs axes. The length of the arrows depends on the ω and α sliders on top of the window.'};
            app.ChkBoxshowArrows.Text = 'showArrows';
            app.ChkBoxshowArrows.Value = true;
            app.ChkBoxRowPointsProp2Dist = uicheckbox(app.GridLayout);
            app.ChkBoxRowPointsProp2Dist.ValueChangedFcn = createCallbackFcn(app, @ChkBoxRowPointsProp2DistValueChanged, true);
            app.ChkBoxRowPointsProp2Dist.Tooltip = {'If this checkbox is selected every row point has a color which is proportional to its orthogonal distance to the subspaced spanned by the first two principal components. Units in dark blue have a very good representation while units in red have a large orthogonal distance and are not represented well.'};
            app.ChkBoxRowPointsProp2Dist.Text = 'color prop to orthogonal distance';
            app.ChkBoxChangeSign = uicheckbox(app.GridLayout);
            app.ChkBoxChangeSign.ValueChangedFcn = createCallbackFcn(app, @ChkBoxChangeSignValueChanged, true);
            app.ChkBoxChangeSign.Tooltip = {'Open the panel which allows the user to change the sign of the 1st or 2nd eigenvector. Note that the biplot  is changed, and also the plot of the correlations, between the variables and the PCs, but the output structure in the Workspace does not change.'};
            app.ChkBoxChangeSign.Text = 'Show change sign panel';
            app.ChkBoxRobAnalysis = uicheckbox(app.GridLayout);
            app.ChkBoxRobAnalysis.ValueChangedFcn = createCallbackFcn(app, @ChkBoxRobAnalysisValueChanged, true);
            app.ChkBoxRobAnalysis.Tooltip = {'Robust principal component analysis. Multilvariate outlier detection is done and the units detected as outliers are excluded from the computation of the svd. FS or MCD estimators can be used to detect the outliers.'};
            app.ChkBoxRobAnalysis.Text = 'Robust analysis';
            app.HideSVDslidersCheckBox = uicheckbox(app.GridLayout);
            app.HideSVDslidersCheckBox.ValueChangedFcn = createCallbackFcn(app, @HideSVDslidersCheckBoxValueChanged, true);
            app.HideSVDslidersCheckBox.Text = 'Hide SVD sliders';
            app.ChkBoxBrush = uicheckbox(app.GridLayout);
            app.ChkBoxBrush.ValueChangedFcn = createCallbackFcn(app, @ChkBoxBrushValueChanged, true);
            app.ChkBoxBrush.Tooltip = {'If this checkbox is selected it is possible to brush the units inside the biplot and see their position in the scatterplot matrix'};
            app.ChkBoxBrush.Text = 'Brush units';
            app.ChkBoxConfEll = uicheckbox(app.GridLayout);
            app.ChkBoxConfEll.ValueChangedFcn = createCallbackFcn(app, @ChkBoxConfEllValueChanged, true);
            app.ChkBoxConfEll.Tooltip = {'If this checkbox is selected a confidence ellipse is added to the biplot. When checkbox showRowNames is selected just the labels of the units which are outside of the ellipse are shown'};
            app.ChkBoxConfEll.Text = 'conf. ell';
            app.ConfEllEditField = uieditfield(app.GridLayout, 'numeric');
            app.ConfEllEditField.Limits = [0 100];
            app.ConfEllEditField.ValueChangedFcn = createCallbackFcn(app, @ConfEllEditFieldValueChanged, true);
            app.ConfEllEditField.Visible = 'off';
            app.ConfEllEditField.Tooltip = {'Confidence level for confidence ellipse'};
            app.ConfEllEditField.Value = 95;
            app.ConfEllLabel = uilabel(app.GridLayout);
            app.ConfEllLabel.Visible = 'off';
            app.ConfEllLabel.Text = '%';
            app.TextdensitypercentageKnobLabel = uilabel(app.GridLayout);
            app.TextdensitypercentageKnobLabel.Tooltip = {'Percentage of text data to show (requires the Text Analytics toolbox)'};
            app.TextdensitypercentageKnobLabel.Text = {'Text density'; 'percentage'};
            app.TextdensitypercentageKnob = uiknob(app.GridLayout, 'continuous');
            app.TextdensitypercentageKnob.MajorTicks = [10 90];
            app.TextdensitypercentageKnob.ValueChangedFcn = createCallbackFcn(app, @TextdensitypercentageKnobValueChanged, true);
            app.TextdensitypercentageKnob.Value = 100;
            app.ChangesignPanel = uipanel(app.FSDAdynamicinteractivebiplotUIFigure);
            app.ChangesignPanel.Tooltip = {'Revert the direction of the first or second principal component'};
            app.ChangesignPanel.Title = 'Change sign';
            app.ChangesignPanel.Visible = 'off';
            app.CA2SwitchLabel = uilabel(app.ChangesignPanel);
            app.CA2SwitchLabel.Text = 'PC2';
            app.CA2Switch = uiswitch(app.ChangesignPanel, 'slider');
            app.CA2Switch.ItemsData = [false true];
            app.CA2Switch.ValueChangedFcn = createCallbackFcn(app, @CA2SwitchValueChanged, true);
            app.CA2Switch.Value = false;
            app.CA1SwitchLabel = uilabel(app.ChangesignPanel);
            app.CA1SwitchLabel.Text = 'PC1';
            app.CA1Switch = uiswitch(app.ChangesignPanel, 'slider');
            app.CA1Switch.ItemsData = [false true];
            app.CA1Switch.ValueChangedFcn = createCallbackFcn(app, @CA1SwitchValueChanged, true);
            app.CA1Switch.Value = false;
            app.SliderLabelOmega = uilabel(app.FSDAdynamicinteractivebiplotUIFigure);
            app.SliderLabelOmega.Text = 'ω';
            app.SliderOmega = uislider(app.FSDAdynamicinteractivebiplotUIFigure);
            app.SliderOmega.Limits = [0 1];
            app.SliderOmega.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.SliderOmega.ValueChangedFcn = createCallbackFcn(app, @SliderOmegaValueChanged, true);
            app.SliderOmega.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SliderOmega.Value = 0.7;
            app.SliderLabelAlpha = uilabel(app.FSDAdynamicinteractivebiplotUIFigure);
            app.SliderLabelAlpha.Text = 'α';
            app.SliderAlpha = uislider(app.FSDAdynamicinteractivebiplotUIFigure);
            app.SliderAlpha.Limits = [0 1];
            app.SliderAlpha.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.SliderAlpha.ValueChangedFcn = createCallbackFcn(app, @SliderAlphaValueChanged, true);
            app.SliderAlpha.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SliderAlpha.Value = 0.3;
            app.ConflevEditFieldLabel = uilabel(app.FSDAdynamicinteractivebiplotUIFigure);
            app.ConflevEditFieldLabel.Tooltip = {'Define Bonferronized confidence level which has to be used to detect the outliers'};
            app.ConflevEditFieldLabel.Text = 'Conf lev';
            app.ConflevEditField = uieditfield(app.FSDAdynamicinteractivebiplotUIFigure, 'numeric');
            app.ConflevEditField.ValueChangedFcn = createCallbackFcn(app, @ConflevEditFieldValueChanged, true);
            app.ConflevEditField.Tooltip = {'Bonferronized confidence level which is used to detect the outliers inside robust estimators'};
            app.ConflevEditField.Value = 0.99;
            % Show the figure after all components are created
            app.FSDAdynamicinteractivebiplotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = biplotAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FSDAdynamicinteractivebiplotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FSDAdynamicinteractivebiplotUIFigure)
        end
    end
end