classdef geoplotAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FSDAInteractivegeomapUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        MainLabel                      matlab.ui.control.Label
        geoPanel                       matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        LabelsdensityKnob              matlab.ui.control.Knob
        textdensityLabel               matlab.ui.control.Label
        ColorbasedonDropDown           matlab.ui.control.DropDown
        ColorbasedonLabel              matlab.ui.control.Label
        GeographiccoordinatesCheckBox  matlab.ui.control.CheckBox
        ShowlabelsCheckBox             matlab.ui.control.CheckBox
        MapTypeDropDown                matlab.ui.control.DropDown
        MapTypeDropDownLabel           matlab.ui.control.Label
        ReversecolorSwitch             matlab.ui.control.Switch
        ReversecolorSwitchLabel        matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property5 % Description
    end

    properties (Access = public)
        Xtable='';
        score='';
        Xgeotable='';
        proj='';
        varNames='';
        bsb='';
        arrlonglat;
        rownames='';
        ga='';
        stdMapHandle='';
        LabelPerm;
        LabelsDefaultPercDensity = 100;
        LabelOrder;
        FlagLabelOrderCalculated=0;
    end

    methods (Access = public)

    end

    methods (Access = private)

        function  main(app)
            app.rownames=app.Xtable.Properties.RowNames;
            n=size(app.Xtable,1);
          
            % starts ordering all labels)                        
            kmax   = n; 

            app.LabelsdensityKnob.Visible = false;
            % % Specify the variable for which the colors have to be set
            % if app.SwitchcolortoPC2.ValueIndex == 2
            %     colorIntensity = app.score(:,2);
            %     strForColorBar='Color based on second PC';
            %
            % else
            %     colorIntensity = app.score(:,1);
            %     strForColorBar='Color based on first PC';
            % end

            colorIntensity=app.score(:,app.ColorbasedonDropDown.Value);
            strForColorBar="Color based on "+ app.varNames(app.ColorbasedonDropDown.Value);

            if app.ReversecolorSwitch.ValueIndex==2
                colorIntensity=-colorIntensity;
            end

            % Define a colorbar from green to red with 50 colors
            %numColors=50;
            %customGradient = interp1([0;1],[0 1 0; 1 0 0],linspace(0,1,numColors));

            % Define a colorblind-safe diverging colormap
            numColors=50;
            customGradient = aux.diverging_hcl_matlab(numColors);


            % Show or not in geographical coordinates
            if app.GeographiccoordinatesCheckBox.Value == true
                geocoord = true;
            else
                geocoord = false;
            end

            if geocoord == true
                app.geoPanel.Visible = "on";
                app.MapTypeDropDown.Visible= "on";
                app.MapTypeDropDownLabel.Visible ="on";


                delete(app.geoPanel.Children)
                app.ga=geoaxes(app.geoPanel);
                % Add to the geotable the Variable which contains the colors

                app.Xgeotable.ColorVar=colorIntensity(:);

                hg=geoplot(app.ga,app.Xgeotable,'ColorVariable','ColorVar');
                % Find full color scale of colorIntensity
                fullMin = min(colorIntensity);
                fullMax = max(colorIntensity);

                colormap(app.ga,customGradient);
                h=colorbar(app.ga);
                h.Label.String=strForColorBar;
                value = app.MapTypeDropDown.Value;
                basemaps = ["satellite" "streets" "streets-light" "streets-dark" ...
                    "landcover" "darkwater" "grayland" "bluegreen" ...
                    "grayterrain" "colorterrain"];
                geobasemap(app.ga,basemaps(value))

                % Lock color limit of axes
                ax = hg.Parent;
                clim(ax,[fullMin fullMax])

                notbsb=setdiff(1:n,app.bsb);
                hg.SourceTable.ColorVar(notbsb)=NaN;


                if app.ShowlabelsCheckBox.Value == true
                    app.LabelsdensityKnob.Visible = "on";
                    app.textdensityLabel.Visible = "on";

                    
                    % Add labels in the centroids of the shapes
                    % extract XY pairs coords of enclosing polygons for each row of input table
                    Xtab= geotable2table(app.Xgeotable, ["X" "Y"]);
                    % Obtain the average latitude and longitude for each row
                    warning('off','map:shapefile:unsupportedType')

                    app.arrlonglat=zeros(n,2);

                    % Check the type of coordinates we have
                    try
                        projinv(app.proj, Xtab.X{1},Xtab.Y{1});
                        satcoord=true;
                    catch
                        satcoord=false;
                    end

                    for i = 1:n
                        if satcoord==true
                            [lat, lon] = projinv(app.proj, Xtab.X{i},Xtab.Y{i});  % Convert to lat/lon
                        else
                            lat=Xtab.X{i};
                            lon=Xtab.Y{i};
                        end

                        app.arrlonglat(i,1:2)=[mean(lat,'omitmissing') mean(lon,'omitmissing')];
                        % Improved code to define the centroid
                        % the following code causes a lot of warnings:
                        %    [meanlat,meanlon]=centroid(polyshape([lat' lon']));
                        %    app.arrlonglat(i,1:2)=[meanlat,meanlon];

                    end

                    % Show the labels using geographical coordinates
                    if app.FlagLabelOrderCalculated==0
                        app.LabelOrder = farthestPointOrder(app, app.arrlonglat(app.bsb,1), app.arrlonglat(app.bsb,2), kmax);
                        app.FlagLabelOrderCalculated=1;
                    end
                    app.redrawLabels();
    

                else
                    % hide the knob and his label
                    app.LabelsdensityKnob.Visible = "off";
                    app.textdensityLabel.Visible = "off";
                end

            else % Plot without using geographical coordinates
                app.geoPanel.Visible = "off";
                app.MapTypeDropDown.Visible="off";
                app.MapTypeDropDownLabel.Visible ="off";

                cla(app.UIAxes, 'reset')

                % This rescales colorIntensity's actual min/max to the colormap's index range 1..numColors.
                %fc=customGradient(round(rescale(colorIntensity,1,numColors)),:);
                
                % the center-anchoring fix is to rescale symmetrically around 0 rather than around the data's own min/max
                maxAbs = max(abs(colorIntensity));
                fc = customGradient(round(rescale(colorIntensity, 1, numColors, ...
                    'InputMin', -maxAbs, 'InputMax', maxAbs)), :);

                % Check if mapshow can be called (Display map data without
                % projection)
                % otherwise call geoshow (Display map latitude and
                % longitude data)
                GT=app.Xgeotable;
                typeSHape=GT.Properties.VariableTypes(string(GT.Properties.VariableNames)=="Shape");
                if ismember(typeSHape,["mappointshape", "maplineshape" "mappolyshape"])
                    polygon=mapshow(app.UIAxes,app.Xgeotable, "FaceAlpha",0.9,"FaceColor","none");
                    rev=false;
                else
                    polygon=geoshow(app.UIAxes,app.Xgeotable, "FaceAlpha",0.9,"FaceColor","none");
                    rev=true;
                end
                for i=app.bsb % 1:n
                    set(polygon.Children(i), 'FaceColor', fc(i,:),"FaceAlpha",0.9);
                end
                colormap(app.UIAxes,customGradient);
                rang=[min(colorIntensity) max(colorIntensity)];
                h=colorbar(app.UIAxes);
                h.Label.String=strForColorBar;
                soglie=linspace(min(rang),max(rang),6);
                valori=(soglie-min(soglie))/(max(soglie)-min(soglie));
                h.Ticks=valori;
                h.TickLabels=string(soglie);

                if app.ShowlabelsCheckBox.Value == true
                    % show knob and description
                    app.LabelsdensityKnob.Visible = "on";
                    app.textdensityLabel.Visible = "on";

                    % Add labels in the centroids of the shapes
                    app.arrlonglat=zeros(n,2);
                    % extract XY pairs coords (that is latitude and
                    % longitude) of enclosing polygons for each row of
                    % input table
                    Xtab= geotable2table(app.Xgeotable, ["X" "Y"]);
                    warning('off','MATLAB:polyshape:repairedBySimplify')

                    for i=app.bsb  % 1:n
                        % first we create polygonal objects
                        polObj= polyshape(Xtab.X{i,1}, ...
                            Xtab.Y{i,1});
                        [centX,centY]=centroid(polObj);
                        % Store centroids
                        if rev==true
                            app.arrlonglat(i,1:2)=[centY centX];
                        else
                            app.arrlonglat(i,1:2)=[centX centY];
                        end
                    end
                    %text(app.UIAxes,app.arrlonglat(app.bsb,1), app.arrlonglat(app.bsb,2),app.rownames(app.bsb))
                    % Show the labels using geographical coordinates
                    app.redrawLabelsUx;

                else
                    app.LabelsdensityKnob.Visible = "off";
                    app.textdensityLabel.Visible = "off";
                end
                hold(app.UIAxes,'off')
            end
        end

        function redrawLabels(app)
            %  called from knob callback to set labels density

            delete(findall(app.ga, "Type","text", "Tag","GeoLabels"));
            pct = app.LabelsdensityKnob.Value;
            N = numel(app.arrlonglat(app.bsb,1));

            k   = max(0, min(numel(app.LabelOrder), round(pct/100 * N)));
            if k == 0
                return
            end

            idx = app.LabelOrder(1:k);
            idxsel=app.bsb(idx);

            text(app.ga, app.arrlonglat(idxsel,1), app.arrlonglat(idxsel,2), app.rownames(idxsel), ...
                "FontSize", 8, "Tag", "GeoLabels");

        end

        function redrawLabelsUx(app)
            %  called from knob callback to set labels density

            delete(findall(app.UIAxes, "Type","text", "Tag","CartesianLabels"));
            pct = app.LabelsdensityKnob.Value;
            N = numel(app.arrlonglat(app.bsb,1));

           k   = max(0, min(numel(app.LabelOrder), round(pct/100 * N)));
            if k == 0
                return
            end

            idx = app.LabelOrder(1:k);
            idxsel=app.bsb(idx);

            text(app.UIAxes, app.arrlonglat(idxsel,1), app.arrlonglat(idxsel,2), app.rownames(idxsel), ...
                "FontSize", 8, "Tag", "CartesianLabels");


        end

        function order = farthestPointOrder(app, latDeg, lonDeg, kmax)
            % ordering using haversine distances.
            % Returns an index vector of length kmax of increasing distances.

            lat = deg2rad(latDeg(:));
            lon = deg2rad(lonDeg(:));
            N = numel(lat);

            if nargin < 3 || isempty(kmax)
                kmax = N;
            end
            kmax = min(kmax, N);

           
            % start from centroid
            lat0 = mean(lat); lon0 = mean(lon);
            d0 = haversineVec(app, lat, lon, lat0, lon0);
            [~, startIdx] = min(d0);
          

            order = zeros(1, kmax, 'uint32');
            chosen = false(N,1);

            order(1) = startIdx;
            chosen(startIdx) = true;

            % mindist(i) = distance from point i to the nearest chosen point
            mindist = haversineVec(app, lat, lon, lat(startIdx), lon(startIdx));
            mindist(chosen) = -Inf;

            for t = 2:kmax
                % farthest from chosen set
                [~, imax] = max(mindist);      
                order(t) = imax;
                chosen(imax) = true;
                dnew = haversineVec(app, lat, lon, lat(imax), lon(imax));
                % update nearest distance
                mindist = min(mindist, dnew);  
                mindist(chosen) = -inf;
            end
        end

        function dkm = haversineVec(~, lat, lon, lat0, lon0)
            % Vectorized great-circle distance (km) from (lat0,lon0) to all (lat,lon)
            
            % mean Earth radius (km)
            radiusEarth = 6371.0088; 
            dlat = lat - lat0;
            dlon = lon - lon0;

            a = sin(dlat/2).^2 + cos(lat0).*cos(lat).*sin(dlon/2).^2;
            c = 2 * atan2(sqrt(a), sqrt(1-a));
            dkm = radiusEarth * c;
        end


    end
    %                  GeographicAxes     FaceColor
    %    geoplot            yes             no (yes if mapping toolbox is
    %                                           installed)
    %    geoscatter         yes             no
    %    wmpolygon           no             yes       (to be removed)
    %     mapshow            no             yes
    %     geoshow            no             yes
    % geodensityplot         yes             no
    %
    % To call geoplot or  mapshow
    %   mappointshape, maplineshape, or mappolyshape

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Xtable, score, ShapeFile, bsb)

            [n,p]=size(Xtable);
            app.bsb=bsb;

            if ~istable(Xtable)
                Xtable=array2table(Xtable,"RowNames","r"+(1:n),"VariableNames","X"+(1:p));
            end

            if isempty(app.bsb)
                app.bsb=1:n;
            elseif islogical(app.bsb)
                seq=1:n;
                app.bsb=seq(app.bsb);
            end


            if isgeotable(ShapeFile)
                app.Xgeotable=ShapeFile;
            elseif ischar(ShapeFile) || isstring(ShapeFile)
                app.Xgeotable = readgeotable(ShapeFile);
            else
                error('FSDA:geoplotAPP:WrongInput','ShapeFile must be a char or a string or a geotable')
            end
            % Not necessary to call shapeinfo
            %{
            info = shapeinfo(ShapeFile);
            app.proj = info.CoordinateReferenceSystem;
            %}


            % score can be a scalar number (in this case it refers to a particular
            % column for example 3) a scalar string or char (in this case
            % it contains the name of the variable inside Xtable to use).
            % It can be a matrix with two columns (in this last case we
            % assume that these are the first two principal components)


            % Try to understand type of coordinate systems. The geographic coordinate
            % systems are based on latitude
            % and longitude coordinates. The projected coordinate systems,
            % represent the projection of a geographic coordinate
            % system on a plane and use linear measures (like feet or
            % meters) for coordinates.
            % https://www.hec.usace.army.mil/confluence/cwmsdocs/cwmsum/3.1.1/setting-up-the-coordinate-system/coordinate-system-types
            try
                app.proj = app.Xgeotable.Shape.ProjectedCRS;
            catch
                app.proj=[];
                % app.proj= projcrs(32632);
            end


            % create the array of latitude and longitude at startup
            Xtab= geotable2table(app.Xgeotable, ["X" "Y"]);
            warning('off','MATLAB:polyshape:repairedBySimplify')
            app.arrlonglat=zeros(n,2);
            for i = 1:n
                [lat, lon] = projinv(app.proj, Xtab.X{i},Xtab.Y{i});
                app.arrlonglat(i,1:2)=[mean(lat,'omitmissing') mean(lon,'omitmissing')];
            end

            % compute the ordered distances between city pairs
            if app.FlagLabelOrderCalculated==0
                if numel(app.bsb)==0
                    app.bsb=1:n;
                end
                app.LabelOrder = farthestPointOrder(app, app.arrlonglat(app.bsb,1), app.arrlonglat(app.bsb,2), n);
                app.FlagLabelOrderCalculated=1;
            end


            if isscalar(score) || ischar(score)
                % Populate the drop down variable
                app.varNames=Xtable.Properties.VariableNames;
                if isnumeric(score)
                    valDropDown=score;
                else
                    valDropDown=find(string(app.varNames)==string(score));
                end

                score=Xtable{:,:};
            else
                app.varNames="PC"+(1:size(score,2));
                valDropDown=1;
                app.MainLabel.Text="Interactive geomap based on (robust) PC1 or PC2";
            end
            app.ColorbasedonDropDown.Items=app.varNames;
            app.ColorbasedonDropDown.ItemsData=1:length(app.varNames);
            app.ColorbasedonDropDown.Value=valDropDown;


            % Store Xtable
            app.Xtable=Xtable;
            app.score=score;
            main(app)

        end

        % Value changed function: ShowlabelsCheckBox
        function ShowlabelsCheckBoxValueChanged(app, event)
            % value = app.ShowlabelsCheckBox.Value;
            main(app)
        end

        % Value changed function: ReversecolorSwitch
        function ReversecolorSwitchValueChanged(app, event)
            main(app)
        end

        % Value changed function: MapTypeDropDown
        function MapTypeDropDownValueChanged(app, event)
            main(app)
        end

        % Value changed function: GeographiccoordinatesCheckBox
        function GeographiccoordinatesCheckBoxValueChanged(app, event)
            main(app)
        end

        % Value changed function: ColorbasedonDropDown
        function ColorbasedonDropDownValueChanged(app, event)
            main(app)
        end

        % Value changed function: LabelsdensityKnob
        function TextdensitypercentageKnobValueChanged(app, event)
            % add flexibility calling a standard method from the callback
            app.redrawLabels;
            app.redrawLabelsUx;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FSDAInteractivegeomapUIFigure = uifigure('Visible', 'off');
            app.FSDAInteractivegeomapUIFigure.Name = 'FSDA: Interactive geomap';
            app.GridLayout = uigridlayout(app.FSDAInteractivegeomapUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.GridLayout);
            app.ReversecolorSwitchLabel = uilabel(app.Panel);
            app.ReversecolorSwitchLabel.Text = 'Reverse color';
            app.ReversecolorSwitch = uiswitch(app.Panel, 'slider');
            app.ReversecolorSwitch.ValueChangedFcn = createCallbackFcn(app, @ReversecolorSwitchValueChanged, true);
            app.MapTypeDropDownLabel = uilabel(app.Panel);
            app.MapTypeDropDownLabel.Text = 'Map Type';
            app.MapTypeDropDown = uidropdown(app.Panel);
            app.MapTypeDropDown.Items = {'"satellite"', '"streets"', '"streets-light"', '"streets-dark"', '"landcover"', '"darkwater"', '"grayland"', '"bluegreen" ', '"grayterrain"', '"colorterrain'};
            app.MapTypeDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.MapTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MapTypeDropDownValueChanged, true);
            app.MapTypeDropDown.Tooltip = {'Type of map to use. Note that this drop down is visbile just if Geografic coordinates is selected.'};
            app.MapTypeDropDown.Value = 3;
            app.ShowlabelsCheckBox = uicheckbox(app.Panel);
            app.ShowlabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowlabelsCheckBoxValueChanged, true);
            app.ShowlabelsCheckBox.Text = 'Show labels';
            app.GeographiccoordinatesCheckBox = uicheckbox(app.Panel);
            app.GeographiccoordinatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @GeographiccoordinatesCheckBoxValueChanged, true);
            app.GeographiccoordinatesCheckBox.Tooltip = {'Use geogrphic axes based on latitute and longitude or simpl use the coordinates in the original ShapeFile'};
            app.GeographiccoordinatesCheckBox.Text = 'Geographic coordinates';
            app.GeographiccoordinatesCheckBox.Value = true;
            app.ColorbasedonLabel = uilabel(app.Panel);
            app.ColorbasedonLabel.Text = {'Color'; 'based on '};
            app.ColorbasedonDropDown = uidropdown(app.Panel);
            app.ColorbasedonDropDown.Items = {};
            app.ColorbasedonDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorbasedonDropDownValueChanged, true);
            app.ColorbasedonDropDown.Tooltip = {'Type of map to use. Note that this drop down is visbile just if Geografic coordinates is selected.'};
            app.ColorbasedonDropDown.Value = {};
            app.textdensityLabel = uilabel(app.Panel);
            app.textdensityLabel.Text = {'Labels'; 'density'};
            app.LabelsdensityKnob = uiknob(app.Panel, 'continuous');
            app.LabelsdensityKnob.ValueChangedFcn = createCallbackFcn(app, @TextdensitypercentageKnobValueChanged, true);
            app.LabelsdensityKnob.Tooltip = {'Percentage of labels to show.'};
            app.LabelsdensityKnob.Value = 100;
            app.geoPanel = uipanel(app.GridLayout);
            app.MainLabel = uilabel(app.GridLayout);
            app.MainLabel.Text = 'Interactive geomap based on selected variables';
            % Show the figure after all components are created
            app.FSDAInteractivegeomapUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = geoplotAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FSDAInteractivegeomapUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FSDAInteractivegeomapUIFigure)
        end
    end
end