classdef pcaFSif < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FSDAdynamicinteractivebiplotUIFigure  matlab.ui.Figure
        cabcCheckBox          matlab.ui.control.CheckBox
        ResetCheckBox         matlab.ui.control.CheckBox
        FullanalysisCheckBox  matlab.ui.control.CheckBox
        OUTPUTLabel           matlab.ui.control.Label
        INPUTLabel            matlab.ui.control.Label
        outputTable           matlab.ui.control.Table
        inputTable            matlab.ui.control.Table
    end


    properties (Access = public)
        % Property % Description
        Xtable='';

        % boolean which specifies to standardize data in Xtable
        standardize=true;
        % bsb subset on which to compute the statistics
        bsb=[];

        rownames='';

    end


    methods (Access = private)

        function results = createPCA(app,Zbsb)
            Zbsb=zscore(Zbsb);
            [~,~,V]=svd(Zbsb,'econ');

            % Find orthogonal distances
            score=Zbsb*V(:,1);
            if V(1,1)<0
                score=-score;
            end
            n=size(Zbsb,1);
            seq=(1:n)';
            [scoresor,indsor]=sort(score,'descend');
            indWithscore=[seq scoresor];
            indWithscoreT=array2table(indWithscore,'RowNames',app.rownames(indsor));
            indWithscoreT=sortrows(indWithscoreT,2,"descend");
            app.outputTable.Data=indWithscoreT;
            app.outputTable.ColumnName=["Pos." "Score"];
            app.outputTable.RowName=indWithscoreT.Properties.RowNames;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Xtable, varargin)

            %try
            %close all force
            %catch
            %end

            [n,p]=size(Xtable);
            if istable(Xtable)
                X=table2array(Xtable);
                varlabs=Xtable.Properties.VariableNames;
                app.rownames=Xtable.Properties.RowNames;
            else
                varlabs=cellstr(num2str((1:p)','Y%d'));
                app.rownames=cellstr(num2str((1:n)','%d'));
                X=Xtable;
            end

            app.Xtable=Xtable;
            app.inputTable.Data=Xtable;
            app.inputTable.ColumnName=varlabs;
            app.inputTable.RowName=Xtable.Properties.RowNames;
            % app.inputTable.Selection=3;
            % app.inputTable.scroll('row',100)
            scroll(app.inputTable,'row',3)

            s = uistyle("BackgroundColor",[1 0.6 0.6]);
            addStyle(app.inputTable,s,"row",39);
            % app.inputTable.ColumnEditable=true;

            app.bsb=[];

            if nargin>2

                options=struct('standardize',app.standardize, ...
                    'bsb',app.bsb);

                UserOptions=varargin(1:2:length(varargin));
                if ~isempty(UserOptions)
                    % Check if number of supplied options is valid
                    if length(varargin) ~= 2*length(UserOptions)
                        error('FSDA:biplotFS:WrongInputOpt','Number of supplied options is invalid. Probably values for some parameters are missing.');
                    end
                    % Check if user options are valid options
                    aux.chkoptions(options,UserOptions)
                end

                % Write in structure 'options' the options chosen by the user
                for i=1:2:length(varargin)
                    options.(varargin{i})=varargin{i+1};
                end

                app.standardize=options.standardize;
                app.bsb=options.bsb;
            end


            if isempty(app.bsb)
                app.bsb=true(n,1);
            else
                if ~islogical(app.bsb)
                    bsbini=false(n,1);
                    bsbini(app.bsb)=true;
                    app.bsb=bsbini;
                end
            end


            Xbsb=X(app.bsb,:);
            center=mean(Xbsb);
            nbsb=size(Xbsb,1);

            if app.standardize==true
                dispersion=std(Xbsb);
                % Create matrix of standardized data
                Z=(X-center)./dispersion;
            else
                % Create matrix of deviations from the means
                Z=X-center;
            end

            Zbsb=Z(app.bsb,:);
            createPCA(app,Zbsb);


            % app.outputTable.ColumnWidth
        end

        % Close request function: FSDAdynamicinteractivebiplotUIFigure
        function FSDAdynamicinteractivebiplotUIFigureCloseRequest(app, event)
            finalbsb=app.bsb;
            % save biplotAPPout bsb
            assignin('base','bsbfinalFromAPP',finalbsb);
            delete(app)

        end

        % Cell edit callback: inputTable
        function inputTableCellEdit(app, event)
            % indices = event.Indices;
            % newData = event.NewData;
            app.ResetCheckBox.Value=0;
            app.FullanalysisCheckBox.Value=0;
            createPCA(app,app.inputTable.Data{:,:});
        end

        % Value changed function: FullanalysisCheckBox
        function FullanalysisCheckBoxValueChanged(app, event)
            value = app.FullanalysisCheckBox.Value;
            if value == true
                pcaFS(app.inputTable.Data)

            end
        end

        % Value changed function: ResetCheckBox
        function ResetCheckBoxValueChanged(app, event)
            value = app.ResetCheckBox.Value;
            if value ==1
                app.inputTable.Data=app.Xtable;
                createPCA(app,app.inputTable.Data{:,:});
            end
        end

        % Value changed function: cabcCheckBox
        function cabcCheckBoxValueChanged(app, event)
            value = app.cabcCheckBox.Value;
               if value ==1
                   close all
               end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FSDAdynamicinteractivebiplotUIFigure = uifigure('Visible', 'off');
            app.FSDAdynamicinteractivebiplotUIFigure.Name = 'FSDA dynamic interactive biplot';
            app.FSDAdynamicinteractivebiplotUIFigure.CloseRequestFcn = createCallbackFcn(app, @FSDAdynamicinteractivebiplotUIFigureCloseRequest, true);
            app.inputTable = uitable(app.FSDAdynamicinteractivebiplotUIFigure);
            app.inputTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'};
            app.inputTable.RowName = {};
            app.inputTable.SelectionType = 'row';
            app.inputTable.ColumnEditable = true;
            app.inputTable.CellEditCallback = createCallbackFcn(app, @inputTableCellEdit, true);
            app.outputTable = uitable(app.FSDAdynamicinteractivebiplotUIFigure);
            app.outputTable.ColumnName = {'Column 1'; 'Column 2'; ''};
            app.outputTable.ColumnRearrangeable = 'on';
            app.outputTable.RowName = {};
            app.outputTable.ColumnSortable = [true true false];
            app.INPUTLabel = uilabel(app.FSDAdynamicinteractivebiplotUIFigure);
            app.INPUTLabel.Text = 'INPUT TABLE';
            app.OUTPUTLabel = uilabel(app.FSDAdynamicinteractivebiplotUIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT';
            app.FullanalysisCheckBox = uicheckbox(app.FSDAdynamicinteractivebiplotUIFigure);
            app.FullanalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @FullanalysisCheckBoxValueChanged, true);
            app.FullanalysisCheckBox.Text = 'Full analysis';
            app.ResetCheckBox = uicheckbox(app.FSDAdynamicinteractivebiplotUIFigure);
            app.ResetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetCheckBoxValueChanged, true);
            app.ResetCheckBox.Text = 'Reset original data';
            app.cabcCheckBox = uicheckbox(app.FSDAdynamicinteractivebiplotUIFigure);
            app.cabcCheckBox.ValueChangedFcn = createCallbackFcn(app, @cabcCheckBoxValueChanged, true);
            app.cabcCheckBox.Text = 'Close all other figures';
            % Show the figure after all components are created
            app.FSDAdynamicinteractivebiplotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pcaFSif(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FSDAdynamicinteractivebiplotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FSDAdynamicinteractivebiplotUIFigure)
        end
    end
end