classdef HelpGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        TabPreamble                 matlab.ui.container.Tab
        FileNameLabel               matlab.ui.control.Label
        FileName                    matlab.ui.control.EditField
        PurposeLabel                matlab.ui.control.Label
        Purpose                     matlab.ui.control.TextArea
        DescriptionLabel            matlab.ui.control.Label
        Description                 matlab.ui.control.TextArea
        FileNameHelp                matlab.ui.control.Label
        PurposeHelp                 matlab.ui.control.Label
        DescriptionHelp             matlab.ui.control.Label
        TabInpArgs                  matlab.ui.container.Tab
        InpArgsListBoxLabel         matlab.ui.control.Label
        InpArgsListBox              matlab.ui.control.ListBox
        InpArgsShortDescLabel       matlab.ui.control.Label
        InpArgsShortDesc            matlab.ui.control.TextArea
        InpArgsLongDescLabel        matlab.ui.control.Label
        InpArgsLongDesc             matlab.ui.control.TextArea
        InpArgsRequiredInputLabel   matlab.ui.control.Label
        InpArgsTypeIndicationLabel  matlab.ui.control.Label
        InpArgsTypeIndication       matlab.ui.control.TextArea
        InpArgsTypeIndicationHelp   matlab.ui.control.Label
        InpArgsLongDescHelp         matlab.ui.control.Label
        InpArgsRequiredInput        matlab.ui.control.CheckBox
        InpArgsShortDescHelp        matlab.ui.control.Label
        InpArgsExampleLabel         matlab.ui.control.Label
        InpArgsExample              matlab.ui.control.EditField
        InpArgsAdd                  matlab.ui.control.Button
        InpArgsDel                  matlab.ui.control.Button
        inpArgsDataTypeLabel        matlab.ui.control.Label
        InpArgsDataType             matlab.ui.control.EditField
        InpArgsUITable              matlab.ui.control.Table
        InpArgsStructure            matlab.ui.control.CheckBox
        InpArgsStructureHelp        matlab.ui.control.Label
        InpArgsZoom                 matlab.ui.control.TextArea
        InpArgsStructurevalueLabel  matlab.ui.control.Label
        InpArgsListBoxStruct        matlab.ui.control.ListBox
        InpArgsStructuredescriptionLabel  matlab.ui.control.Label
        TabOptArgs                  matlab.ui.container.Tab
        OptArgsTypeIndicationHelp   matlab.ui.control.Label
        OptArgsLongDescHelp         matlab.ui.control.Label
        OptArgsShortDescHelp        matlab.ui.control.Label
        OptArgsAdd                  matlab.ui.control.Button
        OptArgsDel                  matlab.ui.control.Button
        OptArgsListBoxLabel         matlab.ui.control.Label
        OptArgsListBox              matlab.ui.control.ListBox
        OptArgsShortDescLabel       matlab.ui.control.Label
        OptArgsShortDesc            matlab.ui.control.TextArea
        OptArgsLongDescLabel        matlab.ui.control.Label
        OptArgsLongDesc             matlab.ui.control.TextArea
        OptArgsTypeIndicationLabel  matlab.ui.control.Label
        OptArgsTypeIndication       matlab.ui.control.TextArea
        OptArgsExampleLabel         matlab.ui.control.Label
        OptArgsExample              matlab.ui.control.EditField
        OptArgsDataTypeLabel        matlab.ui.control.Label
        OptArgsDataType             matlab.ui.control.EditField
        OptArgsUITable              matlab.ui.control.Table
        OptArgsStructure            matlab.ui.control.CheckBox
        OptArgsStructureHelp        matlab.ui.control.Label
        TabOutArgs                  matlab.ui.container.Tab
        OutArgsListBoxLabel         matlab.ui.control.Label
        OutArgsListBox              matlab.ui.control.ListBox
        OutArgsTypeIndicationHelp   matlab.ui.control.Label
        OutArgsLongDescHelp         matlab.ui.control.Label
        OutArgsShortDescHelp        matlab.ui.control.Label
        OutArgsShortDescLabel       matlab.ui.control.Label
        OutArgsShortDesc            matlab.ui.control.TextArea
        OutArgsLongDescLabel        matlab.ui.control.Label
        OutArgsLongDesc             matlab.ui.control.TextArea
        OutArgsTypeIndicationLabel  matlab.ui.control.Label
        OutArgsTypeIndication       matlab.ui.control.TextArea
        OutArgsUITable              matlab.ui.control.Table
        OutArgsStructureHelp        matlab.ui.control.Label
        OutArgsStructure            matlab.ui.control.CheckBox
        OutArgsAdd                  matlab.ui.control.Button
        OutArgsDel                  matlab.ui.control.Button
        TabMoreAbout                matlab.ui.container.Tab
        MoreAboutLabel              matlab.ui.control.Label
        MoreAbout                   matlab.ui.control.TextArea
        AcknowledgementsLabel       matlab.ui.control.Label
        Acknowledgements            matlab.ui.control.TextArea
        ReferencesLabel             matlab.ui.control.Label
        References                  matlab.ui.control.TextArea
        SeeAlsoListBoxLabel         matlab.ui.control.Label
        SeeAlsoListBox              matlab.ui.control.ListBox
        SeeAlsoAdd                  matlab.ui.control.Button
        SeeAlsoDel                  matlab.ui.control.Button
        TabExamples                 matlab.ui.container.Tab
        ExtoExecuteHelp             matlab.ui.control.Label
        ExDescriptionHelp           matlab.ui.control.Label
        ExMatlabCodeHelp            matlab.ui.control.Label
        ExtoExecute                 matlab.ui.control.CheckBox
        ExAdd                       matlab.ui.control.Button
        ExeDel                      matlab.ui.control.Button
        ExMatlabCodeLabel           matlab.ui.control.Label
        ExMatlabCode                matlab.ui.control.TextArea
        ExDescriptionLabel          matlab.ui.control.Label
        ExDescription               matlab.ui.control.TextArea
        ExListBoxLabel              matlab.ui.control.Label
        ExListBox                   matlab.ui.control.ListBox
        TabExtraExamples            matlab.ui.container.Tab
        ExtraExtoExecuteHelp        matlab.ui.control.Label
        ExtraExDescriptionHelp      matlab.ui.control.Label
        ExtraExMatlabCodeHelp       matlab.ui.control.Label
        ExtraExtoExecute            matlab.ui.control.CheckBox
        ExtraExAdd                  matlab.ui.control.Button
        ExtraExDel                  matlab.ui.control.Button
        ExtraExMatlabCodeLabel      matlab.ui.control.Label
        ExtraExMatlabCode           matlab.ui.control.TextArea
        ExtraExDescriptionLabel     matlab.ui.control.Label
        ExtraExDescription          matlab.ui.control.TextArea
        ExtraExListBoxLabel         matlab.ui.control.Label
        ExtraExListBox              matlab.ui.control.ListBox
        SavechangestoXMLfileButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        out % This is the output of function publishFS. This variable will be shared across functions
    end
    
    methods (Access = private)
        
        function fillInputArgs(app,i)
            %app.InpArgsShortDesc.Value=app.out.InpArgs{i,2};
            
            
            if ~isempty(app.out.InpArgs{i,2})
                app.InpArgsShortDesc.Value=sprintf('%s\n',app.out.InpArgs{i,2});
            else
                app.InpArgsShortDesc.Value='';
            end
            
            if ~isempty(app.out.InpArgs{i,3})
                app.InpArgsTypeIndication.Value=app.out.InpArgs{i,3};
            else
                app.InpArgsTypeIndication.Value='';
            end
            if ~isempty(app.out.InpArgs{i,4})
                app.InpArgsLongDesc.Value=sprintf('%s\n',app.out.InpArgs{i,4});
            else
                app.InpArgsLongDesc.Value='';
            end
            
            if ~isempty(app.out.InpArgs{i,5})
                app.InpArgsExample.Value=app.out.InpArgs{i,5};
            else
                app.InpArgsExample.Value='';
            end
            if ~isempty(app.out.InpArgs{i,6})
                app.InpArgsDataType.Value=app.out.InpArgs{i,6};
            else
                app.InpArgsDataType.Value='';
            end
            if strcmp(app.out.InpArgs{i,7},'1')
                app.InpArgsRequiredInput.Value=true;
            else
                app.InpArgsRequiredInput.Value=false;
            end
            
            if ~isempty(app.out.InpArgs{i,8})
                state='on';
                app.InpArgsUITable.Visible=state;
                app.InpArgsListBoxStruct.Visible=state;
                app.InpArgsZoom.Visible=state;
                app.InpArgsStructurevalueLabel.Visible=state;
                app.InpArgsStructuredescriptionLabel.Visible=state;
                
                % if iscell(app.out.InpArgs(i,8))
                TAB=app.out.InpArgs{i,8};
                app.InpArgsUITable.Data=TAB;
                app.InpArgsStructure.Value=true;
                if ~isempty(TAB{1,2})
                    app.InpArgsZoom.Value=TAB{1,2};
                    %try
                    boo=cellfun(@isempty,TAB(:,1));
                    app.InpArgsListBoxStruct.Items=TAB(~boo,1)';
                    %catch
                    %    ddd=1;
                    %end
                else
                    app.InpArgsListBoxStruct.Items={''};
                    app.InpArgsZoom.Value='';
                end
                
                
            else
                %
                app.InpArgsStructure.Value=false;
                state='off';
                app.InpArgsUITable.Data='';
                app.InpArgsListBoxStruct.Items={''};
                app.InpArgsZoom.Value='';
                
                app.InpArgsUITable.Visible=state;
                app.InpArgsListBoxStruct.Visible=state;
                app.InpArgsZoom.Visible=state;
                app.InpArgsStructurevalueLabel.Visible=state;
                app.InpArgsStructuredescriptionLabel.Visible=state;
            end
            
        end


        function fillOptArgs(app,i)
            
            if ~isempty(app.out.OptArgs)
                
                if ~isempty(app.out.OptArgs{i,2})
                    app.OptArgsShortDesc.Value=app.out.OptArgs{i,2};
                else
                    app.OptArgsShortDesc.Value='';
                end
                
                if ~isempty(app.out.OptArgs{i,3})
                    app.OptArgsTypeIndication.Value=app.out.OptArgs{i,3};
                else
                    app.OptArgsTypeIndication.Value='';
                end
                
                if ~isempty(app.out.OptArgs{i,4})
                    app.OptArgsLongDesc.Value=app.out.OptArgs{i,4};
                else
                    app.OptArgsLongDesc.Value='';
                end
                
                if ~isempty(app.out.OptArgs{i,5})
                    app.OptArgsExample.Value=app.out.OptArgs{i,5};
                else
                    app.OptArgsExample.Value='';
                end
                if ~isempty(app.out.OptArgs{i,6})
                    app.OptArgsDataType.Value=app.out.OptArgs{i,6};
                else
                    app.OptArgsDataType.Value='';
                end
                
                if ~isempty(app.out.OptArgs{i,7})
                    app.OptArgsUITable.Data=app.out.OptArgs{i,7};
                    app.OptArgsStructure.Value=true;
                else
                    app.OptArgsUITable.Data='';
                    app.OptArgsStructure.Value=false;
                end
            end
        end
        
        function fillOutArgs(app,i)
            %app.OutArgsShortDesc.Value='';
            %app.OutArgsTypeIndication.Value='';
            %app.OutArgsLongDesc.Value='';
            if ~isempty(app.out.OutArgs{i,2})
                app.OutArgsShortDesc.Value=app.out.OutArgs{i,2};
            else
                app.OutArgsShortDesc.Value='';
            end
            if ~isempty(app.out.OutArgs{i,3})
                app.OutArgsTypeIndication.Value=app.out.OutArgs{i,3};
            else
                app.OutArgsTypeIndication.Value='';
            end
            if ~isempty(app.out.OutArgs{i,4})
                app.OutArgsLongDesc.Value=app.out.OutArgs{i,4};
            else
                app.OutArgsLongDesc.Value='';
            end
            
            if ~isempty(app.out.OutArgs{i,5})
                app.OutArgsStructure.Value=true;
                app.OutArgsUITable.Visible='on';
                app.OutArgsUITable.Data=app.out.OutArgs{i,5};
            else
                app.OutArgsStructure.Value=false;
                app.OutArgsUITable.Visible='off';
                app.OutArgsStructure.Value=false;
            end
        end
        
        function fillExArgs(app,i)
            if ~isempty(app.out.Ex{i,2})
                app.ExDescription.Value=app.out.Ex{i,2};
            else
                app.ExDescription.Value='';
            end
            
            if ~isempty(app.out.Ex{i,3})
                app.ExMatlabCode.Value=app.out.Ex{i,3};
            else
                app.ExMatlabCode.Value='';
            end
            
            if strcmp(app.out.Ex{i,4},'1')
                app.ExtoExecute.Value=true;
            else
                app.ExtoExecute.Value=false;
            end
        end
        
        function fillExtraExArgs(app,i)
            if ~isempty(app.out.ExtraEx{i,2})
                app.ExtraExDescription.Value=app.out.ExtraEx{i,2};
            else
                app.ExtraExDescription.Value='';
            end
            if ~isempty(app.out.ExtraEx{i,3})
                app.ExtraExMatlabCode.Value=app.out.ExtraEx{i,3};
            else
                app.ExtraExMatlabCode.Value='';
            end
            
            if strcmp(app.out.ExtraEx{i,4},'1')
                app.ExtraExtoExecute.Value=true;
            else
                app.ExtraExtoExecute.Value=false;
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Run publishFS
            % app.out=publishFS('tclust.m','evalCode',false,'write2file',false');
            % app.out=publishFS('FSM.m','evalCode',false,'write2file',false');
            % assignin('caller','InputStructForHelpGUI',InputStructForHelpGUI)
            
            [FSDAroot]=fileparts(which('docsearchFS.m'));
            
            fsep=filesep;
            fullpathFileName=[FSDAroot fsep 'helpfiles' fsep 'XML' fsep 'InputStructForHelpGUI'];
            load(fullpathFileName,'InputStructForHelpGUI')
            
            app.out=InputStructForHelpGUI;
            
            % preamble tab
            app.FileName.Value=app.out.titl;
            app.Purpose.Value=app.out.purpose;
            app.Description.Value=app.out.description;
            
            i=1;
            if ~isempty(app.out.InpArgs)
                % TabInpArgs Input Arguments tab
                app.InpArgsListBox.Items=app.out.InpArgs(:,1)';
                app.InpArgsListBox.ItemsData=1:size(app.out.InpArgs,1);
                
                fillInputArgs(app,i)
            end
            
            
            % Optional (FileName/Pairs) Optional Arguments tab
            if ~isempty(app.out.OptArgs)
                app.OptArgsListBox.Items=app.out.OptArgs(:,1)';
                app.OptArgsListBox.ItemsData=1:size(app.out.OptArgs,1);
                fillOptArgs(app,i)
            end
            %
            %             if ~isempty(app.out.OptArgs{i,2})
            %                 app.OptArgsShortDesc.Value=app.out.OptArgs{i,2};
            %             end
            %             if ~isempty(app.out.OptArgs{i,3})
            %                 app.OptArgsTypeIndication.Value=app.out.OptArgs{i,3};
            %             end
            %             if ~isempty(app.out.OptArgs{i,4})
            %                 app.OptArgsLongDesc.Value=app.out.OptArgs{i,4};
            %             end
            %
            %             if ~isempty(app.out.OptArgs{i,5})
            %                 app.OptArgsExample.Value=app.out.OptArgs{i,5};
            %             end
            %             if ~isempty(app.out.OptArgs{i,6})
            %                 app.OptArgsDataType.Value=app.out.OptArgs{i,6};
            %             end
            %             if ~isempty(app.out.OptArgs{i,7})
            %                 app.OptArgsUITable.Data=app.out.OptArgs{i,7};
            %                 app.OptArgsStructure.Value=true;
            %             end
            
            % Output Arguments tab
            if ~isempty(app.out.OutArgs)
                app.OutArgsListBox.Items=app.out.OutArgs(:,1)';
                app.OutArgsListBox.ItemsData=1:size(app.out.OutArgs,1);
                
                if ~isempty(app.out.OutArgs{i,2})
                    app.OutArgsShortDesc.Value=app.out.OutArgs{i,2};
                end
                if ~isempty(app.out.OutArgs{i,3})
                    app.OutArgsTypeIndication.Value=app.out.OutArgs{i,3};
                end
                if ~isempty(app.out.OutArgs{i,4})
                    app.OutArgsLongDesc.Value=app.out.OutArgs{i,4};
                end
                
                if ~isempty(app.out.OutArgs{i,5})
                    app.OutArgsStructure.Value=true;
                    app.OutArgsUITable.Visible='on';
                    app.OutArgsUITable.Data=app.out.OutArgs{i,5};
                else
                    app.OutArgsUITable.Visible='off';
                end
            end
            
            % More About tab
            app.MoreAbout.Value=app.out.MoreAbout;
            app.Acknowledgements.Value=app.out.Acknowledgements;
            if ~ isempty(app.out.References)
                app.References.Value=app.out.References;
            end
            
            app.SeeAlsoListBox.Items=app.out.SeeAlso';
            app.SeeAlsoListBox.ItemsData=1:length(app.out.SeeAlso);
            
            % Examples tab
            app.ExListBox.Items=app.out.Ex(:,1)';
            app.ExListBox.ItemsData=1:size(app.out.Ex,1);
            
            if strcmp(app.out.Ex{i,4},'1')
                app.ExtoExecute.Value=true;
            end
            if ~isempty(app.out.Ex{i,2})
                % ~(size(app.out.Ex{i,2},1)==1 && isempty(strtrim(char(app.out.Ex{i,2}))))
                app.ExDescription.Value=app.out.Ex{i,2};
            end
            if  ~isempty(app.out.Ex{i,3})
                % ~(size(app.out.Ex{i,3},1)==1 && isempty(strtrim(char(app.out.Ex{i,3}))))
                % iscell(app.out.Ex{i,3}) && ~all(cellfun(@isempty,app.out.Ex{i,3}))
                
                app.ExMatlabCode.Value=app.out.Ex{i,3};
            end
            
            % ExtraExamples tab
            if ~isempty(app.out.ExtraEx)
                app.ExtraExListBox.Items=app.out.ExtraEx(:,1)';
                app.ExtraExListBox.ItemsData=1:size(app.out.ExtraEx,1);
                
                if strcmp(app.out.ExtraEx{i,4},'1')
                    app.ExtraExtoExecute.Value=true;
                end
                if ~isempty(app.out.ExtraEx{i,2})
                    app.ExtraExDescription.Value=app.out.ExtraEx{i,2};
                end
                if ~isempty(app.out.ExtraEx{i,3})
                    app.ExtraExMatlabCode.Value=app.out.ExtraEx{i,3};
                end
            end
        end

        % Value changed function: InpArgsListBox
        function InpArgsListBoxChanged(app, event)
            value = event.PreviousValue;
            if isempty(value)
                value=1;
            end
            % make sure there is nothing pending
            drawnow;
            % Use previous value to store modifications insice the textboxes ExMatlabCode and ExDescription
            app.out.InpArgs{value,3}=sprintf('%s\n',app.InpArgsTypeIndication.Value{:});
            app.out.InpArgs{value,2}=sprintf('%s\n',app.InpArgsShortDesc.Value{:});
            app.out.InpArgs{value,4}=sprintf('%s\n',app.InpArgsLongDesc.Value{:});
            if iscell(app.InpArgsExample.Value)
                app.out.InpArgs{value,5}=sprintf('%s\n',app.InpArgsExample.Value{:});
            else
                app.out.InpArgs{value,5}=sprintf('%s\n',app.InpArgsExample.Value);
            end
            if iscell(app.InpArgsDataType.Value)
                app.out.InpArgs{value,6}=sprintf('%s\n',app.InpArgsDataType.Value{:});
            else
                app.out.InpArgs{value,6}=sprintf('%s\n',app.InpArgsDataType.Value);
            end
            
            % Store value of textbox app.InpArgsZoom if it is not empty
            TAB=app.out.InpArgs{value,8};
            if ~cellfun(@isempty,app.InpArgsZoom.Value)
                valuestr=app.InpArgsListBoxStruct.Value;
                seq=1:length(app.InpArgsListBoxStruct.Items);
                valuestruct=seq(strcmp(valuestr,app.InpArgsListBoxStruct.Items));
                TAB{valuestruct,2}=sprintf('%s\n',app.InpArgsZoom.Value{:});
                app.out.InpArgs{value,8}=TAB;
            else
                app.out.InpArgs{value,8}=app.InpArgsUITable.Data;
            end


            if app.InpArgsRequiredInput.Value
                app.out.InpArgs{value,7}='1';
            else
                app.out.InpArgs{value,7}='0';
            end
            
            % Once modifications have been stored use, current value of the listbox to show the corresponding items assocaited to new selection
            value = app.InpArgsListBox.Value;
            fillInputArgs(app,value)
        end

        % Button pushed function: InpArgsDel
        function InpArgsDelPushed(app, event)
            % Selected argument will be deleted from the listbox
            app.InpArgsListBox.Items(app.InpArgsListBox.Value)=[];
            
            % Selected argument will be deleted from the original cell
            if ~isempty(app.InpArgsListBox.Value)
                app.out.InpArgs(app.InpArgsListBox.Value,:)=[];
            else
                %app.out.InpArgs='';
                app.out.InpArgs=cell(1,8);
            end
            fillInputArgs(app,1)
        end

        % Button pushed function: InpArgsAdd
        function InpArgsAddPushed(app, event)
            
            % A new argument will be added to the listBox
            newValue= inputdlg('Enter new input argument',...
                'New item', [1 50]);
            if ~isempty(newValue)
                if all(cellfun(@isempty,app.out.InpArgs))
                    %if ~isempty(app.out.InpArgs)
                    app.InpArgsListBox.Items=newValue;
                    app.InpArgsListBox.ItemsData=1;
                    app.out.InpArgs(1,1)=newValue;
                else
                    app.out.InpArgs(end+1,1:end)={''};
                    app.InpArgsListBox.Items=[app.InpArgsListBox.Items newValue];
                    app.InpArgsListBox.ItemsData=1:length(app.InpArgsListBox.Items);
                    app.out.InpArgs(end,1)=newValue;
                    app.out.InpArgsStructure.Value=0;
                    app.out.InpArgs{end,end}=[];
                    
                    % Store previous selection in the listbox
                    ev=struct;
                    ev.PreviousValue=app.InpArgsListBox.Value;
                    
                    scroll(app.InpArgsListBox,'bottom');
                    app.InpArgsListBox.Value=length(app.InpArgsListBox.Items);
                    % call routine InpArgsListBoxChanged because all the correlated elements must be deleted
                    
                    % Store current selection in the listbox
                    ev.Value=app.InpArgsListBox.Value;
                    % Call routine InpArgsListBoxChanged to store modification associated to previous values and include all empty items associated to value which has just beene added
                    InpArgsListBoxChanged(app, ev)
                end
                %else
                %    app.out.InpArgs=cell(1,8);
                %end
            end
        end

        % Value changed function: OptArgsListBox
        function OptArgsListBoxChanged(app, event)
            value = event.PreviousValue;
            
            if isempty(value)
                value=1;
            end
            
            % make sure there is nothing pending
            drawnow;
            % Use previous value to store modifications insice the textboxes ExMatlabCode and ExDescription
            app.out.OptArgs{value,3}=sprintf('%s\n',app.OptArgsTypeIndication.Value{:});
            app.out.OptArgs{value,2}=sprintf('%s\n',app.OptArgsShortDesc.Value{:});
            app.out.OptArgs{value,4}=sprintf('%s\n',app.OptArgsLongDesc.Value{:});
            if iscell(app.OptArgsExample.Value)
                app.out.OptArgs{value,5}=sprintf('%s\n',app.OptArgsExample.Value{:});
            else
                app.out.OptArgs{value,5}=sprintf('%s\n',app.OptArgsExample.Value);
            end
            if iscell(app.OptArgsDataType.Value)
                app.out.OptArgs{value,6}=sprintf('%s\n',app.OptArgsDataType.Value{:});
            else
                app.out.OptArgs{value,6}=sprintf('%s\n',app.OptArgsDataType.Value);
            end
            app.out.OptArgs{value,7}=app.OptArgsUITable.Data;
            
            % Once modifications have been stored use, current value of the listbox to show the corresponding items assocaited to new selection
            value = app.OptArgsListBox.Value;
            
            %disp(value)
            %ItemsData = app.InpArgsListBox.ItemsData;
            %disp(ItemsData)
            fillOptArgs(app,value)
        end

        % Button pushed function: OptArgsAdd
        function OptArgsAddPushed(app, event)
            % A new argument will be added to the listBox
            newValue= inputdlg('Enter new optional input argument',...
                'New item', [1 50]);
            if ~isempty(newValue)
                
                if all(cellfun(@isempty,app.out.OptArgs))
                    
                    app.OptArgsListBox.Items=newValue;
                    app.OptArgsListBox.ItemsData=1;
                    app.out.OptArgs(1,1)=newValue;
                else
                    
                    app.out.OptArgs(end+1,1:end)={''};
                    app.OptArgsListBox.Items=[app.OptArgsListBox.Items newValue];
                    app.OptArgsListBox.ItemsData=1:length(app.OptArgsListBox.Items);
                    app.out.OptArgs(end,1)=newValue;
                    
                    
                    app.out.OptArgs{end,end}=[];
                    
                    % Store previous selection in the listbox
                    ev=struct;
                    ev.PreviousValue=app.OptArgsListBox.Value;
                    
                    scroll(app.OptArgsListBox,'bottom');
                    app.OptArgsListBox.Value=length(app.OptArgsListBox.Items);
                    % call routine InpArgsListBoxChanged because all the correlated elements must be deleted
                    
                    % Store current selection in the listbox
                    ev.Value=app.OptArgsListBox.Value;
                    % Call routine OptArgsListBoxChanged to store modification associated to previous values and include all empty items associated to value which has just beene added
                    OptArgsListBoxChanged(app, ev)
                end
            end
        end

        % Button pushed function: OptArgsDel
        function OptArgsDelPushed(app, event)
            
            % Selected argument will be deleted from the listbox
            app.OptArgsListBox.Items(app.OptArgsListBox.Value)=[];
            
            % Selected argument will be deleted from the original cell
            if ~isempty(app.OptArgsListBox.Value)
                app.out.OptArgs(app.OptArgsListBox.Value,:)=[];
            else
                app.out.OptArgs=cell(1,7);
            end
            fillOptArgs(app,1)
        end

        % Button pushed function: OutArgsAdd
        function OutArgsAddPushed(app, event)
            
            
            %             prompt = {'Enter name of new output argument:','If it is a structure write struct:'};
            %             dlg_title = 'FileName of new output argument (or varargout)';
            %             num_lines = 1;
            %             defaultans = {'','struct'};
            %             answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
            %             newValue=answer(1);
            %
            %             app.out.OutArgs(end+1,1:end)={''};
            %             app.OutArgsListBox.Items=[app.OutArgsListBox.Items newValue];
            %             app.OutArgsListBox.ItemsData=1:length(app.OutArgsListBox.Items);
            %             app.out.OutArgs(end,1)=newValue;
            %             if strcmp(answer(end),'struct')
            %                 app.out.OutArgs(end,4)=cell(1,1);
            %             else
            %                 app.out.OutArgs{end,2}='xxx';
            %             end
            
            % A new argument will be added to the listBox
            newValue= inputdlg('Enter new output argument',...
                'New item', [1 50]);
            if ~isempty(newValue)
                app.out.OutArgs(end+1,1:end)={''};
                app.OutArgsListBox.Items=[app.OutArgsListBox.Items newValue];
                app.OutArgsListBox.ItemsData=1:length(app.OutArgsListBox.Items);
                app.out.OutArgs(end,1)=newValue;
                
                app.out.OutArgs{end,end}=[];
                
                % Store previous selection in the listbox
                ev=struct;
                ev.PreviousValue=app.OutArgsListBox.Value;
                
                scroll(app.OutArgsListBox,'bottom');
                app.OutArgsListBox.Value=length(app.OutArgsListBox.Items);
                
                % Store current selection in the listbox
                ev.Value=app.OutArgsListBox.Value;
                % Call routine OutArgsListBoxChanged to store modification associated to previous values and include all empty items associated to value which has just beene added
                OutArgsListBoxChanged(app, ev)
            end
        end

        % Value changed function: OutArgsListBox
        function OutArgsListBoxChanged(app, event)
            value = event.PreviousValue;
            if isempty(value)
                value=1;
            end
            % make sure there is nothing pending
            drawnow;
            % Use previous value to store modifications insice the textboxes ExMatlabCode and ExDescription
            app.out.OutArgs{value,3}=sprintf('%s\n',app.OutArgsTypeIndication.Value{:});
            app.out.OutArgs{value,2}=sprintf('%s\n',app.OutArgsShortDesc.Value{:});
            app.out.OutArgs{value,4}=sprintf('%s\n',app.OutArgsLongDesc.Value{:});
            
            
            if app.OutArgsStructure.Value == true
                app.out.OutArgs{value,5}=app.OutArgsUITable.Data;
            else
                app.out.OutArgs{value,5}='';
            end
            
            
            % Once modifications have been stored, use current value of the listbox to show the corresponding items assocaited to new selection
            
            value = app.OutArgsListBox.Value;
            fillOutArgs(app,value)
        end

        % Button pushed function: OutArgsDel
        function OutArgsDelPushed(app, event)
            
            
            % Selected argument will be deleted from the listbox
            app.OutArgsListBox.Items(app.OutArgsListBox.Value)=[];
            
            % Selected argument will be deleted from the original cell
            if ~isempty(app.OutArgsListBox.Value)
                app.out.OutArgs(app.OutArgsListBox.Value,:)=[];
            else
                app.out.OutArgs=cell(1,5);
            end
            fillOutArgs(app,1)
            
        end

        % Button pushed function: SeeAlsoAdd
        function SeeAlsoAddPushed(app, event)
            newValue= inputdlg('Enter new See Also argument',...
                'New item', [1 50]);
            app.out.SeeAlso(end+1,1:end)={''};
            app.SeeAlsoListBox.Items=[app.SeeAlsoListBox.Items newValue];
            app.SeeAlsoListBox.ItemsData=1:length(app.SeeAlsoListBox.Items);
            app.out.SeeAlso(end,1)=newValue;
            
        end

        % Button pushed function: SeeAlsoDel
        function SeeAlsoDelPushed(app, event)
            value = app.SeeAlsoListBox.Value;
            % Selected argument will be deleted
            app.SeeAlsoListBox.Items(value)=[];
            % and from the original cell
            app.out.SeeAlso(value)=[];
        end

        % Value changed function: ExListBox
        function ExListBoxChanged(app, event)
            value = event.PreviousValue;
            % make sure there is nothing pending
            drawnow;
            % Use previous value to store modifications insice the textboxes ExMatlabCode and ExDescription
            %             app.out.Ex{value,3}=sprintf('%s\n',app.ExMatlabCode.Value{:});
            %             app.out.Ex{value,2}=sprintf('%s\n',app.ExDescription.Value{:});
            if ~any(cellfun(@isempty,app.ExDescription.Value))
                app.out.Ex{value,2}=app.ExDescription.Value;
            end
            if ~any(cellfun(@isempty,app.ExMatlabCode.Value))
                app.out.Ex{value,3}=app.ExMatlabCode.Value;
            end
            
            % Once modifications have been stored use, current value of the listbox to show the corresponding items assocaited to new selection
            value = event.Value;
            fillExArgs(app,value)
        end

        % Button pushed function: ExAdd
        function ExAddPushed(app, event)
            % A new argument will be added to the listBox
            newValue= inputdlg('Enter new example ',...
                'New item', [1 50]);
            if ~isempty(newValue)
                app.out.Ex(end+1,1:end)={''};
                app.ExListBox.Items=[app.ExListBox.Items newValue];
                app.ExListBox.ItemsData=1:length(app.ExListBox.Items);
                app.out.Ex(end,1)=newValue;
                
                % Store previous selection in the listbox
                ev=struct;
                ev.PreviousValue=app.ExListBox.Value;
                
                scroll(app.ExListBox,'bottom');
                app.ExListBox.Value=length(app.ExListBox.Items);
                
                % Store current selection in the listbox
                ev.Value=app.ExListBox.Value;
                % Call routine ExListBoxChanged to store modification associated to previous values and include all empty items associated to value which has just beene added
                ExListBoxChanged(app, ev)
            end
            
        end

        % Button pushed function: ExeDel
        function ExeDelPushed(app, event)
            value = app.ExListBox.Value;
            % Selected argument will be deleted
            app.ExListBox.Items(value)=[];
            % and from the original cell
            app.out.Ex(value,:)=[];
            % call routine ExListBoxChanged because all the correlated elements must be deleted
            ExListBoxChanged(app, event)
        end

        % Value changed function: ExtoExecute
        function ExtoExecuteValueChanged(app, event)
            value = app.ExtoExecute.Value;
            if value
                app.out.Ex{app.ExListBox.Value,4}='1';
            else
                app.out.Ex{app.ExListBox.Value,4}='0';
            end
        end

        % Value changed function: InpArgsRequiredInput
        function InpArgsRequiredInputValueChanged(app, event)
            value = app.InpArgsRequiredInput.Value;
            
            if value
                app.out.InpArgs{app.InpArgsListBox.Value,7}='1';
            else
                app.out.InpArgs{app.InpArgsListBox.Value,7}='0';
            end
        end

        % Value changed function: OutArgsStructure
        function OutArgsStructureChanged(app, event)
            value = app.OutArgsStructure.Value;
            drawnow;
            if value
                choice = questdlg('A new UItable will be shown. Are you sure?', ...
                   	'Confirm addition', ...
                   	'Yes','No');
                % Handle response
                if strcmp(choice,'Yes')
                    
                    app.out.OutArgs{app.OutArgsListBox.Value,5}=cell(10,2);
                end                
            else
                % Just obtain a confirmation from the user
                choice = questdlg('All the elements inside the UItable for this output arguemnt will be deleted. Are you sure?', ...
                   	'Delete the associated UItable', ...
                   	'Yes','No');
                % Handle response
                if strcmp(choice,'Yes')
                    app.out.OutArgs{app.OutArgsListBox.Value,5}='';
                end
            end
            
            fillOutArgs(app,app.OutArgsListBox.Value)
        end

        % Button pushed function: ExtraExAdd
        function ExtraExAddPushed(app, event)
            % A new argument will be added to the listBox
            newValue= inputdlg('Enter new extra example ',...
                'New item', [1 50]);
            if ~isempty(newValue)
                app.out.ExtraEx(end+1,1:end)={''};
                app.ExtraExListBox.Items=[app.ExtraExListBox.Items newValue];
                app.ExtraExListBox.ItemsData=1:length(app.ExtraExListBox.Items);
                app.out.ExtraEx(end,1)=newValue;
                
                
                % Store previous selection in the listbox
                ev=struct;
                ev.PreviousValue=app.ExtraExListBox.Value;
                
                scroll(app.ExtraExListBox,'bottom');
                app.ExtraExListBox.Value=length(app.ExtraExListBox.Items);
                
                % Store current selection in the listbox
                ev.Value=app.ExtraExListBox.Value;
                % Call routine ExtraExListBoxChanged to store modification associated to previous values and include all empty items associated to value which has just beene added
                ExtraExListBoxChanged(app, ev)
            end
            
        end

        % Value changed function: ExtraExListBox
        function ExtraExListBoxChanged(app, event)
            
            value = event.PreviousValue;
            % make sure there is nothing pending
            drawnow;
            % Use previous value to store modifications insice the textboxes ExMatlabCode and ExDescription
            %            app.out.ExtraEx{value,2}=sprintf('%s\n',app.ExtraExDescription.Value{:});
            %            app.out.ExtraEx{value,3}=sprintf('%s\n',app.ExtraExMatlabCode.Value{:});
            if ~any(cellfun(@isempty,app.ExtraExDescription.Value))
                app.out.ExtraEx{value,2}=app.ExtraExDescription.Value;
            end
            if ~any(cellfun(@isempty,app.ExtraExMatlabCode.Value))
                app.out.ExtraEx{value,3}=app.ExtraExMatlabCode.Value;
            end
            % Once modifications have been stored use, current value of the listbox to show the corresponding items assocaited to new selection
            value = event.Value;
            % value = app.ExtraExListBox.Value;
            fillExtraExArgs(app,value)
        end

        % Button pushed function: ExtraExDel
        function ExtraExDelPushed(app, event)
            value = app.ExtraExListBox.Value;
            % Selected argument will be deleted
            app.ExtraExListBox.Items(value)=[];
            % and from the original cell
            app.out.ExtraEx(value,:)=[];
            % call routine ExListBoxChanged because all the correlated elements must be deleted
            ExtraExListBoxChanged(app, event)
        end

        % Value changed function: ExtraExtoExecute
        function ExtraExtoExecuteValueChanged(app, event)
            
            value = app.ExtraExtoExecute.Value;
            if value
                app.out.ExtraEx{app.ExtraExListBox.Value,4}='1';
            else
                app.out.ExtraEx{app.ExtraExListBox.Value,4}='0';
            end
        end

        % Button pushed function: SavechangestoXMLfileButton
        function SavechangestoXML(app, event)
            %           fsep=filesep;
            %fullpathFileName=[FSDAroot fsep 'helpfiles' fsep 'XML' fsep 'InputStructForHelpGUI'];
            %save(fullpathFileName,'InputStructForHelpGUI')
            app.out.titl=app.FileName.Value;
            app.out.purpose=app.Purpose.Value;
            app.out.description=app.Description.Value;
            app.out.MoreAbout=app.MoreAbout.Value;
            app.out.Acknowledgements=app.Acknowledgements.Value;
            app.out.References= app.References.Value;
            
            
            % Store what is in tab Input Arguments
            if ~isempty(app.out.InpArgs)
                ev=struct;
                i=app.InpArgsListBox.Value;
                ev.PreviousValue=i;
                ev.Value=i;
                InpArgsListBoxChanged(app,ev)
            end
            
            % Store what is in tab FileName/Value Opt. Input arguments
            if ~isempty(app.out.OptArgs)
                i=app.OptArgsListBox.Value;
                ev.PreviousValue=i;
                ev.Value=i;
                OptArgsListBoxChanged(app,ev)
            end
            
            % Store what is in tab  output arguments
            if ~isempty(app.out.OutArgs)
                i=app.OutArgsListBox.Value;
                ev.PreviousValue=i;
                ev.Value=i;
                OutArgsListBoxChanged(app,ev)
            end
            
            % Store what is in tab Examples
            if ~isempty(app.out.Ex)
                i=app.ExListBox.Value;
                ev.PreviousValue=i;
                ev.Value=i;
                ExListBoxChanged(app,ev)
                
                % if ~max(cellfun(@isempty,app.ExDescription.Value))==0 && ~isempty(strtrim({app.ExDescription.Value}))
                %             if iscell(app.ExDescription.Value) && ~all(cellfun(@isempty,app.ExDescription.Value))
                %
                %                     app.out.Ex{i,2}=app.ExDescription.Value;
                %
                %             else
                if ~any(cellfun(@isempty,app.ExDescription.Value))
                    % isempty(strtrim(app.ExDescription.Value))
                    % app.out.Ex{i,2}=sprintf('%s\n',app.ExDescription.Value{:});
                    app.out.Ex{i,2}=app.ExDescription.Value;
                end
                % end
                
                %              if iscell(app.ExMatlabCode.Value) && ~all(cellfun(@isempty,app.ExMatlabCode.Value))
                %                     app.out.Ex{i,3}=app.ExMatlabCode.Value;
                %
                %             else
                if ~any(cellfun(@isempty,app.ExMatlabCode.Value))
                    % ~isempty(strtrim(app.ExMatlabCode.Value))
                    %app.out.Ex{i,3}=sprintf('%s\n',app.ExMatlabCode.Value{:});
                    app.out.Ex{i,3}=app.ExMatlabCode.Value;
                end
                %             end
                
                
                %              if ~isempty(app.ExMatlabCode.Value)
                %             app.out.Ex{i,3}=app.ExMatlabCode.Value;
                %              end
                
                if app.ExtoExecute.Value
                    app.out.Ex{i,4}='1';
                else
                    app.out.Ex{i,4}='0';
                end
            end
            
            
            % Store what is in tab Extra Examples
            if ~isempty(app.out.ExtraEx)
                
                i=app.ExtraExListBox.Value;
                ev.PreviousValue=i;
                ev.Value=i;
                
                ExtraExListBoxChanged(app,ev)
                
                if  ~any(cellfun(@isempty,app.ExtraExDescription.Value))
                    % app.out.ExtraEx{i,2}=sprintf('%s\n',app.ExtraExDescription.Value{:});
                    app.out.ExtraEx{i,2}=app.ExtraExDescription.Value;
                end
                
                if  ~any(cellfun(@isempty,app.ExtraExMatlabCode.Value))
                    % app.out.ExtraEx{i,3}=sprintf('%s\n',app.ExtraExMatlabCode.Value{:});
                    app.out.ExtraEx{i,3}=app.ExtraExMatlabCode.Value;
                end
                
                if app.ExtraExtoExecute.Value
                    app.out.ExtraEx{i,4}='1';
                else
                    app.out.ExtraEx{i,4}='0';
                end
            end
            
            % write XML file
            xmlwriteFS(app.out);
            disp('Document Saved to XML')
            tmp=app.out;
            save('outtowritetmp','tmp')
            mwriteFS(app.out)
            [FSDAroot]=fileparts(which('docsearchFS.m'));
            disp(['.m file written to ' FSDAroot filesep 'helpfiles' filesep 'XML' filesep 'tmpHELP.m'])
                   %     datetime('now')
        end

        % Value changed function: InpArgsStructure
        function InpArgsStructureChanged(app, event)
            value = app.InpArgsStructure.Value;
            if value
                if isempty(app.out.InpArgs{app.InpArgsListBox.Value,8})
                    app.out.InpArgs{app.InpArgsListBox.Value,8}=cell(1,2);
                end
            else
                app.InpArgsUITable.Data='';
                app.out.InpArgs{app.InpArgsListBox.Value,8}='';
            end
            fillInputArgs(app,app.InpArgsListBox.Value)
        end

        % Cell edit callback: InpArgsUITable
        function InpArgsUITableCellEdit(app, event)
            %  app.out.InpArgs{app.InpArgsListBox.Value,8}=app.InpArgsUITable.Data;
            % check what is the number of non empty rows of the UITable
            UITab=app.InpArgsUITable.Data;
            
            Tab=app.InpArgsUITable.Data(:,1);
            boo=cellfun(@isempty,Tab);
            UITab=UITab(~boo,:);
            UITab=[UITab; cell(1,2)];
            Tab=Tab(~boo);
            % app.out.InpArgs{app.InpArgsListBox.Value,8}
            app.InpArgsListBoxStruct.Items=Tab';
            %app.InpArgsListBoxStruct.ItemsData=1:length(Tab);
            % app.InpArgsListBoxStruct.ItemsData=1:length(app.InpArgsListBoxStruct.Items);
            app.out.InpArgs{app.InpArgsListBox.Value,8}=UITab;
            app.InpArgsUITable.Data=UITab;
            index=event.Indices(1);
            % Change the corresponding description (inside InpArgsZoom)
            if isempty(UITab{index,2})
                app.InpArgsZoom.Value='';
            else
                app.InpArgsZoom.Value=UITab{index,2};
            end
            % Change the corresponding selection inside the listbox (inside  InpArgsListBoxStruct)
            drawnow;
            % try
            scroll(app.InpArgsListBoxStruct,app.InpArgsListBoxStruct.Items{index})
            app.InpArgsListBoxStruct.Value=app.InpArgsListBoxStruct.Items{index};
            %  catch
            %                 scroll(app.InpArgsListBoxStruct,'bottom')
            %                 app.InpArgsListBoxStruct.Value=app.InpArgsListBoxStruct.Items{end};
            %                 index=length(app.InpArgsListBoxStruct.Items);
            %
            %ddd=1;
            %   end
            
        end

        % Cell edit callback: OptArgsUITable
        function OptArgsUITableCellEdit(app, event)
            app.out.OptArgs{app.OptArgsListBox.Value,7}=app.OptArgsUITable.Data;
        end

        % Value changed function: OptArgsStructure
        function OptArgsStructureChanged(app, event)
            value = app.OptArgsStructure.Value;
            if value
                if isempty(app.out.OptArgs{app.OptArgsListBox.Value,7})
                    app.out.OptArgs{app.OptArgsListBox.Value,7}=cell(10,2);
                end
                
            end
            fillOptArgs(app,app.OptArgsListBox.Value)
        end

        % Cell selection callback: InpArgsUITable
        function InpArgsUITableCellSelection(app, event)
            
            % First of all store in the table what has been included inside in the textbox description (XXXZoom.Value)
            drawnow
            valuestr = app.InpArgsListBoxStruct.Value;
            seq=1:length(app.InpArgsListBoxStruct.Items);
            value=seq(strcmp(valuestr,app.InpArgsListBoxStruct.Items));
            TAB=app.out.InpArgs{app.InpArgsListBox.Value,8};
            
            if iscell(app.InpArgsZoom.Value)
                drawnow
                spri=sprintf('%s\n',app.InpArgsZoom.Value{:});
                drawnow
                TAB{value,2}=spri;
                if isempty(strtrim(TAB{value,2}))
                    pause(0.1)
                    TAB{value,2}=sprintf('%s\n',app.InpArgsZoom.Value{:});
                end
            end
            
            app.out.InpArgs{app.InpArgsListBox.Value,8}=TAB;
            if ~isempty(TAB{end,1}) && ~isempty(TAB{end,2})
                app.InpArgsUITable.Data=[TAB; cell(1,2)];
            else
                app.InpArgsUITable.Data=TAB;
            end
            
            % Now show the proper values in the listboxstruct.
            % When the user changes the row in the table the selection and the entries in the listbox
            % are automatically updated
            drawnow;
            try
                index=event.Indices(1);
                scroll(app.InpArgsListBoxStruct,app.InpArgsListBoxStruct.Items{index})
                app.InpArgsListBoxStruct.Value=app.InpArgsListBoxStruct.Items{index};
            catch
                scroll(app.InpArgsListBoxStruct,'bottom')
                app.InpArgsListBoxStruct.Value=app.InpArgsListBoxStruct.Items{end};
                index=length(app.InpArgsListBoxStruct.Items);
                
            end
            % scroll(app.InpArgsListBoxStruct,event.Indices(1));
            Zoom=app.out.InpArgs{app.InpArgsListBox.Value,8};
            if ~cellfun(@isempty,Zoom(index,2))
                app.InpArgsZoom.Value=Zoom{index,2};
            end
        end

        % Value changed function: InpArgsListBoxStruct
        function InpArgsListBoxStructChanged(app, event)
            
            %  value = app.InpArgsZoom.Value{:};
            % app.InpArgsUITable.Data{app.out.event.Indices(1),2}=value;
            
            valuestr = event.PreviousValue;
            
            % itemsdata property of listbox has ot been defined therefore it is necessary to convert string  into num


            if isempty(valuestr)
                value=1;
            else
                seq=1:length(event.Source.Items);
                value=seq(strcmp(valuestr,event.Source.Items));
            end
            % make sure there is nothing pending
            drawnow;
            % Use previous value to store modifications inside the UItable
            TAB=app.out.InpArgs{app.InpArgsListBox.Value,8};
            if iscell(app.InpArgsZoom.Value)
                TAB{value,2}=sprintf('%s\n',app.InpArgsZoom.Value{:});
            end
            app.out.InpArgs{app.InpArgsListBox.Value,8}=TAB;
            app.InpArgsUITable.Data=TAB;
            
            
            valuestr = app.InpArgsListBoxStruct.Value;
            seq=1:length(event.Source.Items);
            value=seq(strcmp(valuestr,event.Source.Items));
            
            % fillInputArgsStruct(app,value)
            
            % TAB= app.InpArgsUITable.Data;
            
            if ~isempty(TAB{value,2})
                app.InpArgsZoom.Value=TAB{value,2};
            else
                app.InpArgsZoom.Value='';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, false)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabPreamble = uitab(app.TabGroup);
            app.TabPreamble.Title = 'Preamble';
            app.TabPreamble.Units = 'pixels';
            app.FileNameLabel = uilabel(app.TabPreamble);
            app.FileNameLabel.Text = 'FileName';
            app.FileName = uieditfield(app.TabPreamble, 'text');
            app.PurposeLabel = uilabel(app.TabPreamble);
            app.PurposeLabel.Text = 'Purpose';
            app.Purpose = uitextarea(app.TabPreamble);
            app.DescriptionLabel = uilabel(app.TabPreamble);
            app.DescriptionLabel.Text = 'Description';
            app.Description = uitextarea(app.TabPreamble);
            app.FileNameHelp = uilabel(app.TabPreamble);
            app.FileNameHelp.Text = 'Name of the file';
            app.PurposeHelp = uilabel(app.TabPreamble);
            app.PurposeHelp.Text = 'Purpose contains the second line of the file';
            app.DescriptionHelp = uilabel(app.TabPreamble);
            app.DescriptionHelp.Text = 'Description contains the text to include before "Required Input Arguments"';
            app.TabInpArgs = uitab(app.TabGroup);
            app.TabInpArgs.Title = 'Input Arguments';
            app.TabInpArgs.Units = 'pixels';
            app.InpArgsListBoxLabel = uilabel(app.TabInpArgs);
            app.InpArgsListBoxLabel.Text = {'List of Required '; 'Input arguments'};
            app.InpArgsListBox = uilistbox(app.TabInpArgs);
            app.InpArgsListBox.ValueChangedFcn = createCallbackFcn(app, @InpArgsListBoxChanged, true);
            app.InpArgsShortDescLabel = uilabel(app.TabInpArgs);
            app.InpArgsShortDescLabel.Text = 'Short Description';
            app.InpArgsShortDesc = uitextarea(app.TabInpArgs);
            app.InpArgsLongDescLabel = uilabel(app.TabInpArgs);
            app.InpArgsLongDescLabel.Text = 'Long Description';
            app.InpArgsLongDesc = uitextarea(app.TabInpArgs);
            app.InpArgsRequiredInputLabel = uilabel(app.TabInpArgs);
            app.InpArgsRequiredInputLabel.Text = {'Check this box if the argument'; 'is required'};
            app.InpArgsTypeIndicationLabel = uilabel(app.TabInpArgs);
            app.InpArgsTypeIndicationLabel.Text = 'Type indication';
            app.InpArgsTypeIndication = uitextarea(app.TabInpArgs);
            app.InpArgsTypeIndicationHelp = uilabel(app.TabInpArgs);
            app.InpArgsTypeIndicationHelp.Text = {'Type indication extracts what is contained before the fist and second full stop '; '(for example: scalar, vector of size 2, ...) '};
            app.InpArgsLongDescHelp = uilabel(app.TabInpArgs);
            app.InpArgsLongDescHelp.Text = {'Long Description extracts what is contained after the second full stop '; '(this is the long description of the required input argument) '};
            app.InpArgsRequiredInput = uicheckbox(app.TabInpArgs);
            app.InpArgsRequiredInput.ValueChangedFcn = createCallbackFcn(app, @InpArgsRequiredInputValueChanged, true);
            app.InpArgsRequiredInput.Text = 'Required Input Arguement';
            app.InpArgsShortDescHelp = uilabel(app.TabInpArgs);
            app.InpArgsShortDescHelp.Text = 'Short description extracts what is contained before the fist full stop (This is a syntethic description)';
            app.InpArgsExampleLabel = uilabel(app.TabInpArgs);
            app.InpArgsExampleLabel.Text = 'Example';
            app.InpArgsExample = uieditfield(app.TabInpArgs, 'text');
            app.InpArgsAdd = uibutton(app.TabInpArgs, 'push');
            app.InpArgsAdd.ButtonPushedFcn = createCallbackFcn(app, @InpArgsAddPushed, true);
            app.InpArgsAdd.Text = 'Add Input Argument';
            app.InpArgsDel = uibutton(app.TabInpArgs, 'push');
            app.InpArgsDel.ButtonPushedFcn = createCallbackFcn(app, @InpArgsDelPushed, true);
            app.InpArgsDel.Text = {'Delete Selected '; 'Input Argument'};
            app.inpArgsDataTypeLabel = uilabel(app.TabInpArgs);
            app.inpArgsDataTypeLabel.Text = 'DataType';
            app.InpArgsDataType = uieditfield(app.TabInpArgs, 'text');
            app.InpArgsUITable = uitable(app.TabInpArgs);
            app.InpArgsUITable.ColumnName = {'Value'; 'Description'};
            app.InpArgsUITable.RowName = {};
            app.InpArgsUITable.ColumnEditable = [true false];
            app.InpArgsUITable.CellEditCallback = createCallbackFcn(app, @InpArgsUITableCellEdit, true);
            app.InpArgsUITable.CellSelectionCallback = createCallbackFcn(app, @InpArgsUITableCellSelection, true);
            app.InpArgsStructure = uicheckbox(app.TabInpArgs);
            app.InpArgsStructure.ValueChangedFcn = createCallbackFcn(app, @InpArgsStructureChanged, true);
            app.InpArgsStructure.Text = 'Struct';
            app.InpArgsStructureHelp = uilabel(app.TabInpArgs);
            app.InpArgsStructureHelp.Text = {'Check this box if the argument'; 'is a structure'};
            app.InpArgsZoom = uitextarea(app.TabInpArgs);
            app.InpArgsStructurevalueLabel = uilabel(app.TabInpArgs);
            app.InpArgsStructurevalueLabel.Text = 'Structure value';
            app.InpArgsListBoxStruct = uilistbox(app.TabInpArgs);
            app.InpArgsListBoxStruct.ValueChangedFcn = createCallbackFcn(app, @InpArgsListBoxStructChanged, true);
            app.InpArgsStructuredescriptionLabel = uilabel(app.TabInpArgs);
            app.InpArgsStructuredescriptionLabel.Text = 'Structure description';
            app.TabOptArgs = uitab(app.TabGroup);
            app.TabOptArgs.Title = 'Name/Value Opt. Input arguments';
            app.TabOptArgs.Units = 'pixels';
            app.OptArgsTypeIndicationHelp = uilabel(app.TabOptArgs);
            app.OptArgsTypeIndicationHelp.Text = {'Type indication extracts what is contained before the fist and second full stop '; '(for example: scalar, vector of size 2, ...) '};
            app.OptArgsLongDescHelp = uilabel(app.TabOptArgs);
            app.OptArgsLongDescHelp.Text = 'Long Description extracts what is contained after the second full stop';
            app.OptArgsShortDescHelp = uilabel(app.TabOptArgs);
            app.OptArgsShortDescHelp.Text = {'Short description extracts what is contained before the fist full stop '; 'This is a syntethic description ot the input argument'};
            app.OptArgsAdd = uibutton(app.TabOptArgs, 'push');
            app.OptArgsAdd.ButtonPushedFcn = createCallbackFcn(app, @OptArgsAddPushed, true);
            app.OptArgsAdd.Text = 'Add Optional Input Argument';
            app.OptArgsDel = uibutton(app.TabOptArgs, 'push');
            app.OptArgsDel.ButtonPushedFcn = createCallbackFcn(app, @OptArgsDelPushed, true);
            app.OptArgsDel.Text = {'Delete Selected '; 'Optional Input Argument'};
            app.OptArgsListBoxLabel = uilabel(app.TabOptArgs);
            app.OptArgsListBoxLabel.Text = {'List of Optional'; 'Input arguments'};
            app.OptArgsListBox = uilistbox(app.TabOptArgs);
            app.OptArgsListBox.ValueChangedFcn = createCallbackFcn(app, @OptArgsListBoxChanged, true);
            app.OptArgsShortDescLabel = uilabel(app.TabOptArgs);
            app.OptArgsShortDescLabel.Text = 'Short Description';
            app.OptArgsShortDesc = uitextarea(app.TabOptArgs);
            app.OptArgsLongDescLabel = uilabel(app.TabOptArgs);
            app.OptArgsLongDescLabel.Text = 'Long Description';
            app.OptArgsLongDesc = uitextarea(app.TabOptArgs);
            app.OptArgsTypeIndicationLabel = uilabel(app.TabOptArgs);
            app.OptArgsTypeIndicationLabel.Text = 'Type indication';
            app.OptArgsTypeIndication = uitextarea(app.TabOptArgs);
            app.OptArgsExampleLabel = uilabel(app.TabOptArgs);
            app.OptArgsExampleLabel.Text = 'Example';
            app.OptArgsExample = uieditfield(app.TabOptArgs, 'text');
            app.OptArgsDataTypeLabel = uilabel(app.TabOptArgs);
            app.OptArgsDataTypeLabel.Text = 'DataType';
            app.OptArgsDataType = uieditfield(app.TabOptArgs, 'text');
            app.OptArgsUITable = uitable(app.TabOptArgs);
            app.OptArgsUITable.ColumnName = {'Value'; 'Description'};
            app.OptArgsUITable.RowName = {};
            app.OptArgsUITable.ColumnEditable = true;
            app.OptArgsUITable.CellEditCallback = createCallbackFcn(app, @OptArgsUITableCellEdit, true);
            app.OptArgsStructure = uicheckbox(app.TabOptArgs);
            app.OptArgsStructure.ValueChangedFcn = createCallbackFcn(app, @OptArgsStructureChanged, true);
            app.OptArgsStructure.Text = 'Struct';
            app.OptArgsStructureHelp = uilabel(app.TabOptArgs);
            app.OptArgsStructureHelp.Text = {'Check this box if the argument'; 'is a structure'};
            app.TabOutArgs = uitab(app.TabGroup);
            app.TabOutArgs.Title = 'Output';
            app.TabOutArgs.Units = 'pixels';
            app.OutArgsListBoxLabel = uilabel(app.TabOutArgs);
            app.OutArgsListBoxLabel.Text = 'Output Arguments';
            app.OutArgsListBox = uilistbox(app.TabOutArgs);
            app.OutArgsListBox.ValueChangedFcn = createCallbackFcn(app, @OutArgsListBoxChanged, true);
            app.OutArgsTypeIndicationHelp = uilabel(app.TabOutArgs);
            app.OutArgsTypeIndicationHelp.Text = {'Type indication extracts what is contained before the fist and second full stop '; '(for example: scalar, vector of size 2, ...) '};
            app.OutArgsLongDescHelp = uilabel(app.TabOutArgs);
            app.OutArgsLongDescHelp.Text = {'Long Description extracts what is contained after the second full stop '; '(this is the long description of the output argument) '};
            app.OutArgsShortDescHelp = uilabel(app.TabOutArgs);
            app.OutArgsShortDescHelp.Text = {'Short description extracts what is contained before the fist full stop '; 'This is a syntethic description ot the output argument'};
            app.OutArgsShortDescLabel = uilabel(app.TabOutArgs);
            app.OutArgsShortDescLabel.Text = 'Short Description';
            app.OutArgsShortDesc = uitextarea(app.TabOutArgs);
            app.OutArgsLongDescLabel = uilabel(app.TabOutArgs);
            app.OutArgsLongDescLabel.Text = 'Long Description';
            app.OutArgsLongDesc = uitextarea(app.TabOutArgs);
            app.OutArgsTypeIndicationLabel = uilabel(app.TabOutArgs);
            app.OutArgsTypeIndicationLabel.Text = 'Type indication';
            app.OutArgsTypeIndication = uitextarea(app.TabOutArgs);
            app.OutArgsUITable = uitable(app.TabOutArgs);
            app.OutArgsUITable.ColumnName = {'Value'; 'Description'};
            app.OutArgsUITable.RowName = {};
            app.OutArgsUITable.ColumnEditable = [false true];
            app.OutArgsStructureHelp = uilabel(app.TabOutArgs);
            app.OutArgsStructureHelp.Text = {'Check this box if the output argument'; 'is s struct (in this case the'; ' UItable will be filled)'; 'else "Short Description", "Type indication" '; 'and "Long Description" will appear'};
            app.OutArgsStructure = uicheckbox(app.TabOutArgs);
            app.OutArgsStructure.ValueChangedFcn = createCallbackFcn(app, @OutArgsStructureChanged, true);
            app.OutArgsStructure.Text = 'Struct';
            app.OutArgsAdd = uibutton(app.TabOutArgs, 'push');
            app.OutArgsAdd.ButtonPushedFcn = createCallbackFcn(app, @OutArgsAddPushed, true);
            app.OutArgsAdd.Text = 'Add Output Argument';
            app.OutArgsDel = uibutton(app.TabOutArgs, 'push');
            app.OutArgsDel.ButtonPushedFcn = createCallbackFcn(app, @OutArgsDelPushed, true);
            app.OutArgsDel.Text = {'Delete Selected '; 'Output Argument'};
            app.TabMoreAbout = uitab(app.TabGroup);
            app.TabMoreAbout.Title = 'More About';
            app.TabMoreAbout.Units = 'pixels';
            app.MoreAboutLabel = uilabel(app.TabMoreAbout);
            app.MoreAboutLabel.Text = 'More About';
            app.MoreAbout = uitextarea(app.TabMoreAbout);
            app.AcknowledgementsLabel = uilabel(app.TabMoreAbout);
            app.AcknowledgementsLabel.Text = 'Acknowledgements';
            app.Acknowledgements = uitextarea(app.TabMoreAbout);
            app.ReferencesLabel = uilabel(app.TabMoreAbout);
            app.ReferencesLabel.Text = 'References';
            app.References = uitextarea(app.TabMoreAbout);
            app.SeeAlsoListBoxLabel = uilabel(app.TabMoreAbout);
            app.SeeAlsoListBoxLabel.Text = 'See Also';
            app.SeeAlsoListBox = uilistbox(app.TabMoreAbout);
            app.SeeAlsoAdd = uibutton(app.TabMoreAbout, 'push');
            app.SeeAlsoAdd.ButtonPushedFcn = createCallbackFcn(app, @SeeAlsoAddPushed, true);
            app.SeeAlsoAdd.Text = 'Add See Also Argument';
            app.SeeAlsoDel = uibutton(app.TabMoreAbout, 'push');
            app.SeeAlsoDel.ButtonPushedFcn = createCallbackFcn(app, @SeeAlsoDelPushed, true);
            app.SeeAlsoDel.Text = {'Delete See Also'; 'Argument'};
            app.TabExamples = uitab(app.TabGroup);
            app.TabExamples.Title = 'Examples';
            app.TabExamples.Units = 'pixels';
            app.ExtoExecuteHelp = uilabel(app.TabExamples);
            app.ExtoExecuteHelp.Text = {'Check this box if the example code '; 'has to be executed '; 'and its output must be shown '; 'in the HTML output file'};
            app.ExDescriptionHelp = uilabel(app.TabExamples);
            app.ExDescriptionHelp.Text = 'Write here the purpose of the example together with a description';
            app.ExMatlabCodeHelp = uilabel(app.TabExamples);
            app.ExMatlabCodeHelp.Text = 'Insert here the MATLAB code (adding comments with usual symbol %)';
            app.ExtoExecute = uicheckbox(app.TabExamples);
            app.ExtoExecute.ValueChangedFcn = createCallbackFcn(app, @ExtoExecuteValueChanged, true);
            app.ExtoExecute.Text = 'Example to Execute';
            app.ExAdd = uibutton(app.TabExamples, 'push');
            app.ExAdd.ButtonPushedFcn = createCallbackFcn(app, @ExAddPushed, true);
            app.ExAdd.Text = 'Add Example';
            app.ExeDel = uibutton(app.TabExamples, 'push');
            app.ExeDel.ButtonPushedFcn = createCallbackFcn(app, @ExeDelPushed, true);
            app.ExeDel.Text = {'Delete Selected '; 'Example'};
            app.ExMatlabCodeLabel = uilabel(app.TabExamples);
            app.ExMatlabCodeLabel.Text = {'Matlab code '; '(with comments)'};
            app.ExMatlabCode = uitextarea(app.TabExamples);
            app.ExDescriptionLabel = uilabel(app.TabExamples);
            app.ExDescriptionLabel.Text = 'Example description';
            app.ExDescription = uitextarea(app.TabExamples);
            app.ExListBoxLabel = uilabel(app.TabExamples);
            app.ExListBoxLabel.Text = {'Title of '; 'the example'};
            app.ExListBox = uilistbox(app.TabExamples);
            app.ExListBox.ValueChangedFcn = createCallbackFcn(app, @ExListBoxChanged, true);
            app.TabExtraExamples = uitab(app.TabGroup);
            app.TabExtraExamples.Title = 'Extra Examples';
            app.TabExtraExamples.Units = 'pixels';
            app.ExtraExtoExecuteHelp = uilabel(app.TabExtraExamples);
            app.ExtraExtoExecuteHelp.Text = {'Check this box if the example code '; 'has to be executed '; 'and its output must be shown '; 'in the HTML output file'};
            app.ExtraExDescriptionHelp = uilabel(app.TabExtraExamples);
            app.ExtraExDescriptionHelp.Text = 'Write here the purpose of the example together with a description';
            app.ExtraExMatlabCodeHelp = uilabel(app.TabExtraExamples);
            app.ExtraExMatlabCodeHelp.Text = 'Insert here the MATLAB code (adding comments with usual symbol %)';
            app.ExtraExtoExecute = uicheckbox(app.TabExtraExamples);
            app.ExtraExtoExecute.ValueChangedFcn = createCallbackFcn(app, @ExtraExtoExecuteValueChanged, true);
            app.ExtraExtoExecute.Text = 'Example to Execute';
            app.ExtraExAdd = uibutton(app.TabExtraExamples, 'push');
            app.ExtraExAdd.ButtonPushedFcn = createCallbackFcn(app, @ExtraExAddPushed, true);
            app.ExtraExAdd.Text = 'Add Extra Example';
            app.ExtraExDel = uibutton(app.TabExtraExamples, 'push');
            app.ExtraExDel.ButtonPushedFcn = createCallbackFcn(app, @ExtraExDelPushed, true);
            app.ExtraExDel.Text = {'Delete Selected '; 'Extra example'};
            app.ExtraExMatlabCodeLabel = uilabel(app.TabExtraExamples);
            app.ExtraExMatlabCodeLabel.Text = {'Matlab code '; '(with comments)'};
            app.ExtraExMatlabCode = uitextarea(app.TabExtraExamples);
            app.ExtraExDescriptionLabel = uilabel(app.TabExtraExamples);
            app.ExtraExDescriptionLabel.Text = 'Extra Example description';
            app.ExtraExDescription = uitextarea(app.TabExtraExamples);
            app.ExtraExListBoxLabel = uilabel(app.TabExtraExamples);
            app.ExtraExListBoxLabel.Text = {'Title of '; 'the extra'; 'example'};
            app.ExtraExListBox = uilistbox(app.TabExtraExamples);
            app.ExtraExListBox.ValueChangedFcn = createCallbackFcn(app, @ExtraExListBoxChanged, true);
            app.SavechangestoXMLfileButton = uibutton(app.UIFigure, 'push');
            app.SavechangestoXMLfileButton.ButtonPushedFcn = createCallbackFcn(app, @SavechangestoXML, true);
            app.SavechangestoXMLfileButton.Text = 'Save changes to XML file';
        end
    end

    methods (Access = public)

        % Construct app
        function app = HelpGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end